classdef SDAnext < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DataFigure                      matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        FitterTab                       matlab.ui.container.Tab
        CleanEvaluationButton           matlab.ui.control.Button
        CallEvaluationButton            matlab.ui.control.Button
        SFDoseButtonGroup               matlab.ui.container.ButtonGroup
        DoseButton                      matlab.ui.control.RadioButton
        SFButton                        matlab.ui.control.RadioButton
        SessionPanel                    matlab.ui.container.Panel
        CleanFitButton                  matlab.ui.control.Button
        SavePlotButton                  matlab.ui.control.Button
        SaveSessionButton               matlab.ui.control.Button
        ResetSessionButton              matlab.ui.control.Button
        ResultsPanel                    matlab.ui.container.Panel
        SigmaCBSwitch                   matlab.ui.control.Switch
        CoeffResultsTable               matlab.ui.control.Table
        DataPanel                       matlab.ui.container.Panel
        FileNameEditField               matlab.ui.control.EditField
        ImportSessionButton             matlab.ui.control.Button
        DataTable                       matlab.ui.control.Table
        SelectDataButton                matlab.ui.control.Button
        FitResultTable                  matlab.ui.control.Table
        FitOptionsPanel                 matlab.ui.container.Panel
        ScanAllModelsButton             matlab.ui.control.Button
        DataSDSwitch                    matlab.ui.control.Switch
        DataSDLabel                     matlab.ui.control.Label
        CoefficientsTable               matlab.ui.control.Table
        AlgorithmDropDown               matlab.ui.control.DropDown
        AlgorithmDropDownLabel          matlab.ui.control.Label
        FitButton                       matlab.ui.control.Button
        RobustDropDown                  matlab.ui.control.DropDown
        RobustDropDownLabel             matlab.ui.control.Label
        ModelDropDown                   matlab.ui.control.DropDown
        ModelDropDownLabel              matlab.ui.control.Label
        DataAxes                        matlab.ui.control.UIAxes
        ViewerTab                       matlab.ui.container.Tab
        RBEPanel                        matlab.ui.container.Panel
        ReferenceLabel                  matlab.ui.control.Label
        CalculateRBEButton              matlab.ui.control.Button
        ReferenceFileNameEditField      matlab.ui.control.EditField
        RBETable                        matlab.ui.control.Table
        MultiSessionPanel               matlab.ui.container.Panel
        ResetMultiSessionButton         matlab.ui.control.Button
        ImportMultiSessionFigureButton  matlab.ui.control.Button
        SaveMultiPlotButton             matlab.ui.control.Button
        RefreshButton                   matlab.ui.control.Button
        ImportMultiSessionButton        matlab.ui.control.Button
        MultiSessionTable               matlab.ui.control.Table
        MultiDataAxes                   matlab.ui.control.UIAxes
    end


    properties (Access = private)
        dataStruct = struct();
        fitObject = cfit(fittype('a*x + 1', 'independent', 'x', 'coefficients', {'a'}), 0);
        plotCheckbox = false;
        Version = '0.0'; % Dynamic mlapp version
    end

    methods (Access = private)

        function fitOptions = getFitOptionsFromFitOptionsPanel(app)
            fitOptions.Algorithm = app.AlgorithmDropDown.Value;
            fitOptions.Robust = app.RobustDropDown.Value;

            % Retrieve the data from the CoefficientsTable
            tableData = app.CoefficientsTable.Data;

            % Validate that tableData is not empty
            if isempty(tableData)
                error('The coefficients table is empty. Cannot retrieve fit options.');
            end

            % Fill the fitOptions struct
            fitOptions.StartPoint = cell2mat(tableData(:, 2));
            fitOptions.Lower = cell2mat(tableData(:, 3));
            fitOptions.Upper = cell2mat(tableData(:, 4));
        end

        function modelStruct = getSelectedModelStruct(app)
            % Map descriptive dropdown names to model keys
            modelMap = containers.Map(...
                app.ModelDropDown.Items, ...
                {'L', 'Q', 'LQ', 'LQC', 'LQL'} ...
                );

            % Get the selected model description
            selectedModelDesc = app.ModelDropDown.Value;

            % Validate and retrieve the corresponding model key
            if isKey(modelMap, selectedModelDesc)
                selectedModel = modelMap(selectedModelDesc);
            else
                error('Invalid model selected in the dropdown.');
            end

            % Retrieve the modelStruct for the selected model
            modelStruct = SelectModel(selectedModel);

            % Modify modelStruct if the DisplayName is 'LQL'
            if strcmp(modelStruct.DisplayName, 'LQL')
                % Compute mean, min, and max of app.dataStruct.x
                xData = app.dataStruct.x;
                modelStruct.StartPoint(3) = mean(xData);
                modelStruct.Lower(3) = min(xData);
                modelStruct.Upper(3) = max(xData);
            end
        end

        function populateDataTable(app, dataStruct)
            % Clear the DataTable before populating
            app.DataTable.Data = {};  % Clears any existing data in the DataTable

            % Format the data to scientific notation
            formattedDose = arrayfun(@(x) sprintf('%3.3f', x), dataStruct.dose, 'UniformOutput', false);
            formattedSurvivalFraction = arrayfun(@(x) sprintf('%3.3f', x), dataStruct.survivalFraction, 'UniformOutput', false);

            if isempty(dataStruct.stdDev)
                % No standard deviation column
                formattedStdDev = repmat({''}, length(dataStruct.dose), 1);
            else
                formattedStdDev = arrayfun(@(x) sprintf('%3.3f', x), dataStruct.stdDev, 'UniformOutput', false);
            end

            % Combine the formatted data into a cell array
            tableData = [formattedDose, formattedSurvivalFraction, formattedStdDev];

            % Set the DataTable data
            app.DataTable.Data = tableData;

            % Add row names with sequential numbering
            numRows = size(tableData, 1);
            rowNames = arrayfun(@(x) num2str(x), 1:numRows, 'UniformOutput', false);
            app.DataTable.RowName = rowNames;
        end

        function populateCoefficientsTable(app, modelStruct)
            % Retrieve data from the modelStruct
            coefficients = modelStruct.Coefficients; % Cell array of coefficient names
            startPoints = modelStruct.StartPoint;    % Numeric array of start points
            lowerBounds = modelStruct.Lower;         % Numeric array of lower bounds
            upperBounds = modelStruct.Upper;         % Numeric array of upper bounds

            % Validate data sizes
            nCoefficients = numel(coefficients);
            if ~(numel(startPoints) == nCoefficients && numel(lowerBounds) == nCoefficients && numel(upperBounds) == nCoefficients)
                uialert(app.DataFigure, 'Mismatch in model parameter sizes.', 'Data Validation Error', 'Icon', 'error');
                return;
            end

            % Create table data for display
            tableData = cell(nCoefficients, 4);
            for i = 1:nCoefficients
                tableData{i, 1} = coefficients{i};    % Coefficient name
                tableData{i, 2} = startPoints(i);     % Start point
                tableData{i, 3} = lowerBounds(i);     % Lower bound
                tableData{i, 4} = upperBounds(i);     % Upper bound
            end

            % Assign data to the CoefficientsTable
            app.CoefficientsTable.Data = tableData;

        end

        function populateFitResultTable(app, GOF, Output)
            % Populate the FitResultTable in the app with fit results

            % Extract fit state and model details
            fitState = int8(Output.exitflag); % Fit state from Output
            fitModel = app.ModelDropDown.Value;

            % Extract goodness-of-fit metrics
            sse = GOF.sse;           % Sum of Squares due to Error
            rsquare = GOF.rsquare;   % Coefficient of Determination
            dfe = int8(GOF.dfe);           % Degrees of Freedom in Error
            adjrsquare = GOF.adjrsquare; % Adjusted R-Square
            rmse = GOF.rmse;         % Root Mean Square Error

            % Prepare the table data (row format)
            tableData = {
                fitState, ...
                fitModel, ...
                sse, ...
                rsquare, ...
                dfe, ...
                adjrsquare, ...
                rmse
                };

            % Assign the data to the FitResultTable
            app.FitResultTable.Data = tableData;
        end

        function plotData(app, datafileStruct)
            % Clear the axes before plotting
            cla(app.DataAxes);

            % Default values for Color and DisplayName
            defaultColor = 'k';
            defaultDisplayName = 'data';

            try
                % Check if standard deviation is provided for error bars
                if ~isempty(datafileStruct.stdDev)
                    % Plot data with error bars
                    survivalPlot = errorbar(app.DataAxes, datafileStruct.dose, datafileStruct.survivalFraction, datafileStruct.stdDev, ...
                        'o', 'Color', defaultColor, 'MarkerEdgeColor', defaultColor, 'MarkerFaceColor', defaultColor, 'MarkerSize', 5);
                else
                    % Plot data without error bars
                    survivalPlot = plot(app.DataAxes, datafileStruct.dose, datafileStruct.survivalFraction, ...
                        'o', 'Color', defaultColor, 'MarkerEdgeColor', defaultColor, 'MarkerFaceColor', defaultColor, 'MarkerSize', 5);
                end

                % Customize the error bar appearance
                if ~isempty(datafileStruct.stdDev)
                    survivalPlot.LineStyle = 'none';
                    survivalPlot.Color = defaultColor; % Default color
                    survivalPlot.LineWidth = 1;
                    survivalPlot.CapSize = 0;
                end

                % Assign properties from headerInfo if available
                if isfield(datafileStruct.headerInfo, 'Color') && ~isempty(datafileStruct.headerInfo.Color)
                    % Validate color
                    try
                        survivalPlot.Color = ColorNameToRGB(datafileStruct.headerInfo.Color);
                        survivalPlot.MarkerEdgeColor = ColorNameToRGB(datafileStruct.headerInfo.Color);
                        survivalPlot.MarkerFaceColor = ColorNameToRGB(datafileStruct.headerInfo.Color);
                    catch
                        % If setting color fails, use default color
                        uialert(app.DataFigure, 'Invalid color specified in headerInfo. Using default color ''black''.', 'Color Warning', 'Icon', 'warning');
                        survivalPlot.Color = defaultColor;
                        survivalPlot.MarkerEdgeColor = defaultColor;
                        survivalPlot.MarkerFaceColor = defaultColor;
                    end
                else
                    % Use default color
                    survivalPlot.Color = defaultColor;
                end

                if isfield(datafileStruct.headerInfo, 'DisplayName') && ~isempty(datafileStruct.headerInfo.DisplayName)
                    % Set display name
                    survivalPlot.DisplayName = datafileStruct.headerInfo.DisplayName;
                else
                    % Use default display name
                    uialert(app.DataFigure, 'Invalid display name specified in headerInfo. Using default display name ''data''.', 'Display name Warning', 'Icon', 'warning');
                    survivalPlot.DisplayName = defaultDisplayName;
                end

                % Customize the axes
                ax = app.DataAxes;
                ax.YLim = [min(datafileStruct.survivalFraction) * 0.8, 1];
                ax.XLim = [0, max(datafileStruct.dose) * 1.05];
                ax.YAxis.Scale = 'log';
                ax.YAxis.TickLabelFormat = '%3.1f';
                ax.YAxis.TickValues = 0.1:0.1:1;
                ax.XLabel.String = 'Dose [Gy]';
                ax.YLabel.String = 'Survival fraction';
                ax.FontName = 'Franklin Gothic Book';
                % ax.FontSize = 16;
                ax.FontWeight = 'bold';
                ax.XMinorTick = 'on';
                ax.YMinorTick = 'on';
                ax.XAxis.LineWidth = 1.5;
                ax.YAxis.LineWidth = 1.5;

                % Add legend
                leg = legend(app.DataAxes, 'Location', 'northeast', 'FontSize', 14);
                leg.Box = 'off';

                app.FitButton.Enable = 'on';
                app.ScanAllModelsButton.Enable = 'on';

            catch ME
                % Display an error message if the plot fails
                uialert(app.DataFigure, ['Plotting failed: ' ME.message], 'Plot Error', 'Icon', 'error');
                app.FitButton.Enable = 'off';
                app.ScanAllModelsButton.Enable = 'off';
                app.CallEvaluationButton.Enable = "off";
            end
        end

        function populateCoeffResultsTable(app, FitObject)

            % Extract coefficient names, values, and confidence intervals
            coeffNames = coeffnames(FitObject);
            coeffValues = coeffvalues(FitObject);
            coeffCI = confint(FitObject,0.950); % 95% confidence intervals

            % Compute standard errors (Sigma) from confidence bounds
            T = 1.96; % For 95% confidence level
            coeffErrors = diff(coeffCI) / (2 * T); % Standard errors

            % Prepare data for the ResultsTable
            numCoeffs = length(coeffNames);
            data = cell(numCoeffs, 4); % 4 columns: Coefficients, Value, Lower, Upper

            for i = 1:numCoeffs
                data{i, 1} = coeffNames{i};          % Coefficient name
                data{i, 2} = coeffValues(i);         % Coefficient value
                % Decide what to display based on the switch state
                if strcmp(app.SigmaCBSwitch.Value, '95%CB') % Confidence bounds
                    data{i, 3} = coeffCI(1, i);  % Lower bound
                    data{i, 4} = coeffCI(2, i);  % Upper bound
                elseif strcmp(app.SigmaCBSwitch.Value, '1σ') % Standard errors
                    data{i, 3} = coeffErrors(i); % Lower shows Sigma
                    data{i, 4} = coeffErrors(i); % Upper also shows Sigma
                end
            end

            % Update the ResultsTable in the app
            app.CoeffResultsTable.Data = data;

        end

        function updateSwitchDataSD(app)
            % Get the data from the DataTable
            data = app.DataTable.Data;

            % Check if the "SD" column (3rd column) is not empty or entirely {''}
            if any(~cellfun(@isempty, data(:, 3))) || ~all(strcmp(data(:, 3), ''))
                % SD column is not entirely {''}
                app.DataSDSwitch.Enable = 'on'; % Enable the switch
                app.DataSDSwitch.Value = 'On'; % Set the switch to On
            else
                % SD column is entirely {''}
                app.DataSDSwitch.Enable = 'off'; % Disable the switch
                app.DataSDSwitch.Value = 'Off'; % Set the switch to Off
            end
        end

        function cleanPreviousFittedCurve(app)
            % --- Clean the previous fitted curve ---
            % Delete any previous fit (search for lines and scatter plots)

            % Find all line objects in the axes
            previousLines = findobj(app.DataAxes, 'Type', 'line');

            % Find all scatter objects in the axes
            previousScatters = findobj(app.DataAxes, 'Type', 'scatter');

            % Delete all line objects
            for i = 1:length(previousLines)
                if ismember(previousLines(i).DisplayName, {'L', 'Q', 'LQ', 'LQC', 'LQL'}) || ismember(previousLines(i).Tag, {'vLine','hLine'})
                    delete(previousLines(i));
                end
            end

            % Delete all scatter objects
            for i = 1:length(previousScatters)
                delete(previousScatters(i));
            end
        end


        function addWatermark(app)
            % Add a watermark to indicate that SD is provided but not used
            text(app.DataAxes, ...
                0.5, 0.5, ...                     % Position (normalized coordinates)
                'Data SD is provided but not used', ... % Message
                'Units', 'normalized', ...         % Normalize position in axes
                'HorizontalAlignment', 'center', ... % Center alignment
                'VerticalAlignment', 'middle', ...   % Middle alignment
                'FontName', 'Arial', ... % Font name
                'FontSize', 35, ...                % Font size
                'FontWeight', 'bold', ...          % Bold text
                'Color', [0.5, 0.5, 0.5, 0.3], ...      % Light gray color
                'Rotation', 45, ...                % Rotate the text for watermark effect
                'Tag', 'Watermark');               % Tag for easy removal
        end

        function removeWatermark(app)
            % Find and delete the watermark
            watermark = findobj(app.DataAxes, 'Tag', 'Watermark');
            if ~isempty(watermark)
                delete(watermark);
            end
        end

        function openEvaluationUI(app, isSFSelected)
            % Open the evaluation UI
            evalFig = uifigure('Name', 'Model evaluation', 'Position', [100, 100, 400, 300]);

            % Create a table with 'SF' and 'Dose' columns
            evalTable = uitable(evalFig, 'Position', [50, 100, 300, 150]);
            evalTable.ColumnName = {'SF', 'Dose [Gy]'}; % Column headers
            evalTable.Data = cell(5, 2); % Initialize table with 5 empty rows

            % Set font to Franklin Gothic Book
            evalTable.FontName = 'Franklin Gothic Book';

            % Set column alignment to left
            s = uistyle('HorizontalAlignment','left');
            addStyle(evalTable,s,'table','')

            % Set column editability based on the selection
            if isSFSelected
                evalTable.ColumnEditable = [false, true]; % 'Dose' column editable
            else
                evalTable.ColumnEditable = [true, false]; % 'SF' column editable
            end

            % Add a compute button with the corrected callback
            computeButton = uibutton(evalFig, 'Position', [180, 40, 35, 35], 'Text', '', ...
                'ButtonPushedFcn', @(btn, event) handleComputeButton(app, evalTable, isSFSelected));

            computeButton.Icon = fullfile(fileparts(mfilename('fullpath')), 'icons', 'calculate_100dp_000000_FILL1_wght400_GRAD0_opsz48.svg');

            % Add the checkbox for plotting
            app.plotCheckbox = uicheckbox(evalFig, 'Text', ' Plot', 'FontName', 'Franklin Gothic Book','FontWeight','bold','Position', [250, 40, 120, 30]);

        end

        function handleComputeButton(app, evalTable, isSFSelected)
            % Handle the compute button functionality
            rawData = evalTable.Data; % Retrieve table data
            numericData = str2double(rawData); % Convert cell data to numeric

            if isSFSelected
                % 'SF' is fixed; compute 'Dose' values
                doseValues = numericData(:, 2); % Get 'Dose' column
                validIndices = ~isnan(doseValues); % Filter valid rows
                doseValues = doseValues(validIndices);

                try
                    % Compute SF using the fit object
                    sfValues = feval(app.fitObject, doseValues);
                    sfColumn = rawData(:, 1); % Retrieve the SF column from evalTable.Data
                    sfColumn(validIndices) = num2cell(sfValues); % Update 'SF' column
                    evalTable.Data(:, 1) = sfColumn; % Reflect changes in evalTable
                catch ME
                    % Display an error alert for FitObject evaluation
                    uialert(app.DataFigure, ME.message, 'Error in FitObject Evaluation');
                end
            else
                % 'Dose' is fixed; compute 'SF' values
                sfValues = numericData(:, 1); % Get 'SF' column
                validIndices = ~isnan(sfValues); % Filter valid rows
                sfValues = sfValues(validIndices);

                try
                    % Compute 'Dose' by inverting the fit object
                    doseValues = arrayfun(@(sf) InvertFitObject(app.fitObject, sf), sfValues);
                    doseColumn = rawData(:, 2); % Retrieve the Dose column from evalTable.Data
                    doseColumn(validIndices) = num2cell(doseValues); % Update 'Dose' column
                    evalTable.Data(:, 2) = doseColumn; % Reflect changes in evalTable
                catch ME
                    % Display an error alert for FitObject inversion
                    uialert(app.DataFigure, ME.message, 'Error in FitObject Inversion');
                end
            end

            % Check if the checkbox is selected, and if so, plot the scatter
            if app.plotCheckbox.Value

                xValues = doseValues; % Dose values (x-axis)
                yValues = sfValues;   % SF values (y-axis)

                % Remove NaN values
                validIndices = ~isnan(xValues) & ~isnan(yValues);
                xValues = xValues(validIndices);
                yValues = yValues(validIndices);

                % Plot the scatter points without connecting lines
                hold(app.DataAxes, 'on');
                scatterPlot = scatter(app.DataAxes, xValues, yValues, ...
                    'Marker', 'square', ...
                    'LineWidth',1.5, ...
                    'MarkerEdgeColor', [0.5, 0, 0.5], 'MarkerFaceColor', [0.5, 0, 0.5], ...
                    'MarkerFaceAlpha', 0.5, ...
                    'DisplayName', '', ...
                    'SizeData', 150, 'HandleVisibility', 'on');

                scatterPlot.Annotation.LegendInformation.IconDisplayStyle = 'off';

                % Get the limits of the axes for the dashed lines
                xLimits = app.DataAxes.XLim;
                yLimits = app.DataAxes.YLim;

                % Add vertical and horizontal dashed lines for each point
                for i = 1:length(xValues)
                    % Vertical line from the point to the x-axis
                    vLine = line(app.DataAxes,[xValues(i), xValues(i)], [yLimits(1), yValues(i)], ...
                        'LineStyle', '-.', 'Color', [0.5, 0, 0.5], 'LineWidth', 1., ...
                        'Tag', 'vLine');
                    vLine.Annotation.LegendInformation.IconDisplayStyle = 'off';

                    % Horizontal line from the point to the y-axis
                    hLine = line(app.DataAxes,[xLimits(1), xValues(i)], [yValues(i), yValues(i)], ...
                        'LineStyle', '-.', 'Color', [0.5, 0, 0.5], 'LineWidth', 1., ...
                        'Tag', 'hLine');
                    hLine.Annotation.LegendInformation.IconDisplayStyle = 'off';
                end

                hold(app.DataAxes, 'off');
            end

        end

        function updateMultiSessionTable(app, plotData, filename)
            % updateMultiSessionTable - Appends only new plots to MultiSessionTable.
            % Parameters:
            %   app (struct): The app structure containing the UI components.
            %   plotData (struct): The structure containing plot information with .Tag.
            %   filename (char): The name of the imported file.

            % Define the color drop-down options
            colorOptions = { ...
                'Red', 'Light Red', 'Dark Red', ...
                'Green', 'Light Green', 'Dark Green', ...
                'Blue', 'Light Blue', 'Dark Blue', ...
                'Yellow', 'Light Yellow', 'Dark Yellow', ...
                'Cyan', 'Light Cyan', 'Dark Cyan', ...
                'Magenta', 'Light Magenta', 'Dark Magenta', ...
                'Black', 'Gray', 'Dark Gray', ...
                'Orange', 'Light Orange', 'Dark Orange', ...
                'Purple', 'Light Purple', 'Dark Purple', ...
                'Pink', 'Light Pink', 'Dark Pink' ...
                };

            % Retrieve existing table data
            oldData = app.MultiSessionTable.Data;

            % Ensure oldData is a valid cell array with at least 6 columns
            if isempty(oldData) || ~iscell(oldData) || size(oldData, 2) < 6
                oldData = {};
                existingTags = {};
            else
                existingTags = strcat(oldData(:, 3), oldData(:, 4)); % Source + DisplayName
            end

            % Preallocate newRows cell array
            newRows = cell(numel(plotData), 6);
            newCount = 0;

            % Iterate through each entry in plotData
            for k = 1:numel(plotData)
                % Validate Tag field
                if ~isfield(plotData, 'Tag') || isempty(plotData(k).Tag)
                    continue;
                end
                newTag = plotData(k).Tag;

                % Skip if tag already exists
                if any(strcmp(existingTags, newTag))
                    continue;
                end

                % Extract visibility status
                if isfield(plotData(k), 'DisplayName') && ismember(plotData(k).DisplayName, {'L', 'Q', 'LQ', 'LQC', 'LQL'})
                    isVisible = false;
                else
                    isVisible = true;
                end

                % Extract source, display name, and color safely
                source = GetFieldSafely(plotData(k), 'Source', '');
                displayName = GetFieldSafely(plotData(k), 'DisplayName', '');
                colorArray = GetFieldSafely(plotData(k), 'Color', []);
                colorName = 'Unknown';
                customEntry = displayName; % Default value for "Custom Entry"

                if ~isempty(colorArray)
                    colorName = RGBToColorName(colorArray);
                end

                % Store new row
                newCount = newCount + 1;
                newRows(newCount, :) = {isVisible, filename, source, displayName, colorName, customEntry};

                % Update existingTags dynamically
                existingTags = [existingTags; newTag]; %#ok<AGROW>
            end

            % Remove unused preallocated rows
            newRows = newRows(1:newCount, :);

            % Append only new rows to the existing table
            if ~isempty(newRows)
                if isempty(oldData)
                    app.MultiSessionTable.Data = newRows;
                else
                    app.MultiSessionTable.Data = [oldData; newRows];
                end
            end

            % Set the color column as a drop-down list
            app.MultiSessionTable.ColumnFormat = {'logical', 'char', 'char', 'char', colorOptions, 'char'};
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Ensure all components are initialized before populating the table
            try

                app.DataFigure.Name = [app.DataFigure.Name ' - Version: ' app.Version];

                % Retrieve the modelStruct for the preselected model
                modelStruct = app.getSelectedModelStruct();

                % Populate the coefficients table
                app.populateCoefficientsTable(modelStruct);

            catch ME
                % Handle errors during initialization
                uialert(app.DataFigure, ME.message, 'Initialization Error', 'Icon', 'error');
            end
        end

        % Button pushed function: SelectDataButton
        function SelectDataButtonPushed(app, event)

            % Open file dialog to select a data file
            [file, path] = uigetfile({'*.txt', 'Text Files (*.txt)'; '*.*', 'All Files (*.*)'}, 'Select Data File');

            if isequal(file, 0)
                uialert(app.DataFigure, 'User cancelled the file selection.', 'Operation Cancelled', 'Icon', 'warning');
                return; % Exit the function if the user cancels
            end

            % Construct full file path
            fullPath = fullfile(path, file);

            % Validate and preprocess data
            datafileStruct = ValidateAndPreprocessData(fullPath);

            % app.FileNameEditField.Value = '';
            % app.FileNameEditField.Placeholder = 'filename';

            % Clear the FitResultTable
            app.FitResultTable.Data = cell(0, 7); % Set to an empty cell array with the correct number of columns

            % Clear the CoeffResultsTable
            app.CoeffResultsTable.Data = cell(0, 4); % Set to an empty cell array with the correct number of columns

            if ~isfield(datafileStruct.headerInfo, 'Title') || isempty(datafileStruct.headerInfo.Title)
                datafileStruct.headerInfo.('Title') = file;
            end

            title(app.DataAxes, datafileStruct.headerInfo.('Title'));

            % Determine alert type based on dataStruct.message
            if contains(datafileStruct.message, 'Error', 'IgnoreCase', true)
                % Show an error alert
                uialert(app.DataFigure, datafileStruct.message, 'Error in Data Processing', 'Icon', 'error');
            elseif contains(datafileStruct.message, 'Warning', 'IgnoreCase', true) || datafileStruct.defaultAdded
                % Show a warning or info alert
                uialert(app.DataFigure, datafileStruct.message, 'Warning: Data Adjusted', 'Icon', 'warning');
            else
                % Show a success alert
                uialert(app.DataFigure, datafileStruct.message, 'Data Processed', 'Icon', 'success');
            end

            % Populate the FileNameEditField with the file name
            app.FileNameEditField.Value = file;

            % Populate the DataTable with the validated data
            app.populateDataTable(datafileStruct);

            % Retrieve and validate the data and store dataStruct property
            app.dataStruct = GetDataFromDataTable(app.DataTable.Data);

            % Update the updateSwitchDataSD with the validated data
            app.updateSwitchDataSD()

            % Plot the data
            app.plotData(datafileStruct);

            % Enable Fit Options
            app.FitOptionsPanel.Enable = "on";

        end

        % Button pushed function: FitButton
        function FitButtonPushed(app, event)

            if isempty(fieldnames(app.dataStruct))
                uialert(app.DataFigure, 'No data has been uploaded or the data is invalid. Please upload a valid dataset to proceed.', ...
                    'Data Validation Error', 'Icon', 'error');
                return;
            end

            % Call the SelectModel function with the selected model name
            try
                modelStruct = getSelectedModelStruct(app);

                % Check if DataTable is empty
                if isempty(app.dataStruct.x) || isempty(app.dataStruct.y)
                    uialert(app.DataFigure, 'Data table is empty or has invalid values.', 'Data Validation Error', 'Icon', 'error');
                    return;
                end

                % Validate data: Ensure the number of x and y values match
                if numel(app.dataStruct.x) ~= numel(app.dataStruct.y)
                    uialert(app.DataFigure, 'The number of x and y values must match.', 'Data Validation Error', 'Icon', 'error');
                    return;
                end

                % Validate weights (ensure no NaNs, negative, or zero values)
                if any(isnan(app.dataStruct.w)) || any(app.dataStruct.w <= 0)
                    uialert(app.DataFigure, 'Invalid weights detected. Weights must be positive values.', 'Data Validation Error', 'Icon', 'error');
                    return;
                end

                fitOptions = app.getFitOptionsFromFitOptionsPanel();

                % Detect and switch the algorithm
                if strcmp(fitOptions.Algorithm, 'Levenberg-Marquardt') && ...
                        (any(fitOptions.Lower ~= -Inf) || any(fitOptions.Upper ~= Inf))
                    warning('Switching to Trust-Region because bounds are defined.');
                    fitOptions.Algorithm = 'Trust-Region';
                    % Update the dropdown to reflect the 'Trust-Region' algorithm
                    app.AlgorithmDropDown.Value = 'Trust-Region';
                    % Notify the user
                    uialert(app.DataFigure, ...
                        'The "Levenberg-Marquardt" algorithm does not support bounds. It has been switched to "Trust-Region".', ...
                        'Algorithm Switched', ...
                        'Icon', 'warning');
                end

                % Call FitModel with dataStruct, modelStruct, and tolerance
                [FitObject, GOF, Output] = FitModel(app.dataStruct, modelStruct, fitOptions);
                app.fitObject = FitObject;

                % Display the results (you can update this to show the fit results in the UI as needed)
                fprintf('Fit completed successfully.\n');

                % Populate the CoeffResultsTable with fit output
                app.populateCoeffResultsTable(FitObject)

                % Populate the FitResult with fit output
                app.populateFitResultTable(GOF, Output);

                % --- Clean the previous fitted curve ---
                app.cleanPreviousFittedCurve()

                % --- Plot the original data points ---
                hold(app.DataAxes, 'on');  % Hold the current plot to overlay the fit curve

                % --- Plot the fitted curve ---
                % Generate fitted y-values based on the fitted model
                xFit = linspace(min(app.dataStruct.x), max(app.dataStruct.x)*1.05, 100);  % Generate a smooth range of x values
                yFit = feval(FitObject, xFit); % Evaluate the fitted model at these x values

                % Plot the fitted curve on the existing axis
                % Find all marker objects on the axes
                markersOnLine = findobj(app.DataAxes, 'Type', 'line');
                markersOnErrorBar = findobj(app.DataAxes, 'Type', 'ErrorBar');

                % Determine the color of the marker based on the presence of error bars
                if all(app.dataStruct.w == 1)  % If all weights are 1, no error bars, use aline
                    if ~isempty(markersOnLine)
                        markerColor = markersOnLine(1).Color;  % Get the color from the first line object
                    else
                        markerColor = [0.5, 0., 0.5];  % Default to purple if no line is found
                    end
                else  % If weights are not 1, error bars are present, use aerrorbar
                    if ~isempty(markersOnErrorBar)
                        markerColor = markersOnErrorBar(1).Color;  % Get the color from the first error bar object
                    else
                        markerColor = [1, 0, 0];  % Default to red if no error bar is found
                    end
                end

                plot(app.DataAxes, xFit, yFit, 'LineStyle', '-', 'Color', [markerColor 0.3], 'LineWidth', 5, 'DisplayName', modelStruct.DisplayName); % Store the handle for future deletion
                hold(app.DataAxes, 'off'); % Release the hold on the axis

                app.CallEvaluationButton.Enable = "on";

            catch ME
                % Display an error dialog if model selection or function execution fails
                uialert(app.DataFigure, ME.message, 'Model Selection Error', 'Icon', 'error');
            end

        end

        % Value changed function: ModelDropDown
        function ModelDropDownValueChanged(app, event)
            try
                modelStruct = app.getSelectedModelStruct();

                % Populate the CoefficientsTable with modelStruct fields
                app.populateCoefficientsTable(modelStruct);

            catch ME
                % Handle errors in retrieving the modelStruct
                uialert(app.DataFigure, ME.message, 'Model Selection Error', 'Icon', 'error');
            end

        end

        % Value changed function: SigmaCBSwitch
        function SigmaCBSwitchValueChanged(app, event)
            % Get the current state of the switch
            currentState = app.SigmaCBSwitch.Value; % '95%CB' or 'Sigma'

            % Get the current table data
            tableData = app.CoeffResultsTable.Data;

            % Number of rows (coefficients) in the table
            numCoeffs = size(tableData, 1);

            if strcmp(currentState, '1σ') % Switch to Sigma
                for i = 1:numCoeffs
                    lower = tableData{i, 3}; % Current lower bound
                    upper = tableData{i, 4}; % Current upper bound

                    % Convert bounds to standard error (Sigma)
                    sigma = (upper - lower) / (2 * 1.96);

                    % Update both lower and upper with Sigma
                    tableData{i, 3} = sigma;
                    tableData{i, 4} = sigma;
                end
            elseif strcmp(currentState, '95%CB') % Switch to 95% Confidence Bounds
                for i = 1:numCoeffs
                    sigma = tableData{i, 3}; % Current Sigma (from either Lower or Upper)

                    % Convert Sigma back to bounds
                    lower = tableData{i, 2} - sigma * 1.96;
                    upper = tableData{i, 2} + sigma * 1.96;

                    % Update table with bounds
                    tableData{i, 3} = lower;
                    tableData{i, 4} = upper;
                end
            end

            % Update the table data
            app.CoeffResultsTable.Data = tableData;

        end

        % Value changed function: DataSDSwitch
        function DataSDSwitchValueChanged(app, event)
            % Notify the user that fit outputs will be cleared
            uialert(app.DataFigure, ...
                'Fit outputs will be cleared because the switch has been toggled.', ...
                'Warning', ...
                'Icon', 'warning');

            % Clean the previous fitted curve
            app.cleanPreviousFittedCurve()
            app.fitObject = cfit(fittype('a*x + 1', 'independent', 'x', 'coefficients', {'a'}), 0);

            % Clear the FitResultTable
            app.FitResultTable.Data = cell(0, 7); % Set to an empty cell array with the correct number of columns

            % Clear the CoeffResultsTable
            app.CoeffResultsTable.Data = cell(0, 4); % Set to an empty cell array with the correct number of columns

            % Check the current state of the switch
            if strcmp(app.DataSDSwitch.Enable, 'on') && strcmp(app.DataSDSwitch.Value, 'Off')
                % Override dataStruct.w with ones
                numElements = numel(app.dataStruct.w);
                app.dataStruct.w = ones(1, numElements);

                % Display a warning to the user
                uialert(app.DataFigure, ...
                    'Data standard deviations will be ignored because the switch is turned Off.', ...
                    'Warning', ...
                    'Icon', 'warning');

                addWatermark(app);

            elseif strcmp(app.DataSDSwitch.Enable, 'on') && strcmp(app.DataSDSwitch.Value, 'On')
                % Restore the original weights using the existing function
                app.dataStruct = GetDataFromDataTable(app.DataTable.Data);

                % Notify the user that the original weights have been restored
                uialert(app.DataFigure, ...
                    'Original data standard deviations have been restored because the switch is turned On.', ...
                    'Info', ...
                    'Icon', 'info');

                app.removeWatermark();

            end
        end

        % Button pushed function: SaveSessionButton
        function SaveSessionButtonPushed(app, event)
            % SaveSessionButtonPushed - Saves session data including plots to a .mat file
            try
                % Check if there is anything to save
                if isempty(app.DataAxes.Children)
                    uialert(app.DataFigure, 'There are no plots to save. Please create a plot before saving.', ...
                        'Save Error', 'Icon', 'error');
                    return;
                end

                fitResults = app.FitResultTable.Data;
                coeffResults = app.CoeffResultsTable.Data;

                if isempty(fitResults) && isempty(coeffResults)
                    uialert(app.DataFigure, 'Fit results and coefficient results are empty. There is nothing to save.', ...
                        'Save Error', 'Icon', 'error');
                    return;
                end

                % Ask the user where to save the session
                [fileName, pathName] = uiputfile('*.mat', 'Save Session As');
                if isequal(fileName, 0)
                    % User canceled save operation
                    disp('Save operation canceled.');
                    return;
                end

                % Get table data
                fitResultsObject = app.fitObject;
                fitResults = app.FitResultTable.Data;
                coeffResults = app.CoeffResultsTable.Data;

                % Get UI component states
                sigmaState = app.SigmaCBSwitch.Value;  % 'On' or 'Off'
                algorithm = app.AlgorithmDropDown.Value;
                robustOption = app.RobustDropDown.Value;
                dataSDState = app.DataSDSwitch.Value;

                % Get plot data from app.DataAxes
                [plotData, titleText] = GetPlotData(app.DataAxes);

                % Save all data to a .mat file
                save(fullfile(pathName, fileName), ...
                    'fitResultsObject', ...
                    'fitResults', 'coeffResults', 'sigmaState', ...
                    'algorithm', 'robustOption', 'dataSDState', ...
                    'titleText', 'plotData');

                % Confirm the save operation
                uialert(app.DataFigure, 'Session saved successfully!', 'Success', 'Icon', 'success');
            catch ME
                % Handle errors and alert the user
                uialert(app.DataFigure, sprintf('Error saving session:\n%s', ME.message), 'Error', 'Icon', 'error');
                disp(ME.message);
            end
        end

        % Button pushed function: ImportSessionButton
        function ImportSessionButtonPushed(app, event)
            % Disable Fit Options to prevent re-fitting on a saved session.
            % app.FitOptionsPanel.Enable = "off";
            app.FitButton.Enable = 'off';
            app.ScanAllModelsButton.Enable = 'off';

            app.CallEvaluationButton.Enable = "on";

            % app.FileNameEditField.Value = '';
            % app.FileNameEditField.Placeholder = 'filename';

            % Load session data when the user presses the "Import Session" button
            try
                % Ask the user to select the .mat file containing the saved session
                [fileName, pathName] = uigetfile('*.mat', 'Import Session');
                if isequal(fileName, 0)
                    % User canceled the load operation
                    disp('Load operation canceled.');
                    return;
                end

                app.FileNameEditField.Value = fileName;

                % Load the session data from the selected .mat file
                sessionData = load(fullfile(pathName, fileName));

                % Extract data from the loaded session
                fitResultsObject = sessionData.fitResultsObject;
                fitResults = sessionData.fitResults;
                coeffResults = sessionData.coeffResults;
                sigmaState = sessionData.sigmaState;
                algorithm = sessionData.algorithm;
                robustOption = sessionData.robustOption;
                dataSDState = sessionData.dataSDState;
                plotData = sessionData.plotData;
                titleText = sessionData.titleText;

                % Filter the plotData to only include items with LineStyle
                % 'none', i.e. data
                filteredData = plotData(strcmp({plotData.LineStyle}, 'none'));

                if numel(filteredData) == 1
                    % Extract XData (dose) and YData (survival fraction)
                    XData = filteredData.XData;
                    YData = filteredData.YData;

                    % Check if the filteredData contains YNegativeDelta and YPositiveDelta
                    if isfield(filteredData, 'YNegativeDelta') && isfield(filteredData, 'YPositiveDelta')
                        % Compute the average YDelta
                        YDelta = (filteredData.YNegativeDelta + filteredData.YPositiveDelta) / 2;
                    else
                        % If no delta fields, set YDelta to empty
                        YDelta = [];
                    end

                    % Format the data to scientific notation
                    formattedDose = arrayfun(@(x) sprintf('%3.3f', x), XData, 'UniformOutput', false);
                    formattedSurvivalFraction = arrayfun(@(x) sprintf('%3.3f', x), YData, 'UniformOutput', false);

                    if isempty(YDelta)
                        % No YDelta column
                        formattedYDelta = repmat({''}, length(XData), 1);
                    else
                        formattedYDelta = arrayfun(@(x) sprintf('%3.3f', x), YDelta, 'UniformOutput', false);
                    end

                    % Combine the formatted data into a cell array
                    tableData = [formattedDose(:), formattedSurvivalFraction(:), formattedYDelta(:)];

                    % Set the DataTable data
                    app.DataTable.Data = tableData;

                    % Set the FitObject
                    app.fitObject = fitResultsObject;

                    % Add row names with sequential numbering
                    numRows = size(tableData, 1);
                    rowNames = arrayfun(@(x) num2str(x), 1:numRows, 'UniformOutput', false);
                    app.DataTable.RowName = rowNames;

                else
                    % Handle the case where there is no or more than one data series
                    uialert(app.DataFigure, ...
                        sprintf('Error loading session: expected one data series but found %d.', numel(filteredData)), ...
                        'Error', 'Icon', 'error');
                end

                % Recreate the plot using the saved plot data
                RestorePlot(app.DataAxes, plotData, titleText, false);

                % Optionally, update UI components with the saved settings
                app.SigmaCBSwitch.Value = sigmaState;
                app.AlgorithmDropDown.Value = algorithm;
                app.RobustDropDown.Value = robustOption;
                app.DataSDSwitch.Value = dataSDState;

                % Optionally, update the tables with the loaded data
                app.FitResultTable.Data = fitResults;
                app.CoeffResultsTable.Data = coeffResults;

                % Confirm that the session was loaded and plot was recreated
                uialert(app.DataFigure, 'Session loaded and plot recreated successfully!', 'Success', 'Icon', 'success');
            catch ME
                % Handle errors and alert the user if loading fails
                uialert(app.DataFigure, sprintf('Error loading session:\n%s', ME.message), 'Error', 'Icon', 'error');
                disp(ME.message);
            end
        end

        % Button pushed function: ScanAllModelsButton
        function ScanAllModelsButtonPushed(app, event)
            % Clear previous fitted curve
            app.cleanPreviousFittedCurve();

            % Pre-allocate storage for FitResult and Coefficients based on number of models
            numModels = numel(app.ModelDropDown.Items);
            % allFitResults = cell(numModels,1);  % Cell array to store fit results
            % allCoeffResults = cell(numModels,1); % Cell array to store coefficient results

            modelNames = cell(numModels, 1);    % Model names
            rmseValues = NaN(numModels, 1);   % RMSE values
            validIndices = false(numModels, 1); % Logical array for valid models


            % Loop through all the values in the ModelDropDown's item list
            for i = 1:numModels
                % Set the value of the dropdown to the current item
                app.ModelDropDown.Value = app.ModelDropDown.Items{i};

                % Create a dummy event to pass to the ModelDropDownValueChanged function
                eventModelDropDown = struct('Source', app.ModelDropDown, 'Value', app.ModelDropDown.Value);

                % Trigger the ModelDropDownValueChanged function
                ModelDropDownValueChanged(app, eventModelDropDown);

                % Trigger the FitButtonPushed function by simulating a button press
                eventFitButton = struct('Source', app.FitButton);
                FitButtonPushed(app, eventFitButton);

                % Store the results in the pre-allocated arrays
                % allFitResults{i} = app.FitResultTable.Data;
                % allCoeffResults{i} = app.CoeffResultsTable.Data;

                % Extract RMSE and model name
                rmseValues(i) = app.FitResultTable.Data{7}; % RMSE value
                modelNames{i} = app.FitResultTable.Data{2}; % Model name

                % Check validity of coefficients in 3rd and 4th positions
                coeffData = app.CoeffResultsTable.Data;
                % Ensure all elements in the 3rd and 4th columns are numeric, finite, and not NaN
                if ~isempty(coeffData) && ...
                        all(cellfun(@(x) isfinite(x) && ~isnan(x), coeffData(:, 3))) && ...
                        all(cellfun(@(x) isfinite(x) && ~isnan(x), coeffData(:, 4)))
                    validIndices(i) = true; % Mark as valid if all elements in both columns pass the check
                end

                pause(1);
            end

            % Optionally clear the previous fitted curve again
            app.cleanPreviousFittedCurve();

            % Clear the FitResultTable
            app.FitResultTable.Data = cell(0, 7); % Set to an empty cell array with the correct number of columns

            % Clear the CoeffResultsTable
            app.CoeffResultsTable.Data = cell(0, 4); % Set to an empty cell array with the correct number of columns

            % Filter valid models
            filteredRMSE = rmseValues(validIndices);
            filteredModelNames = modelNames(validIndices);

            % Sort filtered data by RMSE
            [sortedRMSE, sortedIndices] = sort(filteredRMSE);
            sortedModelNames = filteredModelNames(sortedIndices);

            % Prepare data for display
            sortedData = cell(length(sortedRMSE), 2);
            for i = 1:length(sortedRMSE)
                sortedData{i, 1} = sortedModelNames{i}; % Model name
                sortedData{i, 2} = sortedRMSE(i);       % RMSE value
            end

            % Create a popup GUI window for the sorted table
            f = uifigure('Name', 'Model scanning results', 'Position', [100, 100, 400, 300]);
            uitable(f, ...
                'Data', sortedData, ...
                'ColumnName', {'Model Name', 'RMSE'}, ...
                'Position', [25, 50, 350, 200], ...
                'ColumnEditable', [false, false]); % Table is not editable

            % Add a title label to the popup
            uilabel(f, ...
                'Text', 'Valid RMSE-sorted models', ...
                'Position', [100, 260, 200, 20], ...
                'FontWeight', 'bold');

            app.ModelDropDown.Value = sortedModelNames{1};
            % Create a dummy event to pass to the ModelDropDownValueChanged function
            eventModelDropDown = struct('Source', app.ModelDropDown, 'Value', app.ModelDropDown.Value);
            % Trigger the ModelDropDownValueChanged function
            ModelDropDownValueChanged(app, eventModelDropDown);

        end

        % Button pushed function: ResetSessionButton
        function ResetSessionButtonPushed(app, event)

            % Reset data structure
            app.dataStruct = struct();

            % Disable buttons and switches
            app.FitButton.Enable = 'off';
            app.ScanAllModelsButton.Enable = 'off';
            app.DataSDSwitch.Enable = 'off';
            app.CallEvaluationButton.Enable = "off";

            % Clear the DataAxes thoroughly
            cla(app.DataAxes);
            title(app.DataAxes, '');   % Clear the title

            % Reset DataTable content
            app.DataTable.Data = {};

            % Reset FileNameEditField
            app.FileNameEditField.Value = '';
            app.FileNameEditField.Placeholder = 'filename';

            app.fitObject = cfit(fittype('a*x + 1', 'independent', 'x', 'coefficients', {'a'}), 0);

            % Clear the FitResultTable
            app.FitResultTable.Data = cell(0, 7); % Set to an empty cell array with the correct number of columns

            % Clear the CoeffResultsTable
            app.CoeffResultsTable.Data = cell(0, 4); % Set to an empty cell array with the correct number of columns

            % Reset dropdowns and their associated callbacks
            app.ModelDropDown.Value = 'Linear-Quadratic (LQ)';
            eventModelDropDown = struct('Source', app.ModelDropDown, 'Value', app.ModelDropDown.Value);
            ModelDropDownValueChanged(app, eventModelDropDown);

            app.AlgorithmDropDown.Value = 'Trust-Region';
            app.RobustDropDown.Value = 'Off';

            % Reset switches
            app.DataSDSwitch.Value = 'On';
            app.SigmaCBSwitch.Value = '1σ';
        end

        % Button pushed function: SavePlotButton
        function SavePlotButtonPushed(app, event)
            % Open file selection dialog with supported formats
            [file, path, ~] = uiputfile(...
                {'*.png', 'PNG Image (*.png)'; ...
                '*.jpg', 'JPEG Image (*.jpg)'; ...
                '*.tiff', 'TIFF Image (*.tiff)'; ...
                '*.pdf', 'PDF Document (*.pdf)'; ...
                '*.eps', 'EPS File (*.eps)'}, ...
                'Save Plot As');

            if isequal(file, 0) || isequal(path, 0)
                disp('User canceled the save operation');
                return; % User canceled
            end

            % Set the full file path
            fullFilePath = fullfile(path, file);

            % Get the axes from the app
            ax = app.DataAxes;

            try
                % Preserve font properties
                originalFontName = ax.FontName;
                originalFontSize = ax.FontSize;

                % Optionally, adjust resolution
                resolution = 300; % 300 DPI for high resolution

                % Use exportgraphics to save the axes
                exportgraphics(ax, fullFilePath, 'Resolution', resolution);

                % Restore the original font properties
                ax.FontName = originalFontName;
                ax.FontSize = originalFontSize;

                % Display a message box with the saved file location
                msg = ['Plot saved to ', fullFilePath];
                uialert(app.DataFigure, msg, 'Save Successful', 'Icon', 'success');
            catch ME
                % Handle potential errors
                uialert(app.DataFigure, ['Failed to save plot: ', ME.message], 'Save Error', 'Icon', 'error');
            end
        end

        % Button pushed function: CallEvaluationButton
        function CallEvaluationButtonPushed(app, event)
            % Triggered when CallEvaluationButton is pushed
            if app.SFButton.Value
                app.openEvaluationUI(true); % SF selected
            elseif app.DoseButton.Value
                app.openEvaluationUI(false); % Dose selected
            end
        end

        % Button pushed function: CleanEvaluationButton
        function CleanEvaluationButtonPushed(app, event)
            % --- Clean the previous fitted curve ---
            % Delete any previous fit (search for lines and scatter plots)

            % Find all line objects in the axes
            previousLines = findobj(app.DataAxes, 'Type', 'line');

            % Find all scatter objects in the axes
            previousScatters = findobj(app.DataAxes, 'Type', 'scatter');

            % Delete all line objects
            for i = 1:length(previousLines)
                if ismember(previousLines(i).Tag, {'vLine','hLine'})
                    delete(previousLines(i));
                end
            end

            % Delete all scatter objects
            for i = 1:length(previousScatters)
                delete(previousScatters(i));
            end
        end

        % Button pushed function: CleanFitButton
        function CleanFitButtonPushed(app, event)

            app.cleanPreviousFittedCurve;

            app.fitObject = cfit(fittype('a*x + 1', 'independent', 'x', 'coefficients', {'a'}), 0);

            % Disable buttons and switches
            app.CallEvaluationButton.Enable = "off";

            % Clear the FitResultTable
            app.FitResultTable.Data = cell(0, 7); % Set to an empty cell array with the correct number of columns

            % Clear the CoeffResultsTable
            app.CoeffResultsTable.Data = cell(0, 4); % Set to an empty cell array with the correct number of columns

            % Reset dropdowns and their associated callbacks
            app.ModelDropDown.Value = 'Linear-Quadratic (LQ)';
            eventModelDropDown = struct('Source', app.ModelDropDown, 'Value', app.ModelDropDown.Value);
            ModelDropDownValueChanged(app, eventModelDropDown);

            app.AlgorithmDropDown.Value = 'Trust-Region';
            app.RobustDropDown.Value = 'Off';

            % Reset switches
            app.DataSDSwitch.Value = 'On';
            app.SigmaCBSwitch.Value = '1σ';
        end

        % Button pushed function: ImportMultiSessionButton
        function ImportMultiSessionButtonPushed(app, event)
            % Import multiple sessions without clearing existing plots.
            try
                % Ask the user to select one or more .mat files
                [fileNames, pathName] = uigetfile('*.mat', 'Import Session', 'MultiSelect', 'on');

                % If the user cancels the operation
                if isequal(fileNames, 0)
                    disp('Load operation canceled.');
                    return;
                end

                % Ensure fileNames is a cell array (even for a single file)
                if ischar(fileNames)
                    fileNames = {fileNames}; % Convert to cell array
                end

                % Retrieve existing filenames and tags from MultiSessionTable
                existingData = app.MultiSessionTable.Data;
                if isempty(existingData) || size(existingData, 2) < 6
                    existingTags = {}; % No existing files
                else
                    % Combine column 3 (Source) and column 4 (Display Name) into a single tag
                    existingTags = strcat(existingData(:, 3), existingData(:, 4));
                end

                % Iterate over all selected files
                for i = 1:numel(fileNames)
                    fileName = fileNames{i}; % Current file name

                    % Load the .mat file
                    sessionData = load(fullfile(pathName, fileName));

                    % Verify that the required fields exist
                    if ~isfield(sessionData, 'plotData') || ~isfield(sessionData, 'titleText')
                        uialert(app.DataFigure, sprintf('Skipping "%s": missing required fields.', fileName), ...
                            'Invalid File', 'Icon', 'warning');
                        continue; % Skip to the next file
                    end

                    plotData = sessionData.plotData;
                    titleText = sessionData.titleText;

                    % Ensure plotData has the "Tag" field
                    if ~isfield(plotData, 'Tag')
                        uialert(app.DataFigure, sprintf('Skipping "%s": missing "Tag" field.', fileName), ...
                            'Invalid Data', 'Icon', 'warning');
                        continue; % Skip to the next file
                    end

                    % Generate new tags
                    newTags = {plotData.Tag};

                    % Check for duplicates
                    if any(ismember(newTags, existingTags))
                        uialert(app.DataFigure, sprintf('Some plots from "%s" have already been imported.', fileName), ...
                            'Duplicate Import', 'Icon', 'warning');
                        continue; % Skip to the next file
                    end

                    % Recreate the plot without overwriting existing ones
                    RestorePlot(app.MultiDataAxes, plotData, titleText, true);

                    % Update the MultiSessionTable
                    app.updateMultiSessionTable(plotData, fileName);

                    % Update existing tags to prevent duplicates in subsequent files
                    existingTags = existingTags(:); % Force column format
                    newTags = newTags(:);           % Force column format
                    existingTags = [existingTags; newTags]; %#ok<AGROW>

                    % Log message for each successfully imported file
                    disp(['Imported: ', fileName]);
                end

                % Final confirmation message
                uialert(app.DataFigure, 'All selected sessions have been loaded successfully!', 'Success', 'Icon', 'success');

            catch ME
                Handle errors and alert the user if loading fails
                uialert(app.DataFigure, sprintf('Error loading session:\n%s', ME.message), 'Error', 'Icon', 'error');
                disp(ME.message);
            end
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            % RefreshButtonPushed - Callback function for the Refresh button.
            % Updates plot colors and legend text based on user selections in the MultiSessionTable.
            %   - The object's color is updated based on the user-selected color (column 5).
            %     If DisplayName (column 4) is one of {'L', 'Q', 'LQ', 'LQC', 'LQL'}, the color is applied with an alpha of 0.3.
            %   - If a custom legend text is provided in the "Custom Entry" column (column 6),
            %     it is set as the object's DisplayName for the legend.

            % Retrieve updated table data
            tableData = app.MultiSessionTable.Data;
            if isempty(tableData)
                return; % No data, no update needed
            end

            % Iterate over the table rows to update plot properties (only for visible ones)
            for i = 1:size(tableData, 1)

                source = tableData{i, 3};        % Source column
                displayName = tableData{i, 4};     % Display Name column
                selectedColor = tableData{i, 5};   % User-selected color column
                customEntry = tableData{i, 6};     % Custom legend entry column

                % Generate the tag for finding the plotted object (concatenate source + displayName)
                tagToFind = strcat(source, displayName);

                % Find the object in the plot with the matching tag (using only the 'Tag' property)
                obj = findall(app.MultiDataAxes, 'Tag', tagToFind);
                if ~isempty(obj)
                    try
                        % Convert color name to RGB using the existing function
                        rgbColor = ColorNameToRGB(selectedColor);

                        % If displayName is in the transparent group, apply transparency (alpha = 0.3)
                        if ismember(displayName, {'L', 'Q', 'LQ', 'LQC', 'LQL'})
                            obj.Color = [rgbColor, 0.3];
                        else
                            obj.Color = rgbColor;
                            if isprop(obj, 'MarkerFaceColor')
                                obj.MarkerFaceColor = rgbColor;
                            end
                        end

                        % If a custom legend entry is provided, update the DisplayName property
                        if ~isempty(customEntry)
                            obj.DisplayName = customEntry;
                        end

                    catch
                        warning('Invalid color name: "%s". Skipping update for tag "%s".', selectedColor, tagToFind);
                    end
                end
            end
        end

        % Button pushed function: SaveMultiPlotButton
        function SaveMultiPlotButtonPushed(app, event)
            % Open file selection dialog with supported formats
            [file, path, ~] = uiputfile(...
                {'*.png', 'PNG Image (*.png)'; ...
                '*.jpg', 'JPEG Image (*.jpg)'; ...
                '*.tiff', 'TIFF Image (*.tiff)'; ...
                '*.pdf', 'PDF Document (*.pdf)'; ...
                '*.eps', 'EPS File (*.eps)'; ...
                '*.fig', 'MATLAB Figure (*.fig)'}, ...
                'Save Plot As');

            if isequal(file, 0) || isequal(path, 0)
                disp('User canceled the save operation');
                return; % User canceled
            end

            % Set the full file path
            fullFilePath = fullfile(path, file);

            % Get the axes from the app
            ax = app.MultiDataAxes;

            try
                % Preserve font properties
                originalFontName = ax.FontName;
                originalFontSize = ax.FontSize;

                % Check the selected format
                [~, ~, extension] = fileparts(fullFilePath);

                if strcmp(extension, '.fig')
                    % Save as a MATLAB figure (.fig)
                    f = figure('Visible', 'off'); % Create a hidden figure
                    newAx = copyobj(ax, f); % Copy axes to new figure
                    set(newAx, 'Units', 'normalized', 'Position', [0.1 0.1 0.8 0.8]); % Adjust position
                    savefig(f, fullFilePath); % Save figure
                    close(f); % Close the temporary figure

                else

                    % Optionally, adjust resolution
                    resolution = 300; % 300 DPI for high resolution

                    % Use exportgraphics to save the axes
                    exportgraphics(ax, fullFilePath, 'Resolution', resolution);
                end

                % Restore the original font properties
                ax.FontName = originalFontName;
                ax.FontSize = originalFontSize;

                % Display a message box with the saved file location
                msg = ['Plot saved to ', fullFilePath];
                uialert(app.DataFigure, msg, 'Save Successful', 'Icon', 'success');
            catch ME
                % Handle potential errors
                uialert(app.DataFigure, ['Failed to save plot: ', ME.message], 'Save Error', 'Icon', 'error');
            end
        end

        % Button pushed function: ImportMultiSessionFigureButton
        function ImportMultiSessionFigureButtonPushed(app, event)
            % Prompt the user to select a .fig file
            [fileName, pathName] = uigetfile('*.fig', 'Select a saved figure');

            % If the user cancels the operation, exit the function
            if isequal(fileName, 0)
                disp('Operation canceled.');
                return;
            end

            % Clear the axes before importing new plots
            cla(app.MultiDataAxes);

            % Clear the session table
            app.MultiSessionTable.Data = {};

            % Open the saved figure in invisible mode
            fig = openfig(fullfile(pathName, fileName), 'invisible');

            % Get the main axes of the figure (assuming only one relevant axes)
            axObjs = findall(fig, 'Type', 'axes');

            if isempty(axObjs)
                uialert(app.DataFigure, 'No valid axes found in the figure.', 'Import Error', 'Icon', 'error');
                close(fig);
                return;
            end

            % Use GetPlotData to retrieve all plot details
            [plotData, titleText] = GetPlotData(axObjs(1));

            % Close the imported figure to prevent display
            close(fig);

            % Define color drop-down options
            colorOptions = { ...
                'Red', 'Light Red', 'Dark Red', ...
                'Green', 'Light Green', 'Dark Green', ...
                'Blue', 'Light Blue', 'Dark Blue', ...
                'Yellow', 'Light Yellow', 'Dark Yellow', ...
                'Cyan', 'Light Cyan', 'Dark Cyan', ...
                'Magenta', 'Light Magenta', 'Dark Magenta', ...
                'Black', 'Gray', 'Dark Gray', ...
                'Orange', 'Light Orange', 'Dark Orange', ...
                'Purple', 'Light Purple', 'Dark Purple', ...
                'Pink', 'Light Pink', 'Dark Pink' ...
                };

            % Initialize a cell array for table data
            tableData = {};

            % Loop through the extracted plot data and populate the table
            for i = 1:numel(plotData)
                % Extract source from the Tag (up to ".txt")
                source = 'Unknown';

                % Determine if the plot should be visible in the table
                % If DisplayName is one of the excluded labels, set it to false
                excludeLabels = {'L', 'Q', 'LQ', 'LQC', 'LQL'};
                isVisible = ~any(strcmp(plotData(i).DisplayName, excludeLabels));

                % Ensure the DisplayName is not empty
                if isempty(plotData(i).DisplayName)
                    plotData(i).DisplayName = 'Unnamed Plot';
                end

                % Convert RGB color to a readable color name
                detectedColor = RGBToColorName(plotData(i).Color);

                % Check if detectedColor is in the predefined list, otherwise set to 'Black'
                if ~ismember(detectedColor, colorOptions)
                    detectedColor = 'Black';
                end

                customEntry = plotData(i).DisplayName;

                % Add the extracted data to the table
                tableData = [tableData; {isVisible, fileName, source, plotData(i).DisplayName, detectedColor, customEntry}]; %#ok<AGROW>
            end

            % Restore the plot on the app's main MultiDataAxes
            if ~isempty(plotData)
                RestorePlot(app.MultiDataAxes, plotData, titleText, true);
            end

            % Update the session table with the extracted data
            if isempty(app.MultiSessionTable.Data)
                app.MultiSessionTable.Data = tableData;
            else
                app.MultiSessionTable.Data = [app.MultiSessionTable.Data; tableData];
            end

            % Make the columns non-editable
            app.MultiSessionTable.Enable = 'off';
            app.MultiSessionTable.ColumnEditable = [false, false, false, false, false, false];

            % Make the RBEPanel non-editable
            app.RBEPanel.Enable = 'off';

            % Final confirmation message
            uialert(app.DataFigure, 'The selected session has been loaded successfully!', 'Success', 'Icon', 'success');

            disp(['Figure "' fileName '" successfully imported and restored.']);
        end

        % Button pushed function: ResetMultiSessionButton
        function ResetMultiSessionButtonPushed(app, event)
            % Clear the axes before importing new plots
            cla(app.MultiDataAxes);

            % Clear the session table
            app.MultiSessionTable.Data = {};

            % Make the columns partially editable
            app.MultiSessionTable.Enable = 'on';
            app.MultiSessionTable.ColumnEditable = [false, false, false, false, true, true];

            % Make the RBEPanel editable
            app.RBEPanel.Enable = 'on';

            % Clear the RBE table
            app.RBETable.Data = {};
            app.RBETable.ColumnName = {'SF'}; % Reset to default column
            app.RBETable.ColumnEditable = false; % Ensure it's not editable

            % Clear the reference file name field
            app.ReferenceFileNameEditField.Value = '';
        end

        % Button pushed function: CalculateRBEButton
        function CalculateRBEButtonPushed(app, event)
            % Button pushed function: CalculateRBEButton
            try
                % Define survival fractions of interest
                survivalFractions = [0.9, 0.8, 0.7, 0.6, 0.5, 0.4, 0.3, 0.2, 0.1];

                % Retrieve data from the table
                tableData = app.MultiSessionTable.Data;
                if isempty(tableData)
                    uialert(app.DataFigure, 'The data table is empty.', 'Error', 'Icon', 'error');
                    return;
                end

                % Define valid curve names
                validDisplayNames = {'L', 'Q', 'LQ', 'LQC', 'LQL'};

                % Extract reference curve (first row in the table)
                refSource = tableData{1, 3};        % Column 'Source'
                refDisplayName = tableData{1, 4};   % Column 'DisplayName'

                % Update the ReferenceFileNameEditField with refSource
                app.ReferenceFileNameEditField.Value = refSource;

                % Check if the reference curve is valid
                if ~ismember(refDisplayName, validDisplayNames)
                    uialert(app.DataFigure, ...
                        sprintf('Invalid reference curve. DisplayName must be one of: %s.', strjoin(validDisplayNames, ', ')), ...
                        'Error', 'Icon', 'error');
                    return;
                end

                refTag = strcat(refSource, refDisplayName);

                % Find the reference curve object in the axes
                refObj = findall(app.MultiDataAxes, 'Tag', refTag);
                if isempty(refObj)
                    uialert(app.DataFigure, ...
                        sprintf('Reference curve with tag "%s" not found in axes.', refTag), ...
                        'Error', 'Icon', 'error');
                    return;
                end
                doseRef = refObj.XData;
                sfRef = refObj.YData;

                % Interpolate reference curve doses for specified survival fractions
                doseRefInterp = interp1(sfRef, doseRef, survivalFractions, 'pchip', NaN);

                % Initialize the RBE table with SF column
                rbeTableData = num2cell(survivalFractions');  % First column: SF values
                rbeTableColumns = {'SF'};  % Column names

                % Iterate over table rows to find target curves
                for i = 2:size(tableData, 1)
                    source = tableData{i, 3};        % Column 'Source'
                    displayName = tableData{i, 4};   % Column 'DisplayName'
                    tagToFind = strcat(source, displayName);

                    if ismember(displayName, validDisplayNames)
                        % Find the target curve object in the axes
                        targetObj = findall(app.MultiDataAxes, 'Tag', tagToFind);
                        if isempty(targetObj)
                            uialert(app.DataFigure, ...
                                sprintf('Curve with tag "%s" not found in axes.', tagToFind), ...
                                'Warning', 'Icon', 'warning');
                            continue;
                        end
                        doseTarget = targetObj.XData;
                        sfTarget = targetObj.YData;

                        % Interpolate target curve doses
                        doseTargetInterp = interp1(sfTarget, doseTarget, survivalFractions, 'pchip', NaN);

                        % Compute RBE
                        rbe = doseRefInterp ./ doseTargetInterp;

                        % Append new data to the RBE table
                        rbeTableData = [rbeTableData, num2cell(rbe')]; %#ok<AGROW>
                        rbeTableColumns{end+1} = source; %#ok<AGROW>
                    end
                end

                % Update app.RBETable with new data
                app.RBETable.Data = rbeTableData;
                app.RBETable.ColumnName = rbeTableColumns;

                % Make all columns non-editable
                app.RBETable.ColumnEditable = false(1, numel(rbeTableColumns));

            catch ME
                % Display errors in a UI pop-up
                uialert(app.DataFigure, ME.message, 'Error', 'Icon', 'error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.DataFigure = uifigure('Visible', 'off');
            app.DataFigure.Name = 'Survival Data Analysis';
            app.DataFigure.Tag = 'DataFigure';
            app.TabGroup = uitabgroup(app.DataFigure);
            app.TabGroup.Tag = 'TabGroup';
            app.FitterTab = uitab(app.TabGroup);
            app.FitterTab.Title = 'Fitter';
            app.FitterTab.Tag = 'FitterTab';
            app.DataAxes = uiaxes(app.FitterTab);
            xlabel(app.DataAxes, 'Dose [Gy]')
            ylabel(app.DataAxes, 'Survival fraction')
            app.DataAxes.XLim = [0 12];
            app.DataAxes.YLim = [0.1 1];
            app.DataAxes.ZLim = [0 1];
            app.DataAxes.YTick = [0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.DataAxes.YScale = 'log';
            app.DataAxes.YTickLabel = {'0.1'; '0.2'; '0.3'; '0.4'; '0.5'; '0.6'; '0.7'; '0.8'; '0.9'; '1.0'};
            app.DataAxes.ZTick = [];
            app.DataAxes.Tag = 'DataAxes';
            app.FitOptionsPanel = uipanel(app.FitterTab);
            app.FitOptionsPanel.Title = 'Fit Options';
            app.FitOptionsPanel.Tag = 'FitOptionsPanel';
            app.ModelDropDownLabel = uilabel(app.FitOptionsPanel);
            app.ModelDropDownLabel.Text = 'Model';
            app.ModelDropDown = uidropdown(app.FitOptionsPanel);
            app.ModelDropDown.Items = {'Linear (L)', 'Quadratic (Q)', 'Linear-Quadratic (LQ)', 'Linear-Quadratic-Cubic (LQC)', 'Linear-Quadratic-Linear (LQL)'};
            app.ModelDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelDropDownValueChanged, true);
            app.ModelDropDown.Tag = 'ModelDropDown';
            app.ModelDropDown.Value = 'Linear-Quadratic (LQ)';
            app.RobustDropDownLabel = uilabel(app.FitOptionsPanel);
            app.RobustDropDownLabel.Text = 'Robust';
            app.RobustDropDown = uidropdown(app.FitOptionsPanel);
            app.RobustDropDown.Items = {'Off', 'LAR', 'Bisquare'};
            app.RobustDropDown.Tag = 'RobustDropDown';
            app.RobustDropDown.Value = 'Off';
            app.FitButton = uibutton(app.FitOptionsPanel, 'push');
            app.FitButton.ButtonPushedFcn = createCallbackFcn(app, @FitButtonPushed, true);
            app.FitButton.Tag = 'FitButton';
            app.FitButton.Icon = fullfile(pathToMLAPP, 'icons', 'cadence_100dp_000000_FILL1_wght400_GRAD0_opsz48.svg');
            app.FitButton.Enable = 'off';
            app.FitButton.Text = 'Fit';
            app.AlgorithmDropDownLabel = uilabel(app.FitOptionsPanel);
            app.AlgorithmDropDownLabel.Enable = 'off';
            app.AlgorithmDropDownLabel.Text = 'Algorithm';
            app.AlgorithmDropDown = uidropdown(app.FitOptionsPanel);
            app.AlgorithmDropDown.Items = {'Trust-Region', 'Levenberg-Marquardt'};
            app.AlgorithmDropDown.Tag = 'AlgorithmDropDown';
            app.AlgorithmDropDown.Enable = 'off';
            app.AlgorithmDropDown.Value = 'Trust-Region';
            app.CoefficientsTable = uitable(app.FitOptionsPanel);
            app.CoefficientsTable.ColumnName = {'Coefficients'; 'Start Point'; 'Lower'; 'Upper'};
            app.CoefficientsTable.RowName = {};
            app.CoefficientsTable.ColumnEditable = [false true true true];
            app.CoefficientsTable.Tag = 'CoefficientsTable';
            app.DataSDLabel = uilabel(app.FitOptionsPanel);
            app.DataSDLabel.Text = 'Data SD';
            app.DataSDSwitch = uiswitch(app.FitOptionsPanel, 'slider');
            app.DataSDSwitch.ValueChangedFcn = createCallbackFcn(app, @DataSDSwitchValueChanged, true);
            app.DataSDSwitch.Tag = 'DataSDSwitch';
            app.DataSDSwitch.Enable = 'off';
            app.DataSDSwitch.Value = 'On';
            app.ScanAllModelsButton = uibutton(app.FitOptionsPanel, 'push');
            app.ScanAllModelsButton.ButtonPushedFcn = createCallbackFcn(app, @ScanAllModelsButtonPushed, true);
            app.ScanAllModelsButton.Tag = 'ScanAllModelsButton';
            app.ScanAllModelsButton.Icon = fullfile(pathToMLAPP, 'icons', 'category_search_100dp_000000_FILL1_wght400_GRAD0_opsz48.svg');
            app.ScanAllModelsButton.Enable = 'off';
            app.ScanAllModelsButton.Tooltip = {'Scan available models'};
            app.ScanAllModelsButton.Text = '';
            app.FitResultTable = uitable(app.FitterTab);
            app.FitResultTable.ColumnName = {'Fit State'; 'Fit Model'; 'SSE'; 'R-square'; 'DFE'; 'Adj R-sq'; 'RMSE'};
            app.FitResultTable.RowName = {};
            app.FitResultTable.Tag = 'FitResultTable';
            app.DataPanel = uipanel(app.FitterTab);
            app.DataPanel.Title = 'Data';
            app.DataPanel.Tag = 'DataPanel';
            app.SelectDataButton = uibutton(app.DataPanel, 'push');
            app.SelectDataButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDataButtonPushed, true);
            app.SelectDataButton.Tag = 'SelectDataButton';
            app.SelectDataButton.Icon = fullfile(pathToMLAPP, 'icons', 'upload_file_100dp_000000_FILL1_wght400_GRAD0_opsz48.svg');
            app.SelectDataButton.Tooltip = {'Upload text data file'};
            app.SelectDataButton.Text = '(*.txt)';
            app.DataTable = uitable(app.DataPanel);
            app.DataTable.ColumnName = {'Dose [Gy]'; 'SF'; 'SD'};
            app.DataTable.SelectionType = 'row';
            app.DataTable.Tag = 'DataTable';
            app.ImportSessionButton = uibutton(app.DataPanel, 'push');
            app.ImportSessionButton.ButtonPushedFcn = createCallbackFcn(app, @ImportSessionButtonPushed, true);
            app.ImportSessionButton.Tag = 'ImportSessionButton';
            app.ImportSessionButton.Icon = fullfile(pathToMLAPP, 'icons', 'upload_100dp_000000_FILL1_wght400_GRAD0_opsz48.svg');
            app.ImportSessionButton.Tooltip = {'Import MATLAB session'};
            app.ImportSessionButton.Text = '(*.mat)';
            app.FileNameEditField = uieditfield(app.DataPanel, 'text');
            app.FileNameEditField.Tag = 'FileNameEditField';
            app.FileNameEditField.Editable = 'off';
            app.FileNameEditField.Placeholder = 'filename';
            app.ResultsPanel = uipanel(app.FitterTab);
            app.ResultsPanel.Title = 'Results';
            app.ResultsPanel.Tag = 'ResultsPanel';
            app.CoeffResultsTable = uitable(app.ResultsPanel);
            app.CoeffResultsTable.ColumnName = {'Coefficients'; 'Value'; 'Lower'; 'Upper'};
            app.CoeffResultsTable.RowName = {};
            app.CoeffResultsTable.ColumnEditable = [false true true true];
            app.CoeffResultsTable.Tag = 'CoeffResultsTable';
            app.SigmaCBSwitch = uiswitch(app.ResultsPanel, 'slider');
            app.SigmaCBSwitch.Items = {'95%CB', '1σ'};
            app.SigmaCBSwitch.ValueChangedFcn = createCallbackFcn(app, @SigmaCBSwitchValueChanged, true);
            app.SigmaCBSwitch.Tag = 'SigmaCBSwitch';
            app.SigmaCBSwitch.Value = '1σ';
            app.SessionPanel = uipanel(app.FitterTab);
            app.SessionPanel.Title = 'Session';
            app.SessionPanel.Tag = 'SessionPanel';
            app.ResetSessionButton = uibutton(app.SessionPanel, 'push');
            app.ResetSessionButton.ButtonPushedFcn = createCallbackFcn(app, @ResetSessionButtonPushed, true);
            app.ResetSessionButton.Tag = 'ResetSessionButton';
            app.ResetSessionButton.Icon = fullfile(pathToMLAPP, 'icons', 'reset_settings_100dp_000000_FILL1_wght400_GRAD0_opsz48.svg');
            app.ResetSessionButton.Tooltip = {'Reset session'};
            app.ResetSessionButton.Text = '';
            app.SaveSessionButton = uibutton(app.SessionPanel, 'push');
            app.SaveSessionButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSessionButtonPushed, true);
            app.SaveSessionButton.Tag = 'SaveSessionButton';
            app.SaveSessionButton.Icon = fullfile(pathToMLAPP, 'icons', 'save_as_100dp_000000_FILL1_wght400_GRAD0_opsz48.svg');
            app.SaveSessionButton.Tooltip = {'Save current session'};
            app.SaveSessionButton.Text = '';
            app.SavePlotButton = uibutton(app.SessionPanel, 'push');
            app.SavePlotButton.ButtonPushedFcn = createCallbackFcn(app, @SavePlotButtonPushed, true);
            app.SavePlotButton.Tag = 'SavePlotButton';
            app.SavePlotButton.Icon = fullfile(pathToMLAPP, 'icons', 'file_save_100dp_000000_FILL0_wght400_GRAD0_opsz48.svg');
            app.SavePlotButton.Tooltip = {'Save plot as ...'};
            app.SavePlotButton.Text = '';
            app.CleanFitButton = uibutton(app.SessionPanel, 'push');
            app.CleanFitButton.ButtonPushedFcn = createCallbackFcn(app, @CleanFitButtonPushed, true);
            app.CleanFitButton.Tag = 'CleanFitButton';
            app.CleanFitButton.Icon = fullfile(pathToMLAPP, 'icons', 'reset_image_100dp_000000_FILL1_wght400_GRAD0_opsz48.svg');
            app.CleanFitButton.Tooltip = {'Reset fit'};
            app.CleanFitButton.Text = '';
            app.SFDoseButtonGroup = uibuttongroup(app.FitterTab);
            app.SFDoseButtonGroup.Tag = 'SFDoseButtonGroup';
            app.SFButton = uiradiobutton(app.SFDoseButtonGroup);
            app.SFButton.Tag = 'SFButton';
            app.SFButton.Text = 'SF';
            app.SFButton.Value = true;
            app.DoseButton = uiradiobutton(app.SFDoseButtonGroup);
            app.DoseButton.Tag = 'DoseButton';
            app.DoseButton.Text = 'Dose';
            app.CallEvaluationButton = uibutton(app.FitterTab, 'push');
            app.CallEvaluationButton.ButtonPushedFcn = createCallbackFcn(app, @CallEvaluationButtonPushed, true);
            app.CallEvaluationButton.Tag = 'CallEvaluationButton';
            app.CallEvaluationButton.Icon = fullfile(pathToMLAPP, 'icons', 'function_100dp_000000_FILL0_wght400_GRAD0_opsz48.svg');
            app.CallEvaluationButton.Enable = 'off';
            app.CallEvaluationButton.Tooltip = {'Fit interpolator'};
            app.CallEvaluationButton.Text = '';
            app.CleanEvaluationButton = uibutton(app.FitterTab, 'push');
            app.CleanEvaluationButton.ButtonPushedFcn = createCallbackFcn(app, @CleanEvaluationButtonPushed, true);
            app.CleanEvaluationButton.Tag = 'CleanEvaluationButton';
            app.CleanEvaluationButton.Icon = fullfile(pathToMLAPP, 'icons', 'mop_100dp_000000_FILL1_wght400_GRAD0_opsz48.svg');
            app.CleanEvaluationButton.Tooltip = {'Clean interpolations'};
            app.CleanEvaluationButton.Text = '';
            app.ViewerTab = uitab(app.TabGroup);
            app.ViewerTab.Title = 'Viewer';
            app.ViewerTab.Tag = 'ViewerTab';
            app.MultiDataAxes = uiaxes(app.ViewerTab);
            xlabel(app.MultiDataAxes, 'Dose [Gy]')
            ylabel(app.MultiDataAxes, 'Survival fraction')
            app.MultiDataAxes.XLim = [0 12];
            app.MultiDataAxes.YLim = [0.1 1];
            app.MultiDataAxes.ZLim = [0 1];
            app.MultiDataAxes.YTick = [0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.MultiDataAxes.YScale = 'log';
            app.MultiDataAxes.YTickLabel = {'0.1'; '0.2'; '0.3'; '0.4'; '0.5'; '0.6'; '0.7'; '0.8'; '0.9'; '1.0'};
            app.MultiDataAxes.ZTick = [];
            app.MultiDataAxes.Tag = 'MultiDataAxes';
            app.MultiSessionPanel = uipanel(app.ViewerTab);
            app.MultiSessionPanel.Title = 'Multi Session';
            app.MultiSessionPanel.Tag = 'MultiSessionPanel';
            app.MultiSessionTable = uitable(app.MultiSessionPanel);
            app.MultiSessionTable.ColumnName = {'Visibility'; 'File Name'; 'Source'; 'Display Name'; 'Color'; 'Custom Entry'};
            app.MultiSessionTable.ColumnEditable = [false false false false true true];
            app.MultiSessionTable.Tag = 'MultiSessionTable';
            app.ImportMultiSessionButton = uibutton(app.MultiSessionPanel, 'push');
            app.ImportMultiSessionButton.ButtonPushedFcn = createCallbackFcn(app, @ImportMultiSessionButtonPushed, true);
            app.ImportMultiSessionButton.Tag = 'ImportMultiSessionButton';
            app.ImportMultiSessionButton.Icon = fullfile(pathToMLAPP, 'icons', 'publish_100dp_000000_FILL1_wght400_GRAD0_opsz48.svg');
            app.ImportMultiSessionButton.Tooltip = {'Import MATLAB multi-session'};
            app.ImportMultiSessionButton.Text = '(*.mat)';
            app.RefreshButton = uibutton(app.MultiSessionPanel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Tag = 'RefreshButton';
            app.RefreshButton.Icon = fullfile(pathToMLAPP, 'icons', 'refresh_100dp_000000_FILL1_wght400_GRAD0_opsz48.svg');
            app.RefreshButton.Tooltip = {'Refresh plot'};
            app.RefreshButton.Text = '';
            app.SaveMultiPlotButton = uibutton(app.MultiSessionPanel, 'push');
            app.SaveMultiPlotButton.ButtonPushedFcn = createCallbackFcn(app, @SaveMultiPlotButtonPushed, true);
            app.SaveMultiPlotButton.Tag = 'SaveMultiPlotButton';
            app.SaveMultiPlotButton.Icon = fullfile(pathToMLAPP, 'icons', 'file_save_100dp_000000_FILL0_wght400_GRAD0_opsz48.svg');
            app.SaveMultiPlotButton.Tooltip = {'Save plot as ...'};
            app.SaveMultiPlotButton.Text = '';
            app.ImportMultiSessionFigureButton = uibutton(app.MultiSessionPanel, 'push');
            app.ImportMultiSessionFigureButton.ButtonPushedFcn = createCallbackFcn(app, @ImportMultiSessionFigureButtonPushed, true);
            app.ImportMultiSessionFigureButton.Tag = 'ImportMultiSessionFigureButton';
            app.ImportMultiSessionFigureButton.Icon = fullfile(pathToMLAPP, 'icons', 'upload_file_100dp_000000_FILL0_wght400_GRAD0_opsz48.svg');
            app.ImportMultiSessionFigureButton.Tooltip = {'Import MATLAB figure'};
            app.ImportMultiSessionFigureButton.Text = '(*.fig)';
            app.ResetMultiSessionButton = uibutton(app.MultiSessionPanel, 'push');
            app.ResetMultiSessionButton.ButtonPushedFcn = createCallbackFcn(app, @ResetMultiSessionButtonPushed, true);
            app.ResetMultiSessionButton.Tag = 'ResetMultiSessionButton';
            app.ResetMultiSessionButton.Icon = fullfile(pathToMLAPP, 'icons', 'reset_settings_100dp_000000_FILL1_wght400_GRAD0_opsz48.svg');
            app.ResetMultiSessionButton.Tooltip = {'Reset multi-session'};
            app.ResetMultiSessionButton.Text = '';
            app.RBEPanel = uipanel(app.ViewerTab);
            app.RBEPanel.Title = 'RBE';
            app.RBEPanel.Tag = 'RBEPanel';
            app.RBETable = uitable(app.RBEPanel);
            app.RBETable.ColumnName = {'SF'};
            app.RBETable.SelectionType = 'row';
            app.RBETable.Tag = 'RBETable';
            app.ReferenceFileNameEditField = uieditfield(app.RBEPanel, 'text');
            app.ReferenceFileNameEditField.Tag = 'ReferenceFileNameEditField';
            app.ReferenceFileNameEditField.Editable = 'off';
            app.ReferenceFileNameEditField.Tooltip = {'Source filename for the reference radiation curve'};
            app.ReferenceFileNameEditField.Placeholder = 'filename';
            app.CalculateRBEButton = uibutton(app.RBEPanel, 'push');
            app.CalculateRBEButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateRBEButtonPushed, true);
            app.CalculateRBEButton.Tag = 'CalculateRBEButton';
            app.CalculateRBEButton.Icon = fullfile(pathToMLAPP, 'icons', 'calculate_100dp_000000_FILL1_wght400_GRAD0_opsz48.svg');
            app.CalculateRBEButton.Tooltip = {'Calculate RBE'};
            app.CalculateRBEButton.Text = '';
            app.ReferenceLabel = uilabel(app.RBEPanel);
            app.ReferenceLabel.Tag = 'ReferenceLabel';
            app.ReferenceLabel.Text = 'Reference :';
            % Show the figure after all components are created
            app.DataFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SDAnext

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.DataFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.DataFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DataFigure)
        end
    end
end