classdef SDAnext < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DataFigure                      matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        FitterTab                       matlab.ui.container.Tab
        AdjSSEEditField                 matlab.ui.control.NumericEditField
        AdjSSEEditFieldLabel            matlab.ui.control.Label
        SFDoseButtonGroup               matlab.ui.container.ButtonGroup
        CallEvaluationButton            matlab.ui.control.Button
        CleanEvaluationButton           matlab.ui.control.Button
        DoseButton                      matlab.ui.control.RadioButton
        SFButton                        matlab.ui.control.RadioButton
        SessionPanel                    matlab.ui.container.Panel
        CleanFitButton                  matlab.ui.control.Button
        SavePlotButton                  matlab.ui.control.Button
        SaveSessionButton               matlab.ui.control.Button
        ResetSessionButton              matlab.ui.control.Button
        ResultsPanel                    matlab.ui.container.Panel
        ExportDataFitButton             matlab.ui.control.Button
        AlphaBetaButton                 matlab.ui.control.Button
        SigmaCBSwitch                   matlab.ui.control.Switch
        CoeffResultsTable               matlab.ui.control.Table
        DataLoaderPanel                 matlab.ui.container.Panel
        FileNameEditField               matlab.ui.control.EditField
        ImportSessionButton             matlab.ui.control.Button
        DataTable                       matlab.ui.control.Table
        SelectDataButton                matlab.ui.control.Button
        FitResultTable                  matlab.ui.control.Table
        FitOptionsPanel                 matlab.ui.container.Panel
        ScanAllModelsButton             matlab.ui.control.Button
        DataSDSwitch                    matlab.ui.control.Switch
        DataSDLabel                     matlab.ui.control.Label
        CoefficientsTable               matlab.ui.control.Table
        AlgorithmDropDown               matlab.ui.control.DropDown
        AlgorithmDropDownLabel          matlab.ui.control.Label
        FitButton                       matlab.ui.control.Button
        RobustDropDown                  matlab.ui.control.DropDown
        RobustDropDownLabel             matlab.ui.control.Label
        ModelDropDown                   matlab.ui.control.DropDown
        ModelDropDownLabel              matlab.ui.control.Label
        DataAxes                        matlab.ui.control.UIAxes
        ViewerTab                       matlab.ui.container.Tab
        ExportRBETableButton            matlab.ui.control.Button
        ResetSpinnersButton             matlab.ui.control.Button
        GraphScalingSpinner             matlab.ui.control.Spinner
        AxesLabel_2                     matlab.ui.control.Label
        LegendFontSizeSpinner           matlab.ui.control.Spinner
        LegendFontLabel                 matlab.ui.control.Label
        LabelsFontSizeSpinner           matlab.ui.control.Spinner
        AxesLabel                       matlab.ui.control.Label
        DmaxSpinner                     matlab.ui.control.Spinner
        DmaxGySpinnerLabel              matlab.ui.control.Label
        SFminSpinner                    matlab.ui.control.Spinner
        SFminSpinnerLabel               matlab.ui.control.Label
        RBECalculatorPanel              matlab.ui.container.Panel
        ReferenceDropDown               matlab.ui.control.DropDown
        ReferenceLabel_2                matlab.ui.control.Label
        RBEInputButtonGroup             matlab.ui.container.ButtonGroup
        RBEDoseButton                   matlab.ui.control.RadioButton
        RBESFButton                     matlab.ui.control.RadioButton
        CalculateRBEButton              matlab.ui.control.Button
        RBETable                        matlab.ui.control.Table
        MultiSessionLoaderManagerPanel  matlab.ui.container.Panel
        ResetMultiSessionButton         matlab.ui.control.Button
        ImportMultiSessionFigureButton  matlab.ui.control.Button
        SaveMultiPlotButton             matlab.ui.control.Button
        RefreshButton                   matlab.ui.control.Button
        ImportMultiSessionButton        matlab.ui.control.Button
        MultiSessionTable               matlab.ui.control.Table
        MultiDataAxes                   matlab.ui.control.UIAxes
    end


    properties (Access = private)
        dataStruct = struct();
        fitObject = cfit(fittype('a*x + 1', 'independent', 'x', 'coefficients', {'a'}), 0);
        lastGOF = struct();
        lastOutput = struct();
        plotCheckbox = false;
        LegendHandle matlab.graphics.illustration.Legend
        OriginalMultiPlotData = [];
        MultiSessionContextMenu matlab.ui.container.ContextMenu
        RemoveByFileMenuItem matlab.ui.container.Menu
        MultiSessionSelectedRow = [];
    end

    properties (Access = public)
        Version = '0.7'; % Dynamic mlapp version
    end

    methods (Access = private)

        function fitOptions = getFitOptionsFromFitOptionsPanel(app)
            fitOptions.Algorithm = app.AlgorithmDropDown.Value;
            fitOptions.Robust = app.RobustDropDown.Value;

            % Retrieve the data from the CoefficientsTable
            tableData = app.CoefficientsTable.Data;

            % Validate that tableData is not empty
            if isempty(tableData)
                % error('The coefficients table is empty. Cannot retrieve fit options.');
                uialert(app.DataFigure, ...
                    'The coefficients table is empty. Cannot retrieve fit options.', ...
                    'Fit error', ...
                    'Icon','error');
                return;
            end

            % Fill the fitOptions struct
            fitOptions.StartPoint = cell2mat(tableData(:, 2));
            fitOptions.Lower = cell2mat(tableData(:, 3));
            fitOptions.Upper = cell2mat(tableData(:, 4));
        end

        function modelStruct = getSelectedModelStruct(app)
            % Map descriptive dropdown names to model keys
            modelMap = containers.Map(...
                app.ModelDropDown.Items, ...
                GetModelsMap() ...
                );

            % Get the selected model description
            selectedModelDesc = app.ModelDropDown.Value;

            % Validate and retrieve the corresponding model key
            if isKey(modelMap, selectedModelDesc)
                selectedModel = modelMap(selectedModelDesc);
            else
                % error('Invalid model selected in the dropdown.');
                uialert(app.DataFigure, ...
                    'Invalid model selected in the dropdown.', ...
                    'Invalid model', ...
                    'Icon','error');
                return;
            end

            % Retrieve the modelStruct for the selected model
            modelStruct = SelectModel(selectedModel);

            % Modify modelStruct if the DisplayName is 'LQL'
            if strcmp(modelStruct.DisplayName, 'LQL')
                % Compute mean, min, and max of app.dataStruct.x
                xData = app.dataStruct.x;
                modelStruct.StartPoint(3) = mean(xData);
                modelStruct.Lower(3) = min(xData);
                modelStruct.Upper(3) = max(xData);
            end
        end

        function populateDataTable(app, dataStruct)
            % Clear the DataTable before populating
            app.DataTable.Data = {};  % Clears any existing data in the DataTable

            % Format the data to scientific notation
            formattedDose = arrayfun(@(x) sprintf('%3.3f', x), dataStruct.dose, 'UniformOutput', false);
            formattedSurvivalFraction = arrayfun(@(x) sprintf('%3.3f', x), dataStruct.survivalFraction, 'UniformOutput', false);

            if isempty(dataStruct.stdDev)
                % No standard deviation column
                formattedStdDev = repmat({''}, length(dataStruct.dose), 1);
            else
                formattedStdDev = arrayfun(@(x) sprintf('%3.3f', x), dataStruct.stdDev, 'UniformOutput', false);
            end

            % Combine the formatted data into a cell array
            tableData = [formattedDose, formattedSurvivalFraction, formattedStdDev];

            % Set the DataTable data
            app.DataTable.Data = tableData;

            % Add row names with sequential numbering
            numRows = size(tableData, 1);
            rowNames = arrayfun(@(x) num2str(x), 1:numRows, 'UniformOutput', false);
            app.DataTable.RowName = rowNames;
        end

        function populateCoefficientsTable(app, modelStruct)
            % Retrieve data from the modelStruct
            coefficients = modelStruct.Coefficients; % Cell array of coefficient names
            startPoints = modelStruct.StartPoint;    % Numeric array of start points
            lowerBounds = modelStruct.Lower;         % Numeric array of lower bounds
            upperBounds = modelStruct.Upper;         % Numeric array of upper bounds

            % Validate data sizes
            nCoefficients = numel(coefficients);
            if ~(numel(startPoints) == nCoefficients && numel(lowerBounds) == nCoefficients && numel(upperBounds) == nCoefficients)
                uialert(app.DataFigure, 'Mismatch in model parameter sizes.', 'Data Validation Error', 'Icon', 'error');
                return;
            end

            % Create table data for display
            tableData = cell(nCoefficients, 4);
            for i = 1:nCoefficients
                tableData{i, 1} = coefficients{i};    % Coefficient name
                tableData{i, 2} = startPoints(i);     % Start point
                tableData{i, 3} = lowerBounds(i);     % Lower bound
                tableData{i, 4} = upperBounds(i);     % Upper bound
            end

            % Assign data to the CoefficientsTable
            app.CoefficientsTable.Data = tableData;

        end

        function populateFitResultTable(app, GOF, Output)
            % Populate the FitResultTable in the app with fit results

            % Extract fit state and model details
            fitState = int8(Output.exitflag); % Fit state from Output
            fitModel = app.ModelDropDown.Value;

            % Extract goodness-of-fit metrics
            sse = GOF.sse;           % Sum of Squares due to Error
            rsquare = GOF.rsquare;   % Coefficient of Determination
            dfe = int8(GOF.dfe);           % Degrees of Freedom in Error
            adjrsquare = GOF.adjrsquare; % Adjusted R-Square
            rmse = GOF.rmse;         % Root Mean Square Error

            % Prepare the table data (row format)
            tableData = {
                fitState, ...
                fitModel, ...
                sse, ...
                rsquare, ...
                dfe, ...
                adjrsquare, ...
                rmse
                };

            % Assign the data to the FitResultTable
            app.FitResultTable.Data = tableData;
        end

        function plotData(app, datafileStruct)
            % Clear the axes before plotting
            cla(app.DataAxes);

            % Default values for Color and DisplayName
            defaultColor = 'k';
            defaultDisplayName = 'data';

            try
                % Check if standard deviation is provided for error bars
                if ~isempty(datafileStruct.stdDev)
                    % Plot data with error bars
                    survivalPlot = errorbar(app.DataAxes, datafileStruct.dose, datafileStruct.survivalFraction, datafileStruct.stdDev, ...
                        'o', 'Color', defaultColor, 'MarkerEdgeColor', defaultColor, 'MarkerFaceColor', defaultColor, 'MarkerSize', 5);
                else
                    % Plot data without error bars
                    survivalPlot = plot(app.DataAxes, datafileStruct.dose, datafileStruct.survivalFraction, ...
                        'o', 'Color', defaultColor, 'MarkerEdgeColor', defaultColor, 'MarkerFaceColor', defaultColor, 'MarkerSize', 5);
                end

                % Customize the error bar appearance
                if ~isempty(datafileStruct.stdDev)
                    survivalPlot.LineStyle = 'none';
                    survivalPlot.Color = defaultColor; % Default color
                    survivalPlot.LineWidth = 1;
                    survivalPlot.CapSize = 0;
                end

                % Assign properties from headerInfo if available
                if isfield(datafileStruct.headerInfo, 'Color') && ~isempty(datafileStruct.headerInfo.Color)
                    % Validate color
                    try
                        survivalPlot.Color = ColorNameToRGB(datafileStruct.headerInfo.Color);
                        survivalPlot.MarkerEdgeColor = ColorNameToRGB(datafileStruct.headerInfo.Color);
                        survivalPlot.MarkerFaceColor = ColorNameToRGB(datafileStruct.headerInfo.Color);
                    catch
                        % If setting color fails, use default color
                        uialert(app.DataFigure, 'Invalid color specified in headerInfo. Using default color ''black''.', 'Color Warning', 'Icon', 'warning');
                        survivalPlot.Color = defaultColor;
                        survivalPlot.MarkerEdgeColor = defaultColor;
                        survivalPlot.MarkerFaceColor = defaultColor;
                    end
                else
                    % Use default color
                    survivalPlot.Color = defaultColor;
                end

                if isfield(datafileStruct.headerInfo, 'DisplayName') && ~isempty(datafileStruct.headerInfo.DisplayName)
                    % Set display name
                    survivalPlot.DisplayName = datafileStruct.headerInfo.DisplayName;
                else
                    % Use default display name
                    uialert(app.DataFigure, 'Invalid display name specified in headerInfo. Using default display name ''data''.', 'Display name Warning', 'Icon', 'warning');
                    survivalPlot.DisplayName = defaultDisplayName;
                end

                % Customize the axes
                ax = app.DataAxes;
                ax.YLim = [min(datafileStruct.survivalFraction) * 0.8, 1];
                ax.XLim = [0, max(datafileStruct.dose) * 1.05];
                ax.YAxis.Scale = 'log';
                ax.YAxis.TickLabelFormat = '%3.1f';
                ax.YAxis.TickValues = 0.1:0.1:1;
                ax.XLabel.String = 'Dose [Gy]';
                ax.YLabel.String = 'Survival fraction';
                ax.FontName = 'Franklin Gothic Book';
                ax.FontWeight = 'bold';
                ax.XMinorTick = 'on';
                ax.YMinorTick = 'on';
                ax.XAxis.LineWidth = 1.5;
                ax.YAxis.LineWidth = 1.5;

                % Add legend
                leg = legend(app.DataAxes, 'Location', 'northeast', 'FontSize', 14);
                leg.Box = 'off';

                app.FitButton.Enable = 'on';
                app.ScanAllModelsButton.Enable = 'on';

            catch ME
                % Display an error message if the plot fails
                uialert(app.DataFigure, ['Plotting failed: ' ME.message], 'Plot Error', 'Icon', 'error');
                app.FitButton.Enable = 'off';
                app.ScanAllModelsButton.Enable = 'off';
                app.CallEvaluationButton.Enable = "off";
            end
        end

        function populateCoeffResultsTable(app, FitObject, GOF, Output)

            coeffNames  = coeffnames(FitObject);
            coeffValues = coeffvalues(FitObject);
            coeffCI     = confint(FitObject, 0.950);

            numCoeffs = numel(coeffNames);
            coeffErrors = NaN(1, numCoeffs); %#ok<NASGU>

            % ------------------------------------------------------------
            % Preferred source: covariance-aware sigma from FitModel output
            % ------------------------------------------------------------
            if nargin >= 4 && isstruct(Output) && ...
                    isfield(Output, 'CoefficientSigma') && ...
                    ~isempty(Output.CoefficientSigma) && ...
                    numel(Output.CoefficientSigma) == numCoeffs && ...
                    all(isfinite(Output.CoefficientSigma(:))) && ...
                    all(Output.CoefficientSigma(:) >= 0)

                coeffErrors = Output.CoefficientSigma(:)';

            else
                % --------------------------------------------------------
                % Fallback: convert 95% confidence bounds using finite-DFE t
                % --------------------------------------------------------
                if nargin >= 3 && isstruct(GOF) && isfield(GOF, 'dfe') && ...
                        isfinite(GOF.dfe) && GOF.dfe > 0
                    T = tinv(0.975, GOF.dfe);
                else
                    T = 1.96; % last-resort fallback only
                end

                coeffErrors = diff(coeffCI, 1, 1) ./ (2 * T);
            end

            data = cell(numCoeffs, 4);

            for i = 1:numCoeffs
                data{i, 1} = coeffNames{i};
                data{i, 2} = coeffValues(i);

                if strcmp(app.SigmaCBSwitch.Value, '95%CB')
                    data{i, 3} = coeffCI(1, i);
                    data{i, 4} = coeffCI(2, i);

                elseif strcmp(app.SigmaCBSwitch.Value, '1σ')
                    data{i, 3} = coeffErrors(i);
                    data{i, 4} = coeffErrors(i);
                end
            end

            app.CoeffResultsTable.Data = data;

        end

        function updateSwitchDataSD(app)
            % Get the data from the DataTable
            data = app.DataTable.Data;

            % Check if the "SD" column (3rd column) is not empty or entirely {''}
            if any(~cellfun(@isempty, data(:, 3))) || ~all(strcmp(data(:, 3), ''))
                % SD column is not entirely {''}
                app.DataSDSwitch.Enable = 'on'; % Enable the switch
                app.DataSDSwitch.Value = 'On'; % Set the switch to On
            else
                % SD column is entirely {''}
                app.DataSDSwitch.Enable = 'off'; % Disable the switch
                app.DataSDSwitch.Value = 'Off'; % Set the switch to Off
            end
        end

        function cleanPreviousFittedCurve(app)
            % --- Clean the previous fitted curve ---
            % Delete any previous fit (search for lines and scatter plots)

            % Find all line objects in the axes
            previousLines = findobj(app.DataAxes, 'Type', 'line');

            % Find all scatter objects in the axes
            previousScatters = findobj(app.DataAxes, 'Type', 'scatter');

            % Delete all line objects
            for i = 1:length(previousLines)
                if ismember(previousLines(i).DisplayName, GetModelsMap()) || ismember(previousLines(i).Tag, {'vLine','hLine'})
                    delete(previousLines(i));
                end
            end

            % Delete all scatter objects
            for i = 1:length(previousScatters)
                delete(previousScatters(i));
            end
        end


        function addWatermark(app)
            % Add a watermark to indicate that SD is provided but not used
            text(app.DataAxes, ...
                0.5, 0.5, ...                     % Position (normalized coordinates)
                'Data SD is provided but not used', ... % Message
                'Units', 'normalized', ...         % Normalize position in axes
                'HorizontalAlignment', 'center', ... % Center alignment
                'VerticalAlignment', 'middle', ...   % Middle alignment
                'FontName', 'Arial', ... % Font name
                'FontSize', 35, ...                % Font size
                'FontWeight', 'bold', ...          % Bold text
                'Color', [0.5, 0.5, 0.5, 0.3], ...      % Light gray color
                'Rotation', 45, ...                % Rotate the text for watermark effect
                'Tag', 'Watermark');               % Tag for easy removal
        end

        function removeWatermark(app)
            % Find and delete the watermark
            watermark = findobj(app.DataAxes, 'Tag', 'Watermark');
            if ~isempty(watermark)
                delete(watermark);
            end
        end

        function openEvaluationUI(app, isSFSelected)
            % Open the evaluation UI
            evalFig = uifigure('Name', 'Model evaluation', 'Position', [100, 100, 400, 300]);

            % Create a table with 'SF' and 'Dose' columns
            evalTable = uitable(evalFig, 'Position', [50, 100, 300, 150]);
            evalTable.ColumnName = {'SF', 'Dose [Gy]'}; % Column headers
            evalTable.Data = cell(5, 2); % Initialize table with 5 empty rows

            % Set font to Franklin Gothic Book
            evalTable.FontName = 'Franklin Gothic Book';

            % Set column alignment to left
            s = uistyle('HorizontalAlignment','left');
            addStyle(evalTable,s,'table','')

            % Set column editability based on the selection
            if isSFSelected
                evalTable.ColumnEditable = [false, true]; % 'Dose' column editable
            else
                evalTable.ColumnEditable = [true, false]; % 'SF' column editable
            end

            % -------------------------------------------------------------
            % Gray background for non-editable column
            % -------------------------------------------------------------
            editable = evalTable.ColumnEditable;
            grayStyle = uistyle('BackgroundColor', [0.9 0.9 0.9]);

            removeStyle(evalTable);

            for c = 1:numel(editable)
                if ~editable(c)
                    addStyle(evalTable, grayStyle, 'column', c);
                end
            end

            % Add a compute button with the corrected callback
            computeButton = uibutton(evalFig, 'Position', [180, 40, 35, 35], 'Text', '', ...
                'ButtonPushedFcn', @(btn, event) handleComputeButton(app, evalTable, isSFSelected));

            computeButton.Icon = fullfile(fileparts(mfilename('fullpath')), 'icons', 'calculate_100dp_000000_FILL1_wght400_GRAD0_opsz48.svg');

            % Add the checkbox for plotting
            app.plotCheckbox = uicheckbox(evalFig, 'Text', ' Plot', 'FontName', 'Franklin Gothic Book','FontWeight','bold','Position', [250, 40, 120, 30]);

        end

        function handleComputeButton(app, evalTable, isSFSelected)
            % Handle the compute button functionality
            rawData = evalTable.Data; % Retrieve table data
            numericData = str2double(rawData); % Convert cell data to numeric

            if isSFSelected
                % 'SF' is fixed; compute 'Dose' values
                doseValues = numericData(:, 2); % Get 'Dose' column
                validIndices = ~isnan(doseValues); % Filter valid rows
                doseValues = doseValues(validIndices);

                try
                    % Compute SF using the fit object
                    sfValues = feval(app.fitObject, doseValues);
                    sfColumn = rawData(:, 1); % Retrieve the SF column from evalTable.Data
                    sfColumn(validIndices) = num2cell(sfValues); % Update 'SF' column
                    evalTable.Data(:, 1) = sfColumn; % Reflect changes in evalTable
                catch ME
                    % Display an error alert for FitObject evaluation
                    uialert(app.DataFigure, ME.message, 'Error in FitObject Evaluation');
                end
            else
                % 'Dose' is fixed; compute 'SF' values
                sfValues = numericData(:, 1); % Get 'SF' column
                validIndices = ~isnan(sfValues); % Filter valid rows
                sfValues = sfValues(validIndices);

                try
                    % Compute 'Dose' by inverting the fit object
                    doseValues = arrayfun(@(sf) InvertFitObject(app.fitObject, sf), sfValues);
                    doseColumn = rawData(:, 2); % Retrieve the Dose column from evalTable.Data
                    doseColumn(validIndices) = num2cell(doseValues); % Update 'Dose' column
                    evalTable.Data(:, 2) = doseColumn; % Reflect changes in evalTable
                catch ME
                    % Display an error alert for FitObject inversion
                    uialert(app.DataFigure, ME.message, 'Error in FitObject Inversion');
                end
            end

            % Check if the checkbox is selected, and if so, plot the scatter
            if app.plotCheckbox.Value

                xValues = doseValues; % Dose values (x-axis)
                yValues = sfValues;   % SF values (y-axis)

                % Remove NaN values
                validIndices = ~isnan(xValues) & ~isnan(yValues);
                xValues = xValues(validIndices);
                yValues = yValues(validIndices);

                % Plot the scatter points without connecting lines
                hold(app.DataAxes, 'on');
                scatterPlot = scatter(app.DataAxes, xValues, yValues, ...
                    'Marker', 'square', ...
                    'LineWidth',1.5, ...
                    'MarkerEdgeColor', [0.5, 0, 0.5], 'MarkerFaceColor', [0.5, 0, 0.5], ...
                    'MarkerFaceAlpha', 0.5, ...
                    'DisplayName', '', ...
                    'SizeData', 150, 'HandleVisibility', 'on');

                scatterPlot.Annotation.LegendInformation.IconDisplayStyle = 'off';

                % Get the limits of the axes for the dashed lines
                xLimits = app.DataAxes.XLim;
                yLimits = app.DataAxes.YLim;

                % Add vertical and horizontal dashed lines for each point
                for i = 1:length(xValues)
                    % Vertical line from the point to the x-axis
                    vLine = line(app.DataAxes,[xValues(i), xValues(i)], [yLimits(1), yValues(i)], ...
                        'LineStyle', '-.', 'Color', [0.5, 0, 0.5], 'LineWidth', 1., ...
                        'Tag', 'vLine');
                    vLine.Annotation.LegendInformation.IconDisplayStyle = 'off';

                    % Horizontal line from the point to the y-axis
                    hLine = line(app.DataAxes,[xLimits(1), xValues(i)], [yValues(i), yValues(i)], ...
                        'LineStyle', '-.', 'Color', [0.5, 0, 0.5], 'LineWidth', 1., ...
                        'Tag', 'hLine');
                    hLine.Annotation.LegendInformation.IconDisplayStyle = 'off';
                end

                hold(app.DataAxes, 'off');
            end

        end

        function updateMultiSessionTable(app, plotData, filename)
            % updateMultiSessionTable - Appends only new plots to MultiSessionTable.
            %
            % Parameters:
            %   app (struct): The app structure containing the UI components.
            %   plotData (struct): The structure containing plot information with .Tag.
            %   filename (char): The name of the imported file.

            % Get drop-down options
            [colorOptions, ~] = GetColorMap();
            lineStyles = GetLineStyles();
            markers = GetMarkers();

            % Retrieve existing table data
            oldData = app.MultiSessionTable.Data;

            % Initialize if empty or invalid
            if isempty(oldData) || ~iscell(oldData) || size(oldData, 2) < 8
                oldData = {};
                existingTags = {};
            else
                existingTags = strcat(oldData(:, 3), oldData(:, 4)); % Source + DisplayName
            end

            % Preallocate new row container
            newRows = cell(numel(plotData), 8); % 8 columns with new additions
            newCount = 0;

            % Populate new rows
            for k = 1:numel(plotData)
                % Skip if missing or duplicate tag
                if ~isfield(plotData, 'Tag') || isempty(plotData(k).Tag)
                    continue;
                end
                newTag = plotData(k).Tag;
                if any(strcmp(existingTags, newTag))
                    continue;
                end

                % % Determine visibility
                % if isfield(plotData(k), 'DisplayName') && ismember(plotData(k).DisplayName, GetModelsMap())
                %     isVisible = false;
                % else
                %     isVisible = true;
                % end

                % Legacy semantic (do NOT use for real visibility)
                if isfield(plotData(k), 'DisplayName') && ismember(plotData(k).DisplayName, GetModelsMap())
                    plotType  = 'Fit';
                else
                    plotType  = 'Data';
                end

                % Safe field extraction
                source = GetFieldSafely(plotData(k), 'Source', '');
                displayName = GetFieldSafely(plotData(k), 'DisplayName', '');
                colorArray = GetFieldSafely(plotData(k), 'Color', []);
                colorName = 'Unknown';
                if ~isempty(colorArray)
                    colorName = RGBToColorName(colorArray);
                end
                customEntry = displayName;

                lineStyle = GetFieldSafely(plotData(k), 'LineStyle', '-');
                marker = GetFieldSafely(plotData(k), 'Marker', 'o');

                % Store new row
                newCount = newCount + 1;
                newRows(newCount, :) = {plotType, filename, source, displayName, ...
                    colorName, customEntry, lineStyle, marker};

                % Track added tag
                existingTags = [existingTags; newTag]; %#ok<AGROW>
            end

            % Remove unused preallocations
            newRows = newRows(1:newCount, :);

            % Append to existing table
            if ~isempty(newRows)
                if isempty(oldData)
                    app.MultiSessionTable.Data = newRows;
                else
                    app.MultiSessionTable.Data = [oldData; newRows];
                end
            end

            % Set dropdowns for editable columns
            % app.MultiSessionTable.ColumnFormat = {'logical', 'char', 'char', 'char', ...
            %     colorOptions, 'char', lineStyles, markers};
            app.MultiSessionTable.ColumnFormat = {'char', 'char', 'char', 'char', ...
                colorOptions, 'char', lineStyles, markers};
        end

        function setupSpinners(app)
            ax = app.MultiDataAxes;

            % ---- X axis max ----
            if isempty(app.DmaxSpinner.Value) || app.DmaxSpinner.Value <= 0
                app.DmaxSpinner.Value = ax.XLim(2);
            end

            % ---- Y axis min ----
            if isempty(app.SFminSpinner.Value) || app.SFminSpinner.Value <= 0 || app.SFminSpinner.Value >= 1
                app.SFminSpinner.Value = ax.YLim(1);
            end

            % ---- Legend font ----
            if isempty(app.LegendFontSizeSpinner.Value)
                app.LegendFontSizeSpinner.Value = 14;
            end
            if isempty(app.LegendHandle) || ~isvalid(app.LegendHandle)
                app.LegendHandle = legend(ax, 'show');
                app.LegendHandle.Box = 'off';
            end
            app.LegendHandle.FontSize = app.LegendFontSizeSpinner.Value;

            % ---- Axes font ----
            if isempty(app.LabelsFontSizeSpinner.Value)
                app.LabelsFontSizeSpinner.Value = ax.FontSize;
            end
            ax.FontSize = app.LabelsFontSizeSpinner.Value;

            % ---- Store originals once ----
            if isempty(app.OriginalMultiPlotData)
                app.OriginalMultiPlotData = StoreOriginalPlotData(ax);
            end

            % ---- Apply initial scaling ----
            if isempty(app.GraphScalingSpinner.Value)
                app.GraphScalingSpinner.Value = 1;
            end
            app.applyScaling(app.GraphScalingSpinner.Value);
        end


        function applyScaling(app, scaleFactor)
            if isempty(app.OriginalMultiPlotData)
                return;
            end
            ax = app.MultiDataAxes;
            for i = 1:numel(app.OriginalMultiPlotData)
                pd = app.OriginalMultiPlotData(i);
                objs = findobj(ax, 'Type', pd.Type);
                if isempty(objs), continue; end

                for k = 1:numel(objs)
                    if isprop(objs(k), 'LineWidth')
                        objs(k).LineWidth = pd.LineWidth * scaleFactor;
                    end
                    if isprop(objs(k), 'MarkerSize')
                        objs(k).MarkerSize = pd.MarkerSize * scaleFactor;
                    end
                end
            end
        end

        function row = buildAlphaBetaRow(app)
            % Returns a 1x4 cell row: {Quantity, Value, Lower, Upper}
            %
            % For SigmaCBSwitch = '1σ':
            %   Lower/Upper columns both store the 1σ uncertainty on alpha/beta.
            %
            % For SigmaCBSwitch = '95%CB':
            %   If covariance is available, returns a covariance-aware 95% CI
            %   computed by first-order delta method:
            %
            %       alpha/beta +/- t(0.975, DFE) * sigma_alpha_beta
            %
            %   If covariance is unavailable, falls back to conservative marginal
            %   coefficient-bound propagation.

            T = app.CoeffResultsTable.Data;
            if isempty(T) || size(T,2) < 4
                uialert(app.DataFigure, ...
                    'No coefficient results available.', ...
                    'Fit error', ...
                    'Icon','error');
                return;
            end

            names = string(T(:,1));
            vals  = cell2mat(T(:,2));
            c3    = cell2mat(T(:,3));
            c4    = cell2mat(T(:,4));

            % strict match: 'alpha' and 'beta'
            iA = find(names == "alpha", 1);
            iB = find(names == "beta",  1);

            if isempty(iA) || isempty(iB)
                uialert(app.DataFigure, ...
                    'Alpha and/or beta not available for the selected model.', ...
                    'Model parameters missing', ...
                    'Icon','error');
                return;
            end

            alpha_hat = vals(iA);
            beta_hat  = vals(iB);

            if ~isfinite(alpha_hat) || ~isfinite(beta_hat) || beta_hat == 0
                uialert(app.DataFigure, ...
                    'Invalid alpha/beta values.', ...
                    'Invalid parameters', ...
                    'Icon','error');
                return;
            end

            ratio_hat = alpha_hat / beta_hat;

            % ------------------------------------------------------------
            % Try covariance-aware delta-method propagation
            % ------------------------------------------------------------
            sigma_ratio_cov = NaN;

            if isstruct(app.lastOutput) && ...
                    isfield(app.lastOutput, 'CovarianceMatrix') && ...
                    isfield(app.lastOutput, 'CoefficientNames') && ...
                    ~isempty(app.lastOutput.CovarianceMatrix) && ...
                    all(isfinite(app.lastOutput.CovarianceMatrix(:)))

                covNames = string(app.lastOutput.CoefficientNames);
                jA = find(covNames == "alpha", 1);
                jB = find(covNames == "beta",  1);

                if ~isempty(jA) && ~isempty(jB)
                    C = app.lastOutput.CovarianceMatrix;

                    if size(C,1) >= max([jA jB]) && size(C,2) >= max([jA jB])
                        varAlpha = C(jA, jA);
                        varBeta  = C(jB, jB);
                        covAB    = C(jA, jB);

                        % Delta method for R = alpha / beta
                        %
                        % grad(R) = [dR/dalpha, dR/dbeta]
                        %         = [1/beta, -alpha/beta^2]
                        %
                        % Var(R) = grad * Cov(alpha,beta) * grad'
                        varRatio = ...
                            (1 / beta_hat^2) * varAlpha + ...
                            (alpha_hat^2 / beta_hat^4) * varBeta - ...
                            (2 * alpha_hat / beta_hat^3) * covAB;

                        % Numerical guard: tolerate tiny negative values from rounding
                        tol = 1e-12 * max(1, abs(ratio_hat)^2);
                        if isfinite(varRatio) && varRatio >= -tol
                            varRatio = max(varRatio, 0);
                            sigma_ratio_cov = sqrt(varRatio);
                        end
                    end
                end
            end

            % ------------------------------------------------------------
            % Display according to selected uncertainty mode
            % ------------------------------------------------------------
            if strcmp(app.SigmaCBSwitch.Value, '1σ')

                % Preferred: covariance-aware sigma
                if isfinite(sigma_ratio_cov)
                    sigma_ratio = sigma_ratio_cov;

                else
                    % Fallback: independent propagation from table sigmas
                    sigma_alpha = c3(iA);
                    sigma_beta  = c3(iB);

                    if sigma_alpha < 0 || sigma_beta < 0
                        uialert(app.DataFigure, ...
                            'Invalid sigma values for alpha/beta.', ...
                            'Invalid uncertainty parameters', ...
                            'Icon','error');
                        return;
                    end

                    sigma_ratio = abs(ratio_hat) * sqrt( ...
                        (sigma_alpha / alpha_hat)^2 + ...
                        (sigma_beta  / beta_hat)^2 );
                end

                lower = sigma_ratio;
                upper = sigma_ratio;

            else
                % --------------------------------------------------------
                % 95% confidence interval
                % --------------------------------------------------------

                if isfinite(sigma_ratio_cov) && ...
                        isstruct(app.lastGOF) && ...
                        isfield(app.lastGOF, 'dfe') && ...
                        isfinite(app.lastGOF.dfe) && ...
                        app.lastGOF.dfe > 0

                    % Covariance-aware 95% CI by delta method
                    Tcrit = tinv(0.975, app.lastGOF.dfe);

                    lower = ratio_hat - Tcrit * sigma_ratio_cov;
                    upper = ratio_hat + Tcrit * sigma_ratio_cov;

                else
                    % ----------------------------------------------------
                    % Fallback: conservative marginal-bound propagation
                    % ----------------------------------------------------
                    alpha_low = c3(iA);
                    alpha_up  = c4(iA);
                    beta_low  = c3(iB);
                    beta_up   = c4(iB);

                    % Need beta CI not crossing zero for meaningful ratio bounds
                    if beta_low <= 0 || beta_up <= 0
                        uialert(app.DataFigure, ...
                            'The beta confidence interval crosses or touches zero; the alpha/beta CI is not reliable.', ...
                            'Unreliable confidence interval', ...
                            'Icon','error');
                        return;
                    end

                    lower = alpha_low / beta_up;
                    upper = alpha_up  / beta_low;
                end
            end

            row = {'alpha/beta [Gy]', ratio_hat, lower, upper};
        end

        function populateAdjSSEEditField(app, GOF)
            % Populate AdjSSEEditField with SSE/DFE (a.k.a. SSE per degree of freedom)

            if isempty(GOF) || ~isfield(GOF,'sse') || ~isfield(GOF,'dfe')
                app.AdjSSEEditField.Value = Inf;
                return;
            end

            sse = GOF.sse;
            dfe = GOF.dfe;

            if ~isfinite(sse) || ~isfinite(dfe) || dfe <= 0
                app.AdjSSEEditField.Value = Inf;
                return;
            end

            adjSSE = sse / dfe;

            % Nice formatting: compact but readable
            app.AdjSSEEditField.Value = adjSSE;
        end

        function refreshReferenceDropDown(app)
            tableData = app.MultiSessionTable.Data;
            if isempty(tableData)
                app.ReferenceDropDown.Items = {};
                app.ReferenceDropDown.Value = '';
                return;
            end

            validDisplayNames = GetModelsMap();
            items = {};

            for i = 1:size(tableData,1)
                src = tableData{i,3};
                dn  = tableData{i,4};
                if ismember(dn, validDisplayNames)
                    items{end+1} = sprintf('%s — %s', src, dn); %#ok<AGROW>
                end
            end

            if isempty(items)
                app.ReferenceDropDown.Items = {};
                app.ReferenceDropDown.Value = '';
            else
                app.ReferenceDropDown.Items = items;

                % Keep current selection if still valid, otherwise default to first
                if isempty(app.ReferenceDropDown.Value) || ~ismember(app.ReferenceDropDown.Value, items)
                    app.ReferenceDropDown.Value = items{1};
                end
            end
        end

        function onMultiSessionTableSelected(app, evt)
            if isempty(evt.Indices)
                app.MultiSessionSelectedRow = [];
            else
                app.MultiSessionSelectedRow = evt.Indices(1);
            end
        end

        function onRemoveSelectedFile(app)
            % Remove all table entries and plot objects associated with the
            % currently selected file (column 2: File Name).
            %
            % This operation is performed at file level to ensure consistency
            % between experimental data and corresponding fitted models, which
            % are stored as separate objects but belong to the same session file.

            % ------------------------------------------------------------------
            % Validate selection
            % ------------------------------------------------------------------
            T = app.MultiSessionTable.Data;
            if isempty(T) || isempty(app.MultiSessionSelectedRow) || ...
                    app.MultiSessionSelectedRow > size(T,1)
                uialert(app.DataFigure, ...
                    'Select a row first.', ...
                    'Remove rows', 'Icon','warning');
                return;
            end

            % Column 2 = File Name
            fileToRemove = T{app.MultiSessionSelectedRow, 2};
            if isempty(fileToRemove)
                uialert(app.DataFigure, ...
                    'Selected row has an empty File Name.', ...
                    'Remove rows', 'Icon','warning');
                return;
            end

            % ------------------------------------------------------------------
            % 1) Remove all plot objects associated with the selected file
            % ------------------------------------------------------------------
            % Identify all rows belonging to the same file
            rowsToRemove = find(strcmp(T(:,2), fileToRemove));

            % Tags are constructed as Source + DisplayName
            tagsToRemove = strcat(string(T(rowsToRemove,3)), ...
                string(T(rowsToRemove,4)));

            ax = app.MultiDataAxes;

            for k = 1:numel(tagsToRemove)
                tagk = char(tagsToRemove(k));

                % Remove any graphical object with the corresponding tag
                % (lines, scatters, error bars, etc.)
                objs = findall(ax, 'Tag', tagk);
                if ~isempty(objs)
                    delete(objs);
                end
            end

            % ------------------------------------------------------------------
            % 2) Remove rows from the multi-session table
            % ------------------------------------------------------------------
            T(rowsToRemove,:) = [];
            app.MultiSessionTable.Data = T;

            % ------------------------------------------------------------------
            % 3) Update dependent UI elements (Reference and RBE table)
            % ------------------------------------------------------------------
            % Refresh reference selector
            app.refreshReferenceDropDown();

            % Clear RBE table, as its content is no longer valid after removal
            if app.RBESFButton.Value
                firstCol = 'SF';
            else
                firstCol = 'Dose [Gy]';
            end

            app.RBETable.Data = {};
            app.RBETable.ColumnName = {firstCol};
            app.RBETable.ColumnEditable = false;

            % Optionally recompute RBE if a valid reference is still available
            if ~isempty(app.ReferenceDropDown.Items) && ...
                    ~isempty(app.ReferenceDropDown.Value)
                try
                    app.CalculateRBEButtonPushed([]);
                catch
                    % Do not block the removal if recomputation fails
                end
            end

            % ------------------------------------------------------------------
            % 4) Re-synchronize stored plot state (used for scaling / reset)
            % ------------------------------------------------------------------
            app.OriginalMultiPlotData = StoreOriginalPlotData(app.MultiDataAxes);
        end


        function tf = hasAlphaBeta(app)
            T = app.CoeffResultsTable.Data;
            if isempty(T) || size(T,2) < 2
                tf = false;
                return;
            end

            names = string(T(:,1));
            tf = any(names == "alpha") && any(names == "beta");
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Ensure all components are initialized before populating the table
            try

                addpath(fullfile(fileparts(mfilename('fullpath')), 'utils'));
                addpath(fullfile(fileparts(mfilename('fullpath')), 'modules'));
                addpath(fullfile(fileparts(mfilename('fullpath')), 'icons'));

                app.DataFigure.Name = [app.DataFigure.Name ' - Version: ' app.Version];

                % Setup spinners
                app.setupSpinners();

                % Retrieve the modelStruct for the preselected model
                modelStruct = app.getSelectedModelStruct();

                % Populate the coefficients table
                app.populateCoefficientsTable(modelStruct);

                % ---- Context menu for MultiSessionTable ----
                app.MultiSessionContextMenu = uicontextmenu(app.DataFigure);

                app.RemoveByFileMenuItem = uimenu(app.MultiSessionContextMenu, ...
                    'Text', 'Remove all rows of selected file', ...
                    'MenuSelectedFcn', @(src,evt) app.onRemoveSelectedFile());

                app.MultiSessionTable.ContextMenu = app.MultiSessionContextMenu;

                app.MultiSessionTable.CellSelectionCallback = ...
                    @(src,evt) app.onMultiSessionTableSelected(evt);

            catch ME
                % Handle errors during initialization
                uialert(app.DataFigure, ME.message, 'Initialization Error', 'Icon', 'error');
            end
        end

        % Button pushed function: SelectDataButton
        function SelectDataButtonPushed(app, event)

            % Open file dialog to select a data file
            [file, path] = uigetfile({'*.txt', 'Text Files (*.txt)'; '*.*', 'All Files (*.*)'}, 'Select Data File');

            if isequal(file, 0)
                uialert(app.DataFigure, 'User cancelled the file selection.', 'Operation Cancelled', 'Icon', 'warning');
                return; % Exit the function if the user cancels
            end

            % Construct full file path
            fullPath = fullfile(path, file);

            % Validate and preprocess data
            datafileStruct = ValidateAndPreprocessData(fullPath);

            % Clear the FitResultTable
            app.FitResultTable.Data = cell(0, 7); % Set to an empty cell array with the correct number of columns

            % Clear the CoeffResultsTable
            app.CoeffResultsTable.Data = cell(0, 4); % Set to an empty cell array with the correct number of columns

            if ~isfield(datafileStruct.headerInfo, 'Title') || isempty(datafileStruct.headerInfo.Title)
                datafileStruct.headerInfo.('Title') = file;
            end

            title(app.DataAxes, datafileStruct.headerInfo.('Title'));

            % Determine alert type based on dataStruct.message
            if contains(datafileStruct.message, 'Error', 'IgnoreCase', true)
                % Show an error alert
                uialert(app.DataFigure, datafileStruct.message, 'Error in Data Processing', 'Icon', 'error');
            elseif contains(datafileStruct.message, 'Warning', 'IgnoreCase', true) || datafileStruct.defaultAdded
                % Show a warning or info alert
                uialert(app.DataFigure, datafileStruct.message, 'Warning: Data Adjusted', 'Icon', 'warning');
            else
                % Show a success alert
                uialert(app.DataFigure, datafileStruct.message, 'Data Processed', 'Icon', 'success');
            end

            % Populate the FileNameEditField with the file name
            app.FileNameEditField.Value = file;

            % Populate the DataTable with the validated data
            app.populateDataTable(datafileStruct);

            % Retrieve and validate the data and store dataStruct property
            app.dataStruct = GetDataFromDataTable(app.DataTable.Data);

            % Update the updateSwitchDataSD with the validated data
            app.updateSwitchDataSD()

            % Plot the data
            app.plotData(datafileStruct);

            % Enable Fit Options
            app.FitOptionsPanel.Enable = "on";

        end

        % Button pushed function: FitButton
        function FitButtonPushed(app, event)

            if isempty(fieldnames(app.dataStruct))
                uialert(app.DataFigure, 'No data has been uploaded or the data is invalid. Please upload a valid dataset to proceed.', ...
                    'Data Validation Error', 'Icon', 'error');
                return;
            end

            % Call the SelectModel function with the selected model name
            try
                modelStruct = getSelectedModelStruct(app);

                % Check if DataTable is empty
                if isempty(app.dataStruct.x) || isempty(app.dataStruct.y)
                    uialert(app.DataFigure, 'Data table is empty or has invalid values.', 'Data Validation Error', 'Icon', 'error');
                    return;
                end

                % Validate data: Ensure the number of x and y values match
                if numel(app.dataStruct.x) ~= numel(app.dataStruct.y)
                    uialert(app.DataFigure, 'The number of x and y values must match.', 'Data Validation Error', 'Icon', 'error');
                    return;
                end

                % Validate weights (ensure no NaNs, negative, or zero values)
                if any(isnan(app.dataStruct.w)) || any(app.dataStruct.w <= 0)
                    uialert(app.DataFigure, 'Invalid weights detected. Weights must be positive values.', 'Data Validation Error', 'Icon', 'error');
                    return;
                end

                fitOptions = app.getFitOptionsFromFitOptionsPanel();

                % Detect and switch the algorithm
                if strcmp(fitOptions.Algorithm, 'Levenberg-Marquardt') && ...
                        (any(fitOptions.Lower ~= -Inf) || any(fitOptions.Upper ~= Inf))
                    warning('Switching to Trust-Region because bounds are defined.');
                    fitOptions.Algorithm = 'Trust-Region';
                    % Update the dropdown to reflect the 'Trust-Region' algorithm
                    app.AlgorithmDropDown.Value = 'Trust-Region';
                    % Notify the user
                    uialert(app.DataFigure, ...
                        'The "Levenberg-Marquardt" algorithm does not support bounds. It has been switched to "Trust-Region".', ...
                        'Algorithm Switched', ...
                        'Icon', 'warning');
                end

                % Call FitModel with dataStruct, modelStruct, and tolerance
                [FitObject, GOF, Output] = FitModel(app.dataStruct, modelStruct, fitOptions);

                app.fitObject = FitObject;
                app.lastGOF = GOF;
                app.lastOutput = Output;

                % Display the results (you can update this to show the fit results in the UI as needed)
                fprintf('Fit completed successfully.\n');

                % Populate the CoeffResultsTable with fit output
                app.populateCoeffResultsTable(FitObject, GOF, Output)

                % Enable alpha/beta button only if alpha and beta exist
                if app.hasAlphaBeta()
                    app.AlphaBetaButton.Enable = 'on';
                else
                    app.AlphaBetaButton.Enable = 'off';
                end

                % Populate the FitResult with fit output
                app.populateFitResultTable(GOF, Output);
                app.populateAdjSSEEditField(GOF);

                % --- Clean the previous fitted curve ---
                app.cleanPreviousFittedCurve()

                % --- Plot the original data points ---
                hold(app.DataAxes, 'on');  % Hold the current plot to overlay the fit curve

                % --- Plot the fitted curve ---
                % Generate fitted y-values based on the fitted model
                xFit = linspace(min(app.dataStruct.x), max(app.dataStruct.x)*1.05, 100);  % Generate a smooth range of x values
                yFit = feval(FitObject, xFit); % Evaluate the fitted model at these x values

                % Plot the fitted curve on the existing axis
                % Find all marker objects on the axes
                markersOnLine = findobj(app.DataAxes, 'Type', 'line');
                markersOnErrorBar = findobj(app.DataAxes, 'Type', 'ErrorBar');

                % Determine the color of the marker based on the presence of error bars
                if all(app.dataStruct.w == 1)  % If all weights are 1, no error bars, use aline
                    if ~isempty(markersOnLine)
                        markerColor = markersOnLine(1).Color;  % Get the color from the first line object
                    else
                        markerColor = [0.5, 0., 0.5];  % Default to purple if no line is found
                    end
                else  % If weights are not 1, error bars are present, use aerrorbar
                    if ~isempty(markersOnErrorBar)
                        markerColor = markersOnErrorBar(1).Color;  % Get the color from the first error bar object
                    else
                        markerColor = [1, 0, 0];  % Default to red if no error bar is found
                    end
                end

                plot(app.DataAxes, xFit, yFit, 'LineStyle', '-', 'Color', [markerColor 0.3], 'LineWidth', 5, 'DisplayName', modelStruct.DisplayName); % Store the handle for future deletion
                hold(app.DataAxes, 'off'); % Release the hold on the axis

                app.CallEvaluationButton.Enable = "on";

            catch ME
                % Display an error dialog if model selection or function execution fails
                uialert(app.DataFigure, ME.message, 'Model Selection Error', 'Icon', 'error');
            end

        end

        % Value changed function: ModelDropDown
        function ModelDropDownValueChanged(app, event)
            try
                modelStruct = app.getSelectedModelStruct();

                % Populate the CoefficientsTable with modelStruct fields
                app.populateCoefficientsTable(modelStruct);

            catch ME
                % Handle errors in retrieving the modelStruct
                uialert(app.DataFigure, ME.message, 'Model Selection Error', 'Icon', 'error');
            end

        end

        % Value changed function: SigmaCBSwitch
        function SigmaCBSwitchValueChanged(app, event)
            % ------------------------------------------------------------
            % No coefficient table available: nothing to regenerate
            % ------------------------------------------------------------
            if isempty(app.CoeffResultsTable.Data)
                return;
            end

            % ------------------------------------------------------------
            % Legacy session guard:
            % old .mat sessions may contain fitObject + coefficient table,
            % but not fitGOF / fitOutput metadata introduced in v0.7.
            % In that case, uncertainty display cannot be regenerated safely.
            % ------------------------------------------------------------
            missingGOF = isempty(fieldnames(app.lastGOF));
            missingOutput = isempty(fieldnames(app.lastOutput));

            if missingGOF || missingOutput

                % Revert the switch to the previous value, if available.
                % This avoids a mismatch between the switch label and the table content.
                if nargin >= 2 && isprop(event, 'PreviousValue') && ~isempty(event.PreviousValue)
                    app.SigmaCBSwitch.Value = event.PreviousValue;
                end

                uialert(app.DataFigure, ...
                    ['This session was created with an older SDAnext version and does not contain ', ...
                    'fit covariance/GOF metadata. Coefficient uncertainty display cannot be regenerated.'], ...
                    'Legacy session', ...
                    'Icon','info');

                return;
            end

            % ------------------------------------------------------------
            % Normal v0.7+ workflow:
            % regenerate the coefficient table from FitObject + GOF + Output.
            % ------------------------------------------------------------
            try
                app.populateCoeffResultsTable(app.fitObject, app.lastGOF, app.lastOutput);

            catch ME
                % Revert switch if regeneration fails
                if nargin >= 2 && isprop(event, 'PreviousValue') && ~isempty(event.PreviousValue)
                    app.SigmaCBSwitch.Value = event.PreviousValue;
                end

                uialert(app.DataFigure, ...
                    sprintf('Unable to update coefficient uncertainty display:\n%s', ME.message), ...
                    'Coefficient display error', ...
                    'Icon', 'warning');
            end
        end

        % Value changed function: DataSDSwitch
        function DataSDSwitchValueChanged(app, event)
            % Notify the user that fit outputs will be cleared
            uialert(app.DataFigure, ...
                'Fit outputs will be cleared because the switch has been toggled.', ...
                'Warning', ...
                'Icon', 'warning');

            % Clean the previous fitted curve
            app.cleanPreviousFittedCurve()
            app.fitObject = cfit(fittype('a*x + 1', 'independent', 'x', 'coefficients', {'a'}), 0);
            app.lastGOF = struct();
            app.lastOutput = struct();

            % Clear the FitResultTable
            app.FitResultTable.Data = cell(0, 7); % Set to an empty cell array with the correct number of columns
            app.AdjSSEEditField.Value = Inf;

            % Clear the CoeffResultsTable
            app.CoeffResultsTable.Data = cell(0, 4); % Set to an empty cell array with the correct number of columns

            % Check the current state of the switch
            if strcmp(app.DataSDSwitch.Enable, 'on') && strcmp(app.DataSDSwitch.Value, 'Off')
                % Override dataStruct.w with ones
                numElements = numel(app.dataStruct.w);
                app.dataStruct.w = ones(1, numElements);

                % Display a warning to the user
                uialert(app.DataFigure, ...
                    'Data standard deviations will be ignored because the switch is turned Off.', ...
                    'Warning', ...
                    'Icon', 'warning');

                addWatermark(app);

            elseif strcmp(app.DataSDSwitch.Enable, 'on') && strcmp(app.DataSDSwitch.Value, 'On')
                % Restore the original weights using the existing function
                app.dataStruct = GetDataFromDataTable(app.DataTable.Data);

                % Notify the user that the original weights have been restored
                uialert(app.DataFigure, ...
                    'Original data standard deviations have been restored because the switch is turned On.', ...
                    'Info', ...
                    'Icon', 'info');

                app.removeWatermark();

            end
        end

        % Button pushed function: SaveSessionButton
        function SaveSessionButtonPushed(app, event)
            % SaveSessionButtonPushed - Saves session data including plots to a .mat file
            try
                % Check if there is anything to save
                if isempty(app.DataAxes.Children)
                    uialert(app.DataFigure, 'There are no plots to save. Please create a plot before saving.', ...
                        'Save Error', 'Icon', 'error');
                    return;
                end

                fitResults = app.FitResultTable.Data;
                coeffResults = app.CoeffResultsTable.Data;

                if isempty(fitResults) && isempty(coeffResults)
                    uialert(app.DataFigure, 'Fit results and coefficient results are empty. There is nothing to save.', ...
                        'Save Error', 'Icon', 'error');
                    return;
                end

                % Ask the user where to save the session
                [fileName, pathName] = uiputfile('*.mat', 'Save Session As');
                if isequal(fileName, 0)
                    % User canceled save operation
                    disp('Save operation canceled.');
                    return;
                end

                % Get table data
                fitResultsObject = app.fitObject;
                fitResults = app.FitResultTable.Data;
                coeffResults = app.CoeffResultsTable.Data;

                fitGOF = app.lastGOF;
                fitOutput = app.lastOutput;

                % Get UI component states
                sigmaState = app.SigmaCBSwitch.Value;  % 'On' or 'Off'
                algorithm = app.AlgorithmDropDown.Value;
                robustOption = app.RobustDropDown.Value;
                dataSDState = app.DataSDSwitch.Value;

                % Get plot data from app.DataAxes
                [plotData, titleText] = GetPlotData(app.DataAxes);

                % Save all data to a .mat file
                save(fullfile(pathName, fileName), ...
                    'fitResultsObject', ...
                    'fitGOF', 'fitOutput', ...
                    'fitResults', 'coeffResults', 'sigmaState', ...
                    'algorithm', 'robustOption', 'dataSDState', ...
                    'titleText', 'plotData');

                % Confirm the save operation
                uialert(app.DataFigure, 'Session saved successfully!', 'Success', 'Icon', 'success');
            catch ME
                % Handle errors and alert the user
                uialert(app.DataFigure, sprintf('Error saving session:\n%s', ME.message), 'Error', 'Icon', 'error');
                disp(ME.message);
            end
        end

        % Button pushed function: ImportSessionButton
        function ImportSessionButtonPushed(app, event)
            % Disable Fit Options to prevent re-fitting on a saved session.
            app.FitButton.Enable = 'off';
            app.ScanAllModelsButton.Enable = 'off';

            app.CallEvaluationButton.Enable = "on";

            app.AlphaBetaButton.Enable = 'on';

            % Load session data when the user presses the "Import Session" button
            try
                % Ask the user to select the .mat file containing the saved session
                [fileName, pathName] = uigetfile('*.mat', 'Import Session');
                if isequal(fileName, 0)
                    % User canceled the load operation
                    disp('Load operation canceled.');
                    return;
                end

                app.FileNameEditField.Value = fileName;

                % Load the session data from the selected .mat file
                sessionData = load(fullfile(pathName, fileName));

                % Extract data from the loaded session
                fitResultsObject = sessionData.fitResultsObject;
                if isfield(sessionData, 'fitGOF')
                    app.lastGOF = sessionData.fitGOF;
                else
                    app.lastGOF = struct();
                end

                if isfield(sessionData, 'fitOutput')
                    app.lastOutput = sessionData.fitOutput;
                else
                    app.lastOutput = struct();
                end
                fitResults = sessionData.fitResults;
                coeffResults = sessionData.coeffResults;
                sigmaState = sessionData.sigmaState;
                algorithm = sessionData.algorithm;
                robustOption = sessionData.robustOption;
                dataSDState = sessionData.dataSDState;
                plotData = sessionData.plotData;
                titleText = sessionData.titleText;

                % Filter the plotData to only include items with LineStyle
                % 'none', i.e. data
                filteredData = plotData(strcmp({plotData.LineStyle}, 'none'));

                if numel(filteredData) == 1
                    % Extract XData (dose) and YData (survival fraction)
                    XData = filteredData.XData;
                    YData = filteredData.YData;

                    % Check if the filteredData contains YNegativeDelta and YPositiveDelta
                    if isfield(filteredData, 'YNegativeDelta') && isfield(filteredData, 'YPositiveDelta')
                        % Compute the average YDelta
                        YDelta = (filteredData.YNegativeDelta + filteredData.YPositiveDelta) / 2;
                    else
                        % If no delta fields, set YDelta to empty
                        YDelta = [];
                    end

                    % Format the data to scientific notation
                    formattedDose = arrayfun(@(x) sprintf('%3.3f', x), XData, 'UniformOutput', false);
                    formattedSurvivalFraction = arrayfun(@(x) sprintf('%3.3f', x), YData, 'UniformOutput', false);

                    if isempty(YDelta)
                        % No YDelta column
                        formattedYDelta = repmat({''}, length(XData), 1);
                    else
                        formattedYDelta = arrayfun(@(x) sprintf('%3.3f', x), YDelta, 'UniformOutput', false);
                    end

                    % Combine the formatted data into a cell array
                    tableData = [formattedDose(:), formattedSurvivalFraction(:), formattedYDelta(:)];

                    % Set the DataTable data
                    app.DataTable.Data = tableData;

                    % Set the FitObject
                    app.fitObject = fitResultsObject;

                    % Add row names with sequential numbering
                    numRows = size(tableData, 1);
                    rowNames = arrayfun(@(x) num2str(x), 1:numRows, 'UniformOutput', false);
                    app.DataTable.RowName = rowNames;

                else
                    % Handle the case where there is no or more than one data series
                    uialert(app.DataFigure, ...
                        sprintf('Error loading session: expected one data series but found %d.', numel(filteredData)), ...
                        'Error', 'Icon', 'error');
                end

                % Recreate the plot using the saved plot data
                RestorePlot(app.DataAxes, plotData, titleText, false);

                % Optionally, update UI components with the saved settings
                app.SigmaCBSwitch.Value = sigmaState;
                app.AlgorithmDropDown.Value = algorithm;
                app.RobustDropDown.Value = robustOption;
                app.DataSDSwitch.Value = dataSDState;

                % Optionally, update the tables with the loaded data
                app.FitResultTable.Data = fitResults;
                app.CoeffResultsTable.Data = coeffResults;

                % Confirm that the session was loaded and plot was recreated
                uialert(app.DataFigure, 'Session loaded and plot recreated successfully!', 'Success', 'Icon', 'success');
            catch ME
                % Handle errors and alert the user if loading fails
                uialert(app.DataFigure, sprintf('Error loading session:\n%s', ME.message), 'Error', 'Icon', 'error');
                disp(ME.message);
            end
        end

        % Button pushed function: ScanAllModelsButton
        function ScanAllModelsButtonPushed(app, event)
            % Clear previous fitted curve
            app.cleanPreviousFittedCurve();

            % Pre-allocate storage for FitResult and Coefficients based on number of models
            numModels = numel(app.ModelDropDown.Items);

            modelNames = cell(numModels, 1);    % Model names
            rmseValues = NaN(numModels, 1);   % RMSE values
            adjRsqValues = NaN(numModels, 1); % adjRsq values
            adjSSEValues = NaN(numModels, 1); % adjSSE values
            validIndices = false(numModels, 1); % Logical array for valid models

            % Loop through all the values in the ModelDropDown's item list
            for i = 1:numModels
                % Set the value of the dropdown to the current item
                app.ModelDropDown.Value = app.ModelDropDown.Items{i};

                % Create a dummy event to pass to the ModelDropDownValueChanged function
                eventModelDropDown = struct('Source', app.ModelDropDown, 'Value', app.ModelDropDown.Value);

                % Trigger the ModelDropDownValueChanged function
                ModelDropDownValueChanged(app, eventModelDropDown);

                % Trigger the FitButtonPushed function by simulating a button press
                eventFitButton = struct('Source', app.FitButton);
                FitButtonPushed(app, eventFitButton);

                % Extract adjRsq, adjSSE, RMSE and model name
                adjSSEValues(i) = app.AdjSSEEditField.Value;
                adjRsqValues(i) = app.FitResultTable.Data{6};
                rmseValues(i) = app.FitResultTable.Data{7}; % RMSE value
                modelNames{i} = app.FitResultTable.Data{2}; % Model name

                % Check validity of coefficients in 3rd and 4th positions
                coeffData = app.CoeffResultsTable.Data;
                % Ensure all elements in the 3rd and 4th columns are numeric, finite, and not NaN
                if ~isempty(coeffData) && ...
                        all(cellfun(@(x) isfinite(x) && ~isnan(x), coeffData(:, 3))) && ...
                        all(cellfun(@(x) isfinite(x) && ~isnan(x), coeffData(:, 4)))
                    validIndices(i) = true; % Mark as valid if all elements in both columns pass the check
                end

                app.CallEvaluationButton.Enable = "off";
                pause(1);
            end

            % Optionally clear the previous fitted curve again
            app.cleanPreviousFittedCurve();

            % Clear the FitResultTable
            app.FitResultTable.Data = cell(0, 7); % Set to an empty cell array with the correct number of columns
            app.AdjSSEEditField.Value = Inf;

            % Clear the CoeffResultsTable
            app.CoeffResultsTable.Data = cell(0, 4); % Set to an empty cell array with the correct number of columns

            % Filter valid models
            filteredRMSE    = rmseValues(validIndices);
            filteredAdjRsq  = adjRsqValues(validIndices);
            filteredAdjSSE  = adjSSEValues(validIndices);
            filteredModelNames = modelNames(validIndices);

            % Sort by RMSE (ascending)
            [sortedRMSE, sortedIndices] = sort(filteredRMSE);
            sortedAdjRsq = filteredAdjRsq(sortedIndices);
            sortedAdjSSE = filteredAdjSSE(sortedIndices);
            sortedModelNames = filteredModelNames(sortedIndices);

            % Prepare data for display
            sortedData = cell(numel(sortedRMSE), 4);
            for k = 1:numel(sortedRMSE)
                sortedData{k, 1} = sortedModelNames{k};
                sortedData{k, 2} = sortedRMSE(k);
                sortedData{k, 3} = sortedAdjSSE(k);
                sortedData{k, 4} = sortedAdjRsq(k);
            end

            % Create a popup GUI window for the sorted table
            f = uifigure('Name', 'Model scanning results', 'Position', [100, 100, 610, 300]);
            t = uitable(f, ...
                'Data', sortedData, ...
                'ColumnName', {'Model Name', 'RMSE', 'Adj SSE', 'Adj R-sq'}, ...
                'Position', [25, 50, 560, 200], ...
                'ColumnEditable', [false false false false]);
            t.ColumnWidth = 'auto';

            % Add a title label to the popup
            uilabel(f, ...
                'Text', 'Successfully converged models (sorted by RMSE)', ...
                'Position', [25, 260, 560, 20], ...
                'FontWeight', 'bold');

            app.ModelDropDown.Value = sortedModelNames{1};
            % Create a dummy event to pass to the ModelDropDownValueChanged function
            eventModelDropDown = struct('Source', app.ModelDropDown, 'Value', app.ModelDropDown.Value);
            % Trigger the ModelDropDownValueChanged function
            ModelDropDownValueChanged(app, eventModelDropDown);

        end

        % Button pushed function: ResetSessionButton
        function ResetSessionButtonPushed(app, event)

            % Reset data structure
            app.dataStruct = struct();

            % Disable buttons and switches
            app.FitButton.Enable = 'off';
            app.ScanAllModelsButton.Enable = 'off';
            app.DataSDSwitch.Enable = 'off';
            app.CallEvaluationButton.Enable = "off";

            % Clear the DataAxes thoroughly
            cla(app.DataAxes);
            title(app.DataAxes, '');   % Clear the title

            % Reset DataTable content
            app.DataTable.Data = {};

            % Reset FileNameEditField
            app.FileNameEditField.Value = '';
            app.FileNameEditField.Placeholder = 'filename';

            app.fitObject = cfit(fittype('a*x + 1', 'independent', 'x', 'coefficients', {'a'}), 0);
            app.lastGOF = struct();
            app.lastOutput = struct();

            % Clear the FitResultTable
            app.FitResultTable.Data = cell(0, 7); % Set to an empty cell array with the correct number of columns
            app.AdjSSEEditField.Value = Inf;

            % Clear the CoeffResultsTable
            app.CoeffResultsTable.Data = cell(0, 4); % Set to an empty cell array with the correct number of columns
            app.AlphaBetaButton.Enable = 'off';

            % Reset dropdowns and their associated callbacks
            app.ModelDropDown.Value = 'Linear-Quadratic (LQ)';
            eventModelDropDown = struct('Source', app.ModelDropDown, 'Value', app.ModelDropDown.Value);
            ModelDropDownValueChanged(app, eventModelDropDown);

            app.AlgorithmDropDown.Value = 'Trust-Region';
            app.RobustDropDown.Value = 'Off';

            % Reset switches
            app.DataSDSwitch.Value = 'On';
            app.SigmaCBSwitch.Value = '1σ';
        end

        % Button pushed function: SavePlotButton
        function SavePlotButtonPushed(app, event)
            % Open file selection dialog with supported formats
            [file, path, ~] = uiputfile(...
                GetImageFormatsMap(), ...
                'Save Plot As');

            if isequal(file, 0) || isequal(path, 0)
                disp('User canceled the save operation');
                return; % User canceled
            end

            % Set the full file path
            fullFilePath = fullfile(path, file);

            % Get the axes from the app
            ax = app.DataAxes;

            try
                % Preserve font properties
                originalFontName = ax.FontName;
                originalFontSize = ax.FontSize;

                % Optionally, adjust resolution
                resolution = 300; % 300 DPI for high resolution

                % Use exportgraphics to save the axes
                exportgraphics(ax, fullFilePath, 'Resolution', resolution);

                % Restore the original font properties
                ax.FontName = originalFontName;
                ax.FontSize = originalFontSize;

                % Display a message box with the saved file location
                msg = ['Plot saved to ', fullFilePath];
                uialert(app.DataFigure, msg, 'Save Successful', 'Icon', 'success');
            catch ME
                % Handle potential errors
                uialert(app.DataFigure, ['Failed to save plot: ', ME.message], 'Save Error', 'Icon', 'error');
            end
        end

        % Button pushed function: CallEvaluationButton
        function CallEvaluationButtonPushed(app, event)
            % Triggered when CallEvaluationButton is pushed
            if app.SFButton.Value
                app.openEvaluationUI(true); % SF selected
            elseif app.DoseButton.Value
                app.openEvaluationUI(false); % Dose selected
            end
        end

        % Button pushed function: CleanEvaluationButton
        function CleanEvaluationButtonPushed(app, event)
            % --- Clean the previous fitted curve ---
            % Delete any previous fit (search for lines and scatter plots)

            % Find all line objects in the axes
            previousLines = findobj(app.DataAxes, 'Type', 'line');

            % Find all scatter objects in the axes
            previousScatters = findobj(app.DataAxes, 'Type', 'scatter');

            % Delete all line objects
            for i = 1:length(previousLines)
                if ismember(previousLines(i).Tag, {'vLine','hLine'})
                    delete(previousLines(i));
                end
            end

            % Delete all scatter objects
            for i = 1:length(previousScatters)
                delete(previousScatters(i));
            end
        end

        % Button pushed function: CleanFitButton
        function CleanFitButtonPushed(app, event)

            app.cleanPreviousFittedCurve;

            app.fitObject = cfit(fittype('a*x + 1', 'independent', 'x', 'coefficients', {'a'}), 0);
            app.lastGOF = struct();
            app.lastOutput = struct();

            % Disable buttons and switches
            app.CallEvaluationButton.Enable = "off";

            % Clear the FitResultTable
            app.FitResultTable.Data = cell(0, 7); % Set to an empty cell array with the correct number of columns
            app.AdjSSEEditField.Value = Inf;

            % Clear the CoeffResultsTable
            app.CoeffResultsTable.Data = cell(0, 4); % Set to an empty cell array with the correct number of columns
            app.AlphaBetaButton.Enable = 'off';

            % Reset dropdowns and their associated callbacks
            app.ModelDropDown.Value = 'Linear-Quadratic (LQ)';
            eventModelDropDown = struct('Source', app.ModelDropDown, 'Value', app.ModelDropDown.Value);
            ModelDropDownValueChanged(app, eventModelDropDown);

            app.AlgorithmDropDown.Value = 'Trust-Region';
            app.RobustDropDown.Value = 'Off';

            % Reset switches
            app.DataSDSwitch.Value = 'On';
            app.SigmaCBSwitch.Value = '1σ';
        end

        % Button pushed function: ImportMultiSessionButton
        function ImportMultiSessionButtonPushed(app, event)
            % Import multiple sessions without clearing existing plots.
            try
                % Ask the user to select one or more .mat files
                [fileNames, pathName] = uigetfile('*.mat', 'Import Session', 'MultiSelect', 'on');

                % If the user cancels the operation
                if isequal(fileNames, 0)
                    disp('Load operation canceled.');
                    return;
                end

                % Ensure fileNames is a cell array (even for a single file)
                if ischar(fileNames)
                    fileNames = {fileNames}; % Convert to cell array
                end

                % Retrieve existing filenames and tags from MultiSessionTable
                existingData = app.MultiSessionTable.Data;
                if isempty(existingData) || size(existingData, 2) < 8
                    existingTags = {}; % No existing files
                else
                    % Combine column 3 (Source) and column 4 (Display Name) into a single tag
                    existingTags = strcat(existingData(:, 3), existingData(:, 4));
                end

                % Iterate over all selected files
                for i = 1:numel(fileNames)
                    fileName = fileNames{i}; % Current file name

                    % Load the .mat file
                    sessionData = load(fullfile(pathName, fileName));

                    % Verify that the required fields exist
                    if ~isfield(sessionData, 'plotData') || ~isfield(sessionData, 'titleText')
                        uialert(app.DataFigure, sprintf('Skipping "%s": missing required fields.', fileName), ...
                            'Invalid File', 'Icon', 'warning');
                        continue; % Skip to the next file
                    end

                    plotData = sessionData.plotData;
                    titleText = sessionData.titleText;

                    % Ensure plotData has the "Tag" field
                    if ~isfield(plotData, 'Tag')
                        uialert(app.DataFigure, sprintf('Skipping "%s": missing "Tag" field.', fileName), ...
                            'Invalid Data', 'Icon', 'warning');
                        continue; % Skip to the next file
                    end

                    % Generate new tags
                    newTags = {plotData.Tag};

                    % Check for duplicates
                    if any(ismember(newTags, existingTags))
                        uialert(app.DataFigure, sprintf('Some plots from "%s" have already been imported.', fileName), ...
                            'Duplicate Import', 'Icon', 'warning');
                        continue; % Skip to the next file
                    end

                    % Recreate the plot without overwriting existing ones
                    RestorePlot(app.MultiDataAxes, plotData, titleText, true);

                    % Update the MultiSessionTable
                    app.updateMultiSessionTable(plotData, fileName);

                    % Update existing tags to prevent duplicates in subsequent files
                    existingTags = existingTags(:); % Force column format
                    newTags = newTags(:);           % Force column format
                    existingTags = [existingTags; newTags]; %#ok<AGROW>

                    % Log message for each successfully imported file
                    disp(['Imported: ', fileName]);

                    % Refresh drop down for reference selection
                    app.refreshReferenceDropDown()
                end

                app.setupSpinners();
                % Setup Axes Spinners
                % app.SFminSpinner.Value = app.MultiDataAxes.YLim(1); %DEBUG
                % app.DmaxSpinner.Value = app.MultiDataAxes.XLim(2); %DEBUG

                % Reset RBE table on import (avoid auto-compute with unintended reference)
                app.RBETable.Data = {};
                if app.RBESFButton.Value
                    app.RBETable.ColumnName = {'SF'};
                else
                    app.RBETable.ColumnName = {'Dose [Gy]'};
                end
                app.RBETable.ColumnEditable = false;

                % Final confirmation message
                uialert(app.DataFigure, 'All selected sessions have been loaded successfully!', 'Success', 'Icon', 'success');

            catch ME
                % Handle errors and alert the user if loading fails
                uialert(app.DataFigure, sprintf('Error loading session:\n%s', ME.message), 'Error', 'Icon', 'error');
                disp(ME.message);
            end
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            % RefreshButtonPushed - Updates plot styles, colors, and legends from MultiSessionTable.

            tableData = app.MultiSessionTable.Data;
            if isempty(tableData)
                return;
            end

            for i = 1:size(tableData, 1)
                source       = tableData{i, 3}; % Source
                displayName  = tableData{i, 4}; % Display Name
                selectedColor = tableData{i, 5}; % Color
                customEntry  = tableData{i, 6}; % Custom Legend
                lineStyle    = tableData{i, 7}; % New: Line Style
                marker       = tableData{i, 8}; % New: Marker

                tagToFind = strcat(source, displayName);
                obj = findall(app.MultiDataAxes, 'Tag', tagToFind);

                if ~isempty(obj)
                    try
                        rgbColor = ColorNameToRGB(selectedColor);

                        % Apply transparency if it's a model
                        if ismember(displayName, GetModelsMap())
                            obj.Color = [rgbColor, 0.3];
                        else
                            obj.Color = rgbColor;
                            if isprop(obj, 'MarkerFaceColor')
                                obj.MarkerFaceColor = rgbColor;
                            end
                        end

                        % Update DisplayName if custom legend is provided
                        if ~isempty(customEntry)
                            obj.DisplayName = customEntry;
                        end

                        % Apply line style if valid
                        if isprop(obj, 'LineStyle') && ~isempty(lineStyle)
                            obj.LineStyle = lineStyle;
                        end

                        % Apply marker if valid
                        if isprop(obj, 'Marker') && ~isempty(marker)
                            obj.Marker = marker;
                            markerIsNone = strcmpi(marker, 'none');
                            if isa(obj, 'matlab.graphics.chart.primitive.ErrorBar')
                                if markerIsNone
                                    obj.Visible = 'off';
                                    obj.Color = [1, 1, 1];
                                else
                                    obj.Visible = 'on';
                                end
                            end
                        end

                    catch
                        % warning('Error updating plot for tag "%s". Skipping. (Color="%s")', tagToFind, selectedColor);
                        uialert(app.DataFigure, ...
                            sprintf('Error updating plot for tag "%s".\nSkipping. (Color = "%s")', ...
                            tagToFind, selectedColor), ...
                            'Plot update warning', ...
                            'Icon','warning');
                    end
                end
            end
        end

        % Button pushed function: SaveMultiPlotButton
        function SaveMultiPlotButtonPushed(app, event)
            % Open file selection dialog with supported formats
            [file, path, ~] = uiputfile(...
                GetImageFormatsMap(), ...
                'Save Plot As');

            if isequal(file, 0) || isequal(path, 0)
                disp('User canceled the save operation');
                return; % User canceled
            end

            % Set the full file path
            fullFilePath = fullfile(path, file);

            % Get the axes from the app
            ax = app.MultiDataAxes;

            try
                % Preserve font properties
                originalFontName = ax.FontName;
                originalFontSize = ax.FontSize;

                % Check the selected format
                [~, ~, extension] = fileparts(fullFilePath);

                if strcmp(extension, '.fig')
                    % Save as a MATLAB figure (.fig)
                    f = figure('Visible', 'off'); % Create a hidden figure
                    newAx = copyobj(ax, f); % Copy axes to new figure
                    set(newAx, 'Units', 'normalized', 'Position', [0.1 0.1 0.8 0.8]); % Adjust position
                    savefig(f, fullFilePath); % Save figure
                    close(f); % Close the temporary figure

                else

                    % Optionally, adjust resolution
                    resolution = 300; % 300 DPI for high resolution

                    % Use exportgraphics to save the axes
                    exportgraphics(ax, fullFilePath, 'Resolution', resolution);
                end

                % Restore the original font properties
                ax.FontName = originalFontName;
                ax.FontSize = originalFontSize;

                % Display a message box with the saved file location
                msg = ['Plot saved to ', fullFilePath];
                uialert(app.DataFigure, msg, 'Save Successful', 'Icon', 'success');
            catch ME
                % Handle potential errors
                uialert(app.DataFigure, ['Failed to save plot: ', ME.message], 'Save Error', 'Icon', 'error');
            end
        end

        % Button pushed function: ImportMultiSessionFigureButton
        function ImportMultiSessionFigureButtonPushed(app, event)
            % ImportMultiSessionFigureButtonPushed - Loads a .fig file and populates the session table.

            [fileName, pathName] = uigetfile('*.fig', 'Select a saved figure');
            if isequal(fileName, 0)
                disp('Operation canceled.');
                return;
            end

            cla(app.MultiDataAxes);  % Clear plot
            app.MultiSessionTable.Data = {};  % Clear table

            fig = openfig(fullfile(pathName, fileName), 'invisible');
            axObjs = findall(fig, 'Type', 'axes');

            if isempty(axObjs)
                uialert(app.DataFigure, 'No valid axes found in the figure.', 'Import Error', 'Icon', 'error');
                close(fig);
                return;
            end

            % Get data and close the figure
            [plotData, titleText] = GetPlotData(axObjs(1));
            close(fig);

            [colorOptions, ~] = GetColorMap();
            lineStyles = GetLineStyles(); %#ok<NASGU>
            markers = GetMarkers(); %#ok<NASGU>
            excludeLabels = GetModelsMap();

            tableData = {};

            for i = 1:numel(plotData)
                % Source estimation placeholder
                source = 'Unknown';

                % Determine visibility
                % isVisible = ~any(strcmp(plotData(i).DisplayName, excludeLabels));

                isData = ~any(strcmp(plotData(i).DisplayName, excludeLabels));

                if isData
                    plotType = 'Data';
                else
                    plotType = 'Fit';
                end

                % Ensure DisplayName
                if isempty(plotData(i).DisplayName)
                    plotData(i).DisplayName = 'Unnamed Plot';
                end

                % Color
                detectedColor = RGBToColorName(plotData(i).Color);
                if ~ismember(detectedColor, colorOptions)
                    detectedColor = 'Black';
                end

                % Custom legend
                customEntry = plotData(i).DisplayName;

                % Line style and marker with fallback
                lineStyle = GetFieldSafely(plotData(i), 'LineStyle', '-');
                marker = GetFieldSafely(plotData(i), 'Marker', 'o');

                % Add full row (8 columns)
                tableData = [tableData; {
                    plotType, ...
                    fileName, ...
                    source, ...
                    plotData(i).DisplayName, ...
                    detectedColor, ...
                    customEntry, ...
                    lineStyle, ...
                    marker}]; %#ok<AGROW>
            end

            % Restore plot
            if ~isempty(plotData)
                RestorePlot(app.MultiDataAxes, plotData, titleText, true);
            end

            % Update table
            if isempty(app.MultiSessionTable.Data)
                app.MultiSessionTable.Data = tableData;
            else
                app.MultiSessionTable.Data = [app.MultiSessionTable.Data; tableData];
            end

            % Dropdown settings
            app.MultiSessionTable.ColumnEditable = [false, false, false, false, false, false, false, false];

            app.MultiSessionTable.Enable = 'off';
            app.RBECalculatorPanel.Enable = 'off';
            app.ReferenceDropDown.Enable = 'off';

            app.setupSpinners();
            % Setup Axes Spinners
            app.SFminSpinner.Value = app.MultiDataAxes.YLim(1); % DEBUG
            app.DmaxSpinner.Value = app.MultiDataAxes.XLim(2); % DEBUG

            uialert(app.DataFigure, 'The selected session has been loaded successfully!', 'Success', 'Icon', 'success');
            disp(['Figure "' fileName '" successfully imported and restored.']);
        end

        % Button pushed function: ResetMultiSessionButton
        function ResetMultiSessionButtonPushed(app, event)
            % Clear the axes before importing new plots
            cla(app.MultiDataAxes);
            app.setupSpinners();

            % Clear the session table
            app.MultiSessionTable.Data = {};

            % Make the columns partially editable
            app.MultiSessionTable.Enable = 'on';
            app.MultiSessionTable.ColumnEditable = [false, false, false, false, true, true, true, true];

            % Make the RBEPanel editable
            app.RBECalculatorPanel.Enable = 'on';

            % Clear the RBE table
            app.RBETable.Data = {};
            app.RBETable.ColumnName = {'SF'}; % Reset to default column
            app.RBETable.ColumnEditable = false; % Ensure it's not editable

            % Clear the reference drop down list
            app.ReferenceDropDown.Enable = 'on';
            app.ReferenceDropDown.Items = {};
        end

        % Button pushed function: CalculateRBEButton
        function CalculateRBEButtonPushed(app, event)

            % Bidirectional RBE evaluation:
            % - SF mode (default): RBE at fixed SF values (as before)
            % - Dose mode: user-defined reference doses -> compute SF_ref(D_ref),
            %              then find D_target at same SF_ref, then RBE = D_ref / D_target

            try
                % -----------------------------------------------------------------
                % Retrieve table data
                % -----------------------------------------------------------------
                tableData = app.MultiSessionTable.Data;
                if isempty(tableData)
                    uialert(app.DataFigure, 'The data table is empty.', 'Error', 'Icon', 'error');
                    return;
                end

                % Valid curve names (models only)
                validDisplayNames = GetModelsMap();

                % -----------------------------------------------------------------
                % Reference curve = first row in table
                % -----------------------------------------------------------------
                % refSource = tableData{1, 3};        % Column 'Source'
                % refDisplayName = tableData{1, 4};   % Column 'DisplayName'

                % Reference curve selected from dropdown (formatted as "Source — DisplayName")
                refKey = app.ReferenceDropDown.Value;

                % Parse refKey
                parts = split(string(refKey), " — ");
                if numel(parts) ~= 2
                    uialert(app.DataFigure, 'Invalid reference selection.', 'Error', 'Icon', 'error');
                    return;
                end
                refSource = char(parts(1));
                refDisplayName = char(parts(2));

                if ~ismember(refDisplayName, validDisplayNames)
                    uialert(app.DataFigure, ...
                        sprintf('Invalid reference curve. DisplayName must be one of: %s.', strjoin(validDisplayNames, ', ')), ...
                        'Error', 'Icon', 'error');
                    return;
                end

                refTag = strcat(refSource, refDisplayName);

                % Find the reference curve object in the axes
                refObj = findall(app.MultiDataAxes, 'Tag', refTag);
                if isempty(refObj)
                    uialert(app.DataFigure, ...
                        sprintf('Reference curve with tag "%s" not found in axes.', refTag), ...
                        'Error', 'Icon', 'error');
                    return;
                end

                doseRef = refObj.XData(:);
                sfRef   = refObj.YData(:);

                % -----------------------------------------------------------------
                % Mode selection (SF vs Dose) from Viewer radiobuttons
                % -----------------------------------------------------------------
                isSFMode = true;
                if isprop(app, 'RBESFButton') && isprop(app, 'RBEDoseButton')
                    isSFMode = logical(app.RBESFButton.Value);
                end

                if isSFMode
                    % =============================================================
                    % MODE A: RBE at fixed survival fractions (legacy behavior)
                    % =============================================================
                    survivalFractions = [0.9, 0.8, 0.7, 0.6, 0.5, 0.4, 0.3, 0.2, 0.1]';
                    effectSF = survivalFractions;

                    % Reference doses at those SF (robust inversion)
                    doseRefIso = InvertCurveDoseFromSF(doseRef, sfRef, effectSF);

                    % First column is SF
                    rbeTableData = num2cell(effectSF);
                    rbeTableColumns = {'SF'};

                else
                    % =============================================================
                    % MODE B: RBE at fixed reference doses (bidirectional extension)
                    % =============================================================
                    % Reference dose grid [Gy] (adjust to taste)
                    doseRefGrid = (0.5:0.5:10.0)';   % e.g. 0.5 Gy step up to 10 Gy

                    % Compute SF on reference curve at those doses (robust)
                    sfRefAtDose = InterpMonotoneSafe(doseRef, sfRef, doseRefGrid);

                    % The "effect" is the SF reached at D_ref
                    effectSF = sfRefAtDose;

                    % In this mode the reference is exactly the query dose
                    doseRefIso = doseRefGrid;

                    % First column is Dose [Gy] (and rename column accordingly)
                    rbeTableData = num2cell(doseRefGrid);
                    rbeTableColumns = {'Dose [Gy]'};
                end

                % -----------------------------------------------------------------
                % Iterate over ALL target curves (including the reference itself)
                % -----------------------------------------------------------------
                for i = 1:size(tableData, 1)

                    source = tableData{i, 3};
                    displayName = tableData{i, 4};

                    if ~ismember(displayName, validDisplayNames)
                        continue;
                    end

                    tagToFind = strcat(source, displayName);

                    % If this is the reference curve itself -> RBE = 1
                    if strcmp(source, refSource) && strcmp(displayName, refDisplayName)

                        rbe = ones(size(doseRefIso));  % same length as query grid
                        colName = sprintf('%s (ref)', source);

                    else
                        targetObj = findall(app.MultiDataAxes, 'Tag', tagToFind);
                        if isempty(targetObj)
                            uialert(app.DataFigure, ...
                                sprintf('Curve with tag "%s" not found in axes.', tagToFind), ...
                                'Warning', 'Icon', 'warning');
                            continue;
                        end

                        doseTarget = targetObj.XData(:);
                        sfTarget   = targetObj.YData(:);

                        doseTargetIso = InvertCurveDoseFromSF(doseTarget, sfTarget, effectSF);
                        rbe = doseRefIso ./ doseTargetIso;

                        % Better header to avoid duplicates
                        % colName = sprintf('%s — %s', source, displayName);
                        colName = source;
                    end

                    rbeTableData = [rbeTableData, num2cell(rbe)]; %#ok<AGROW>
                    rbeTableColumns{end+1} = colName; %#ok<AGROW>
                end

                % -----------------------------------------------------------------
                % Update RBETable
                % -----------------------------------------------------------------
                app.RBETable.Data = rbeTableData;
                app.RBETable.ColumnName = rbeTableColumns(:)';
                app.RBETable.ColumnEditable = false(1, numel(rbeTableColumns));

            catch ME
                uialert(app.DataFigure, ME.message, 'Error', 'Icon', 'error');
            end

        end

        % Value changed function: SFminSpinner
        function SFminSpinnerValueChanged(app, event)
            step = app.SFminSpinner.Step;
            val  = round(app.SFminSpinner.Value / step) * step;
            if val > 0 && val < 1
                app.SFminSpinner.Value = val;
                app.MultiDataAxes.YLim = [val, 1];
            else
                uialert(app.DataFigure, ...
                    'Y min must be > 0 and < 1 for log-scaled survival fraction.', ...
                    'Invalid Value');
            end
        end

        % Value changed function: DmaxSpinner
        function DmaxSpinnerValueChanged(app, event)
            step = app.DmaxSpinner.Step;
            val  = round(app.DmaxSpinner.Value / step) * step;
            if val > 0
                app.DmaxSpinner.Value = val;
                app.MultiDataAxes.XLim = [0, val];
            else
                uialert(app.DataFigure, ...
                    'X max must be > 0.', ...
                    'Invalid Value');
            end
        end

        % Value changed function: LegendFontSizeSpinner
        function LegendFontSizeSpinnerValueChanged(app, event)
            fs = app.LegendFontSizeSpinner.Value;
            if isempty(app.LegendHandle) || ~isvalid(app.LegendHandle)
                app.LegendHandle = legend(app.MultiDataAxes, 'show');
            end
            app.LegendHandle.Box = 'off';
            app.LegendHandle.FontSize = fs;
        end

        % Value changed function: LabelsFontSizeSpinner
        function LabelsFontSizeSpinnerValueChanged(app, event)
            fs = app.LabelsFontSizeSpinner.Value;
            app.MultiDataAxes.FontSize = fs;
        end

        % Value changed function: GraphScalingSpinner
        function GraphScalingSpinnerValueChanged(app, event)
            scaleFactor = app.GraphScalingSpinner.Value;
            app.applyScaling(scaleFactor);
        end

        % Button pushed function: ResetSpinnersButton
        function ResetSpinnersButtonPushed(app, event)
            % ==== Default values ====
            defaultDmax         = 12;    % Maximum dose in Gy
            defaultSFmin        = 0.1;   % Minimum survival fraction
            defaultLegendFont   = 14;    % Legend font size
            defaultLabelsFont   = 14;    % Axes labels/ticks font size
            defaultScaleFactor  = 1;     % Line/marker scaling factor

            % ==== Update spinner values ====
            app.DmaxSpinner.Value           = defaultDmax;
            app.SFminSpinner.Value          = defaultSFmin;
            app.LegendFontSizeSpinner.Value = defaultLegendFont;
            app.LabelsFontSizeSpinner.Value = defaultLabelsFont;
            app.GraphScalingSpinner.Value   = defaultScaleFactor;

            % ==== Apply axis limits ====
            app.MultiDataAxes.XLim = [0, defaultDmax];
            app.MultiDataAxes.YLim = [defaultSFmin, 1];

            % ==== Apply legend settings ====
            if isempty(app.LegendHandle) || ~isvalid(app.LegendHandle)
                app.LegendHandle = legend(app.MultiDataAxes, 'show');
            end
            app.LegendHandle.FontSize = defaultLegendFont;
            app.LegendHandle.Box = 'off';

            % ==== Apply axes font size ====
            app.MultiDataAxes.FontSize = defaultLabelsFont;

            % ==== Re-capture original sizes from the current plot ==== [Optional]
            % app.OriginalMultiPlotData = StoreOriginalPlotData(app.MultiDataAxes);

            % ==== Reset scaling to original ====
            if ~isempty(app.OriginalMultiPlotData)
                app.applyScaling(defaultScaleFactor);
            end
        end

        % Button pushed function: AlphaBetaButton
        function AlphaBetaButtonPushed(app, event)
            try
                row = app.buildAlphaBetaRow();

                f = uifigure('Name', 'alpha/beta summary', 'Position', [100 100 420 120]);
                t = uitable(f);
                t.Position = [10 10 400 80];
                t.Data = row;
                t.ColumnName = {'Quantity','Value','Lower','Upper'};
                t.RowName = {};
                t.FontName = 'Franklin Gothic Book';

            catch ME
                % uialert(app.DataFigure, ME.message, 'alpha/beta', 'Icon', 'warning');
                warning('alpha and/or beta not available for the selected model.');
                disp(ME.message);
            end
        end

        % Selection changed function: RBEInputButtonGroup
        function RBEInputButtonGroupSelectionChanged(app, event)
            % Callback triggered when switching between SF and Dose modes

            if app.RBESFButton.Value
                firstColName = 'SF';
            else
                firstColName = 'Dose [Gy]';
            end

            % ---- Clear RBE table completely ----
            app.RBETable.Data = {};
            app.RBETable.ColumnName = {firstColName};
            app.RBETable.ColumnEditable = false;

            % Optional: update table based on the new reference
            if ~isempty(app.MultiSessionTable.Data) && ~isempty(app.ReferenceDropDown.Items)
                app.CalculateRBEButtonPushed([]);
            end
        end

        % Value changed function: ReferenceDropDown
        function ReferenceDropDownValueChanged(app, event)
            % Auto update:
            if ~isempty(app.MultiSessionTable.Data)
                % Recompute RBE table using the new reference
                app.CalculateRBEButtonPushed([]);
            else
                % Fallback: clean
                app.RBETable.Data = {};
                if app.RBESFButton.Value
                    app.RBETable.ColumnName = {'SF'};
                else
                    app.RBETable.ColumnName = {'Dose [Gy]'};
                end
            end
        end

        % Button pushed function: ExportRBETableButton
        function ExportRBETableButtonPushed(app, event)

            if isempty(app.RBETable.Data)
                uialert(app.DataFigure, 'RBE table is empty.', 'Export', 'Icon', 'warning');
                return;
            end

            % ------------------------------------------------------------
            % Timestamp (YYYYMMDD_HHMMSS)
            % ------------------------------------------------------------
            ts = datetime('now','Format','yyyyMMdd_HHmmss');
            tsStr = char(ts);

            % ------------------------------------------------------------
            % Mode (SF / DOSE) from RBE radiobuttons
            % ------------------------------------------------------------
            if isprop(app,'RBEDoseButton') && app.RBEDoseButton.Value
                modeStr = 'DOSE';
            else
                modeStr = 'SF';
            end

            % ------------------------------------------------------------
            % Reference (prefer dropdown; fallback to edit field; else Unknown)
            % ------------------------------------------------------------
            refStr = 'Unknown';
            if isprop(app,'ReferenceDropDown') ...
                    && ~isempty(app.ReferenceDropDown.Items) ...
                    && ~isempty(app.ReferenceDropDown.Value)
                refStr = char(string(app.ReferenceDropDown.Value));
            elseif isprop(app,'ReferenceFileNameEditField') ...
                    && ~isempty(app.ReferenceFileNameEditField.Value)
                refStr = char(string(app.ReferenceFileNameEditField.Value));
            end

            % ------------------------------------------------------------
            % Default filename: include mode + reference + timestamp
            % ------------------------------------------------------------
            refSan = regexprep(refStr, '[^\w\-]+', '_');   % safe for filenames
            refSan = regexprep(refSan, '_+', '_');         % collapse repeats
            refSan = regexprep(refSan, '^_|_$', '');       % trim underscores
            if isempty(refSan)
                refSan = 'Unknown';
            end

            defaultName = sprintf('RBE_%s_%s_%s.txt', modeStr, refSan, tsStr);

            [file, path] = uiputfile({'*.txt','Text file (*.txt)'}, ...
                'Export RBE table', defaultName);
            if isequal(file, 0)
                return;
            end
            fullPath = fullfile(path, file);

            try
                cols = app.RBETable.ColumnName;
                data = app.RBETable.Data;

                if isempty(cols)
                    % error('RBETable has no column names.');
                    uialert(app.DataFigure, ...
                        'The RBE table has no column names.', ...
                        'RBE table error', ...
                        'Icon','error');
                    return;
                end

                if isnumeric(data)
                    data = num2cell(data);
                end

                fid = fopen(fullPath, 'w');
                if fid < 0
                    % error('Cannot open file for writing.');
                    uialert(app.DataFigure, ...
                        'Cannot open file for writing.', ...
                        'File I/O error', ...
                        'Icon','error');
                    return;
                end

                % --------------------------------------------------------
                % Header (commented)
                % --------------------------------------------------------
                fprintf(fid, '# SDAnext RBE export\n');
                fprintf(fid, '# Timestamp: %s\n', tsStr);
                fprintf(fid, '# Mode: %s\n', modeStr);
                fprintf(fid, '# Reference: %s\n', refStr);

                % --------------------------------------------------------
                % Column names (TAB-delimited)
                % --------------------------------------------------------
                fprintf(fid, '%s', string(cols{1}));
                for j = 2:numel(cols)
                    fprintf(fid, '\t%s', string(cols{j}));
                end
                fprintf(fid, '\n');

                % --------------------------------------------------------
                % Data rows (TAB-delimited)
                % --------------------------------------------------------
                nRows = size(data, 1);
                nCols = size(data, 2);

                for i = 1:nRows
                    for j = 1:nCols
                        v = data{i, j};

                        if ismissing(v)
                            s = '';
                        elseif ischar(v) || (isstring(v) && isscalar(v))
                            s = char(string(v));
                        elseif isnumeric(v) && isscalar(v)
                            s = sprintf('%.3f', v);
                        elseif islogical(v) && isscalar(v)
                            s = char(string(v));
                        else
                            try
                                s = char(string(v));
                            catch
                                s = '';
                            end
                        end

                        % Avoid tabs/newlines inside cells (keep file parseable)
                        s = strrep(s, sprintf('\t'), ' ');
                        s = strrep(s, newline, ' ');
                        s = strrep(s, sprintf('\r'), ' ');

                        if j == 1
                            fprintf(fid, '%s', s);
                        else
                            fprintf(fid, '\t%s', s);
                        end
                    end
                    fprintf(fid, '\n');
                end

                fclose(fid);

                uialert(app.DataFigure, sprintf('Exported to:\n%s', fullPath), ...
                    'Export', 'Icon', 'success');

            catch ME
                try fclose(fid); end %#ok<TRYNC>
                uialert(app.DataFigure, ME.message, 'Export error', 'Icon', 'error');
            end

        end

        % Cell edit callback: MultiSessionTable
        function MultiSessionTableCellEdit(app, event)
            % Columns
            COL_TYPE   = 1;   % Type (Data/Fit)
            COL_CUSTOM = 6;   % Custom Entry

            r = event.Indices(1);
            c = event.Indices(2);

            % Only guard Custom Entry edits
            if c ~= COL_CUSTOM
                return;
            end

            T = app.MultiSessionTable.Data;
            if isempty(T) || size(T,1) < r
                return;
            end

            rowType = string(T{r, COL_TYPE});  % "Data" or "Fit"

            if strcmpi(rowType, "Fit")
                % Revert to Display Name (col 4) or old value
                T{r, COL_CUSTOM} = T{r, 4};
                app.MultiSessionTable.Data = T;

                uialert(app.DataFigure, ...
                    'Custom legend entries are disabled for fitted model curves (Type = Fit).', ...
                    'Edit not allowed', 'Icon', 'info');
            end

        end

        % Button pushed function: ExportDataFitButton
        function ExportDataFitButtonPushed(app, event)

            % ------------------------------------------------------------
            % Basic validation
            % ------------------------------------------------------------
            if isempty(app.DataTable.Data)
                uialert(app.DataFigure, 'DataTable is empty. Load data first.', ...
                    'Export', 'Icon', 'warning');
                return;
            end

            if isempty(app.CoeffResultsTable.Data)
                uialert(app.DataFigure, 'No fit coefficients found. Run a fit first.', ...
                    'Export', 'Icon', 'warning');
                return;
            end

            % ------------------------------------------------------------
            % Timestamp
            % ------------------------------------------------------------
            ts = datetime('now','Format','yyyyMMdd_HHmmss');
            tsStr = char(ts);

            % ------------------------------------------------------------
            % Fit options (assumed to exist)
            % ------------------------------------------------------------
            modelStr     = app.ModelDropDown.Value;
            algorithmStr = app.AlgorithmDropDown.Value;
            robustStr    = app.RobustDropDown.Value;
            dataSDStr    = app.DataSDSwitch.Value;      % On / Off
            sigmaModeStr = app.SigmaCBSwitch.Value;     % 1σ / 95%CB

            % ------------------------------------------------------------
            % Fit metrics (if available)
            % ------------------------------------------------------------
            fitState = '';
            sse = [];
            rsq = [];
            dfe = [];
            adjrsq = [];
            rmse = [];
            adjSSE = [];

            if ~isempty(app.FitResultTable.Data)
                fitState = app.FitResultTable.Data{1};
                sse      = app.FitResultTable.Data{3};
                rsq      = app.FitResultTable.Data{4};
                dfe      = app.FitResultTable.Data{5};
                adjrsq   = app.FitResultTable.Data{6};
                rmse     = app.FitResultTable.Data{7};
            end

            if isfinite(app.AdjSSEEditField.Value)
                adjSSE = app.AdjSSEEditField.Value;
            end

            % ------------------------------------------------------------
            % Default filename
            % ------------------------------------------------------------
            baseName = app.FileNameEditField.Value;
            if isempty(baseName)
                baseName = 'FitExport';
            else
                [~, baseName, ~] = fileparts(baseName);
            end

            defaultName = sprintf('Fit_%s_%s.txt', baseName, tsStr);

            [file, path] = uiputfile({'*.txt','Text file (*.txt)'}, ...
                'Export fit report', defaultName);
            if isequal(file,0)
                return;
            end

            fullPath = fullfile(path, file);

            % ------------------------------------------------------------
            % Data references
            % ------------------------------------------------------------
            coeffT = app.CoeffResultsTable.Data;   % {Name, Value, Lower, Upper}
            dataT  = app.DataTable.Data;           % {'Dose','SF','SD'}
            colNames = app.DataTable.ColumnName;

            % ------------------------------------------------------------
            % Write file
            % ------------------------------------------------------------
            fid = fopen(fullPath,'w');
            if fid < 0
                uialert(app.DataFigure, 'Cannot open file for writing.', ...
                    'Export', 'Icon','error');
                return;
            end

            try
                % =========================
                % HEADER
                % =========================
                fprintf(fid, '# SDAnext Fit Export\n');
                fprintf(fid, '# Version: %s\n', app.Version);
                fprintf(fid, '# Timestamp: %s\n', tsStr);
                fprintf(fid, '# SourceFile: %s\n', app.FileNameEditField.Value);

                fprintf(fid, '# FitOptions:\n');
                fprintf(fid, '#   Model: %s\n', modelStr);
                fprintf(fid, '#   Algorithm: %s\n', algorithmStr);
                fprintf(fid, '#   Robust: %s\n', robustStr);
                fprintf(fid, '#   DataSD: %s\n', dataSDStr);
                fprintf(fid, '#   CoeffDisplay: %s\n', sigmaModeStr);

                fprintf(fid, '# FitMetrics:\n');
                fprintf(fid, '#   FitState: %s\n', toStr(fitState));
                fprintf(fid, '#   SSE: %s\n', toStr(sse));
                fprintf(fid, '#   DFE: %s\n', toStr(dfe));
                fprintf(fid, '#   AdjSSE (SSE/DFE): %s\n', toStr(adjSSE));
                fprintf(fid, '#   R-square: %s\n', toStr(rsq));
                fprintf(fid, '#   Adj R-square: %s\n', toStr(adjrsq));
                fprintf(fid, '#   RMSE: %s\n', toStr(rmse));

                fprintf(fid, '# FitCovariance:\n');
                if isstruct(app.lastOutput) && isfield(app.lastOutput, 'CovarianceStatus')
                    fprintf(fid, '#   Status: %s\n', toStr(app.lastOutput.CovarianceStatus));
                else
                    fprintf(fid, '#   Status: unavailable\n');
                end
                if isstruct(app.lastOutput) && isfield(app.lastOutput, 'CovarianceMessage')
                    fprintf(fid, '#   Message: %s\n', toStr(app.lastOutput.CovarianceMessage));
                end
                if isstruct(app.lastOutput) && isfield(app.lastOutput, 'CovarianceMSE')
                    fprintf(fid, '#   MSE: %s\n', toStr(app.lastOutput.CovarianceMSE));
                end
                if isstruct(app.lastOutput) && isfield(app.lastOutput, 'JacobianRank')
                    fprintf(fid, '#   JacobianRank: %s\n', toStr(app.lastOutput.JacobianRank));
                end
                if isstruct(app.lastOutput) && isfield(app.lastOutput, 'JacobianCondition')
                    fprintf(fid, '#   JacobianCondition: %s\n', toStr(app.lastOutput.JacobianCondition));
                end

                if strcmp(sigmaModeStr,'1σ')
                    fprintf(fid, '#   NOTE: Lower/Upper columns store 1σ (duplicated)\n');
                else
                    fprintf(fid, '#   NOTE: Lower/Upper columns store 95%% confidence bounds\n');
                end

                fprintf(fid, '\n');

                % =========================
                % COEFFICIENTS
                % =========================
                fprintf(fid, '## COEFFICIENTS\n');
                fprintf(fid, 'Name\tValue\tLower\tUpper\n');

                for i = 1:size(coeffT,1)
                    fprintf(fid, '%s\t%s\t%s\t%s\n', ...
                        toStr(coeffT{i,1}), ...
                        toStr(coeffT{i,2}), ...
                        toStr(coeffT{i,3}), ...
                        toStr(coeffT{i,4}));
                end

                % =========================
                % COVARIANCE MATRIX
                % =========================
                if isstruct(app.lastOutput) && ...
                        isfield(app.lastOutput, 'CovarianceMatrix') && ...
                        isfield(app.lastOutput, 'CoefficientNames') && ...
                        ~isempty(app.lastOutput.CovarianceMatrix)

                    fprintf(fid, '\n## COVARIANCE_MATRIX\n');
                    writeMatrixBlock(fid, app.lastOutput.CovarianceMatrix, app.lastOutput.CoefficientNames);
                end

                % =========================
                % CORRELATION MATRIX
                % =========================
                if isstruct(app.lastOutput) && ...
                        isfield(app.lastOutput, 'CorrelationMatrix') && ...
                        isfield(app.lastOutput, 'CoefficientNames') && ...
                        ~isempty(app.lastOutput.CorrelationMatrix)

                    fprintf(fid, '\n## CORRELATION_MATRIX\n');
                    writeMatrixBlock(fid, app.lastOutput.CorrelationMatrix, app.lastOutput.CoefficientNames);
                end

                fprintf(fid, '\n');

                % =========================
                % DATA
                % =========================
                fprintf(fid, '## DATA\n');
                fprintf(fid, '%s\t%s\t%s\n', colNames{1}, colNames{2}, colNames{3});

                for i = 1:size(dataT,1)
                    fprintf(fid, '%s\t%s\t%s\n', ...
                        sanitize(dataT{i,1}), ...
                        sanitize(dataT{i,2}), ...
                        sanitize(dataT{i,3}));
                end

                fclose(fid);

                uialert(app.DataFigure, sprintf('Exported to:\n%s', fullPath), ...
                    'Export', 'Icon', 'success');

            catch ME
                fclose(fid);
                uialert(app.DataFigure, ME.message, 'Export error', 'Icon','error');
            end

            % ============================================================
            % Helpers (nested)
            % ============================================================
            function s = toStr(x)
                if isnumeric(x) && isscalar(x)
                    s = sprintf('%.6g', x);
                else
                    s = char(string(x));
                end
            end

            function s = sanitize(x)
                s = char(string(x));
                s = strrep(s, sprintf('\t'), ' ');
                s = strrep(s, newline, ' ');
                s = strrep(s, sprintf('\r'), ' ');
            end

            function writeMatrixBlock(fid, M, names)
                names = cellstr(string(names(:)));

                fprintf(fid, 'Name');
                for jj = 1:numel(names)
                    fprintf(fid, '\t%s', names{jj});
                end
                fprintf(fid, '\n');

                for ii = 1:size(M,1)
                    fprintf(fid, '%s', names{ii});
                    for jj = 1:size(M,2)
                        fprintf(fid, '\t%.12g', M(ii,jj));
                    end
                    fprintf(fid, '\n');
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.DataFigure = uifigure('Visible', 'off');
            app.DataFigure.Name = 'Survival Data Analysis';
            app.DataFigure.Tag = 'DataFigure';
            app.TabGroup = uitabgroup(app.DataFigure);
            app.TabGroup.Tag = 'TabGroup';
            app.FitterTab = uitab(app.TabGroup);
            app.FitterTab.Title = 'Fitter';
            app.FitterTab.Tag = 'FitterTab';
            app.DataAxes = uiaxes(app.FitterTab);
            xlabel(app.DataAxes, 'Dose [Gy]')
            ylabel(app.DataAxes, 'Survival fraction')
            app.DataAxes.XLim = [0 12];
            app.DataAxes.YLim = [0.1 1];
            app.DataAxes.ZLim = [0 1];
            app.DataAxes.YTick = [0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.DataAxes.YScale = 'log';
            app.DataAxes.YTickLabel = {'0.1'; '0.2'; '0.3'; '0.4'; '0.5'; '0.6'; '0.7'; '0.8'; '0.9'; '1.0'};
            app.DataAxes.ZTick = [];
            app.DataAxes.Tag = 'DataAxes';
            app.FitOptionsPanel = uipanel(app.FitterTab);
            app.FitOptionsPanel.Title = 'Fit Options';
            app.FitOptionsPanel.Tag = 'FitOptionsPanel';
            app.ModelDropDownLabel = uilabel(app.FitOptionsPanel);
            app.ModelDropDownLabel.Text = 'Model';
            app.ModelDropDown = uidropdown(app.FitOptionsPanel);
            app.ModelDropDown.Items = {'Linear (L)', 'Quadratic (Q)', 'Linear-Quadratic (LQ)', 'Linear-Quadratic-Cubic (LQC)', 'Linear-Quadratic-Linear (LQL)'};
            app.ModelDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelDropDownValueChanged, true);
            app.ModelDropDown.Tag = 'ModelDropDown';
            app.ModelDropDown.Value = 'Linear-Quadratic (LQ)';
            app.RobustDropDownLabel = uilabel(app.FitOptionsPanel);
            app.RobustDropDownLabel.Text = 'Robust';
            app.RobustDropDown = uidropdown(app.FitOptionsPanel);
            app.RobustDropDown.Items = {'Off', 'LAR', 'Bisquare'};
            app.RobustDropDown.Tag = 'RobustDropDown';
            app.RobustDropDown.Value = 'Off';
            app.FitButton = uibutton(app.FitOptionsPanel, 'push');
            app.FitButton.ButtonPushedFcn = createCallbackFcn(app, @FitButtonPushed, true);
            app.FitButton.Tag = 'FitButton';
            app.FitButton.Icon = fullfile(pathToMLAPP, 'icons', 'cadence_100dp_000000_FILL1_wght400_GRAD0_opsz48.svg');
            app.FitButton.Enable = 'off';
            app.FitButton.Text = 'Fit';
            app.AlgorithmDropDownLabel = uilabel(app.FitOptionsPanel);
            app.AlgorithmDropDownLabel.Enable = 'off';
            app.AlgorithmDropDownLabel.Text = 'Algorithm';
            app.AlgorithmDropDown = uidropdown(app.FitOptionsPanel);
            app.AlgorithmDropDown.Items = {'Trust-Region', 'Levenberg-Marquardt'};
            app.AlgorithmDropDown.Tag = 'AlgorithmDropDown';
            app.AlgorithmDropDown.Enable = 'off';
            app.AlgorithmDropDown.Value = 'Trust-Region';
            app.CoefficientsTable = uitable(app.FitOptionsPanel);
            app.CoefficientsTable.ColumnName = {'Coefficients'; 'Start Point'; 'Lower'; 'Upper'};
            app.CoefficientsTable.RowName = {};
            app.CoefficientsTable.ColumnEditable = [false true true true];
            app.CoefficientsTable.Tag = 'CoefficientsTable';
            app.DataSDLabel = uilabel(app.FitOptionsPanel);
            app.DataSDLabel.Text = 'Data SD';
            app.DataSDSwitch = uiswitch(app.FitOptionsPanel, 'slider');
            app.DataSDSwitch.ValueChangedFcn = createCallbackFcn(app, @DataSDSwitchValueChanged, true);
            app.DataSDSwitch.Tag = 'DataSDSwitch';
            app.DataSDSwitch.Enable = 'off';
            app.DataSDSwitch.Value = 'On';
            app.ScanAllModelsButton = uibutton(app.FitOptionsPanel, 'push');
            app.ScanAllModelsButton.ButtonPushedFcn = createCallbackFcn(app, @ScanAllModelsButtonPushed, true);
            app.ScanAllModelsButton.Tag = 'ScanAllModelsButton';
            app.ScanAllModelsButton.Icon = fullfile(pathToMLAPP, 'icons', 'search_insights_100dp_000000_FILL0_wght400_GRAD0_opsz48.svg');
            app.ScanAllModelsButton.Enable = 'off';
            app.ScanAllModelsButton.Tooltip = {'Scan available models'};
            app.ScanAllModelsButton.Text = '';
            app.FitResultTable = uitable(app.FitterTab);
            app.FitResultTable.ColumnName = {'Fit State'; 'Fit Model'; 'SSE'; 'R-square'; 'DFE'; 'Adj R-sq'; 'RMSE'};
            app.FitResultTable.RowName = {};
            app.FitResultTable.Tag = 'FitResultTable';
            app.DataLoaderPanel = uipanel(app.FitterTab);
            app.DataLoaderPanel.Title = 'Data Loader';
            app.DataLoaderPanel.Tag = 'DataLoaderPanel';
            app.SelectDataButton = uibutton(app.DataLoaderPanel, 'push');
            app.SelectDataButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDataButtonPushed, true);
            app.SelectDataButton.Tag = 'SelectDataButton';
            app.SelectDataButton.Icon = fullfile(pathToMLAPP, 'icons', 'upload_file_100dp_000000_FILL1_wght400_GRAD0_opsz48.svg');
            app.SelectDataButton.Tooltip = {'Upload text data file'};
            app.SelectDataButton.Text = '(*.txt)';
            app.DataTable = uitable(app.DataLoaderPanel);
            app.DataTable.ColumnName = {'Dose [Gy]'; 'SF'; 'SD'};
            app.DataTable.SelectionType = 'row';
            app.DataTable.Tag = 'DataTable';
            app.ImportSessionButton = uibutton(app.DataLoaderPanel, 'push');
            app.ImportSessionButton.ButtonPushedFcn = createCallbackFcn(app, @ImportSessionButtonPushed, true);
            app.ImportSessionButton.Tag = 'ImportSessionButton';
            app.ImportSessionButton.Icon = fullfile(pathToMLAPP, 'icons', 'upload_100dp_000000_FILL1_wght400_GRAD0_opsz48.svg');
            app.ImportSessionButton.Tooltip = {'Import MATLAB session'};
            app.ImportSessionButton.Text = '(*.mat)';
            app.FileNameEditField = uieditfield(app.DataLoaderPanel, 'text');
            app.FileNameEditField.Tag = 'FileNameEditField';
            app.FileNameEditField.Editable = 'off';
            app.FileNameEditField.Placeholder = 'filename';
            app.ResultsPanel = uipanel(app.FitterTab);
            app.ResultsPanel.Title = 'Results';
            app.ResultsPanel.Tag = 'ResultsPanel';
            app.CoeffResultsTable = uitable(app.ResultsPanel);
            app.CoeffResultsTable.ColumnName = {'Coefficients'; 'Value'; 'Lower'; 'Upper'};
            app.CoeffResultsTable.RowName = {};
            app.CoeffResultsTable.ColumnEditable = [false true true true];
            app.CoeffResultsTable.Tag = 'CoeffResultsTable';
            app.SigmaCBSwitch = uiswitch(app.ResultsPanel, 'slider');
            app.SigmaCBSwitch.Items = {'95%CB', '1σ'};
            app.SigmaCBSwitch.ValueChangedFcn = createCallbackFcn(app, @SigmaCBSwitchValueChanged, true);
            app.SigmaCBSwitch.Tag = 'SigmaCBSwitch';
            app.SigmaCBSwitch.Value = '1σ';
            app.AlphaBetaButton = uibutton(app.ResultsPanel, 'push');
            app.AlphaBetaButton.ButtonPushedFcn = createCallbackFcn(app, @AlphaBetaButtonPushed, true);
            app.AlphaBetaButton.Tag = 'AlphaBetaButton';
            app.AlphaBetaButton.Icon = fullfile(pathToMLAPP, 'icons', 'calculate_100dp_000000_FILL1_wght400_GRAD0_opsz48.svg');
            app.AlphaBetaButton.Enable = 'off';
            app.AlphaBetaButton.Tooltip = {'Compute alpha/beta ratio'};
            app.AlphaBetaButton.Text = '';
            app.ExportDataFitButton = uibutton(app.ResultsPanel, 'push');
            app.ExportDataFitButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataFitButtonPushed, true);
            app.ExportDataFitButton.Tag = 'ExportDataFitButton';
            app.ExportDataFitButton.Icon = fullfile(pathToMLAPP, 'icons', 'export_notes_100dp_000000_FILL0_wght400_GRAD0_opsz48.svg');
            app.ExportDataFitButton.Tooltip = {'Export Fit Results'};
            app.ExportDataFitButton.Text = 'Export';
            app.SessionPanel = uipanel(app.FitterTab);
            app.SessionPanel.Title = 'Session';
            app.SessionPanel.Tag = 'SessionPanel';
            app.ResetSessionButton = uibutton(app.SessionPanel, 'push');
            app.ResetSessionButton.ButtonPushedFcn = createCallbackFcn(app, @ResetSessionButtonPushed, true);
            app.ResetSessionButton.Tag = 'ResetSessionButton';
            app.ResetSessionButton.Icon = fullfile(pathToMLAPP, 'icons', 'reset_settings_100dp_000000_FILL1_wght400_GRAD0_opsz48.svg');
            app.ResetSessionButton.Tooltip = {'Reset session'};
            app.ResetSessionButton.Text = 'Reset Session';
            app.SaveSessionButton = uibutton(app.SessionPanel, 'push');
            app.SaveSessionButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSessionButtonPushed, true);
            app.SaveSessionButton.Tag = 'SaveSessionButton';
            app.SaveSessionButton.Icon = fullfile(pathToMLAPP, 'icons', 'save_as_100dp_000000_FILL1_wght400_GRAD0_opsz48.svg');
            app.SaveSessionButton.Tooltip = {'Save current session'};
            app.SaveSessionButton.Text = 'Save Session';
            app.SavePlotButton = uibutton(app.SessionPanel, 'push');
            app.SavePlotButton.ButtonPushedFcn = createCallbackFcn(app, @SavePlotButtonPushed, true);
            app.SavePlotButton.Tag = 'SavePlotButton';
            app.SavePlotButton.Icon = fullfile(pathToMLAPP, 'icons', 'file_save_100dp_000000_FILL0_wght400_GRAD0_opsz48.svg');
            app.SavePlotButton.Tooltip = {'Save plot as ...'};
            app.SavePlotButton.Text = 'Save plot as ...';
            app.CleanFitButton = uibutton(app.SessionPanel, 'push');
            app.CleanFitButton.ButtonPushedFcn = createCallbackFcn(app, @CleanFitButtonPushed, true);
            app.CleanFitButton.Tag = 'CleanFitButton';
            app.CleanFitButton.Icon = fullfile(pathToMLAPP, 'icons', 'reset_image_100dp_000000_FILL1_wght400_GRAD0_opsz48.svg');
            app.CleanFitButton.Tooltip = {'Reset fit'};
            app.CleanFitButton.Text = 'Clean Fit';
            app.SFDoseButtonGroup = uibuttongroup(app.FitterTab);
            app.SFDoseButtonGroup.Title = 'Interpolator';
            app.SFDoseButtonGroup.Tag = 'SFDoseButtonGroup';
            app.SFButton = uiradiobutton(app.SFDoseButtonGroup);
            app.SFButton.Tag = 'SFButton';
            app.SFButton.Text = 'SF';
            app.SFButton.Value = true;
            app.DoseButton = uiradiobutton(app.SFDoseButtonGroup);
            app.DoseButton.Tag = 'DoseButton';
            app.DoseButton.Text = 'Dose';
            app.CleanEvaluationButton = uibutton(app.SFDoseButtonGroup, 'push');
            app.CleanEvaluationButton.ButtonPushedFcn = createCallbackFcn(app, @CleanEvaluationButtonPushed, true);
            app.CleanEvaluationButton.Tag = 'CleanEvaluationButton';
            app.CleanEvaluationButton.Icon = fullfile(pathToMLAPP, 'icons', 'mop_100dp_000000_FILL1_wght400_GRAD0_opsz48.svg');
            app.CleanEvaluationButton.Tooltip = {'Clean interpolations'};
            app.CleanEvaluationButton.Text = '';
            app.CallEvaluationButton = uibutton(app.SFDoseButtonGroup, 'push');
            app.CallEvaluationButton.ButtonPushedFcn = createCallbackFcn(app, @CallEvaluationButtonPushed, true);
            app.CallEvaluationButton.Tag = 'CallEvaluationButton';
            app.CallEvaluationButton.Icon = fullfile(pathToMLAPP, 'icons', 'function_100dp_000000_FILL0_wght400_GRAD0_opsz48.svg');
            app.CallEvaluationButton.Enable = 'off';
            app.CallEvaluationButton.Tooltip = {'Fit interpolator'};
            app.CallEvaluationButton.Text = '';
            app.AdjSSEEditFieldLabel = uilabel(app.FitterTab);
            app.AdjSSEEditFieldLabel.Text = 'Adj SSE';
            app.AdjSSEEditField = uieditfield(app.FitterTab, 'numeric');
            app.AdjSSEEditField.Limits = [0 Inf];
            app.AdjSSEEditField.Tag = 'AdjSSEEditField';
            app.AdjSSEEditField.Editable = 'off';
            app.AdjSSEEditField.Value = Inf;
            app.ViewerTab = uitab(app.TabGroup);
            app.ViewerTab.Title = 'Viewer';
            app.ViewerTab.Tag = 'ViewerTab';
            app.MultiDataAxes = uiaxes(app.ViewerTab);
            xlabel(app.MultiDataAxes, 'Dose [Gy]')
            ylabel(app.MultiDataAxes, 'Survival fraction')
            app.MultiDataAxes.XLim = [0 12];
            app.MultiDataAxes.YLim = [0.1 1];
            app.MultiDataAxes.ZLim = [0 1];
            app.MultiDataAxes.YTick = [0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.MultiDataAxes.YScale = 'log';
            app.MultiDataAxes.YTickLabel = {'0.1'; '0.2'; '0.3'; '0.4'; '0.5'; '0.6'; '0.7'; '0.8'; '0.9'; '1.0'};
            app.MultiDataAxes.ZTick = [];
            app.MultiDataAxes.Tag = 'MultiDataAxes';
            app.MultiSessionLoaderManagerPanel = uipanel(app.ViewerTab);
            app.MultiSessionLoaderManagerPanel.Title = 'Multi Session Loader & Manager';
            app.MultiSessionLoaderManagerPanel.Tag = 'MultiSessionLoaderManagerPanel';
            app.MultiSessionTable = uitable(app.MultiSessionLoaderManagerPanel);
            app.MultiSessionTable.ColumnName = {'Type'; 'File Name'; 'Source'; 'Display Name'; 'Color'; 'Custom Entry'; 'Line Style'; 'Marker'};
            app.MultiSessionTable.ColumnEditable = [false false false false true true true true];
            app.MultiSessionTable.CellEditCallback = createCallbackFcn(app, @MultiSessionTableCellEdit, true);
            app.MultiSessionTable.Tag = 'MultiSessionTable';
            app.ImportMultiSessionButton = uibutton(app.MultiSessionLoaderManagerPanel, 'push');
            app.ImportMultiSessionButton.ButtonPushedFcn = createCallbackFcn(app, @ImportMultiSessionButtonPushed, true);
            app.ImportMultiSessionButton.Tag = 'ImportMultiSessionButton';
            app.ImportMultiSessionButton.Icon = fullfile(pathToMLAPP, 'icons', 'publish_100dp_000000_FILL1_wght400_GRAD0_opsz48.svg');
            app.ImportMultiSessionButton.Tooltip = {'Import MATLAB multi-session'};
            app.ImportMultiSessionButton.Text = '(*.mat)';
            app.RefreshButton = uibutton(app.MultiSessionLoaderManagerPanel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Tag = 'RefreshButton';
            app.RefreshButton.Icon = fullfile(pathToMLAPP, 'icons', 'refresh_100dp_000000_FILL1_wght400_GRAD0_opsz48.svg');
            app.RefreshButton.Tooltip = {'Refresh plot'};
            app.RefreshButton.Text = {'Update'; 'Plot'};
            app.SaveMultiPlotButton = uibutton(app.MultiSessionLoaderManagerPanel, 'push');
            app.SaveMultiPlotButton.ButtonPushedFcn = createCallbackFcn(app, @SaveMultiPlotButtonPushed, true);
            app.SaveMultiPlotButton.Tag = 'SaveMultiPlotButton';
            app.SaveMultiPlotButton.Icon = fullfile(pathToMLAPP, 'icons', 'file_save_100dp_000000_FILL0_wght400_GRAD0_opsz48.svg');
            app.SaveMultiPlotButton.Tooltip = {'Save plot as ...'};
            app.SaveMultiPlotButton.Text = {'Save '; 'Plot As ...'};
            app.ImportMultiSessionFigureButton = uibutton(app.MultiSessionLoaderManagerPanel, 'push');
            app.ImportMultiSessionFigureButton.ButtonPushedFcn = createCallbackFcn(app, @ImportMultiSessionFigureButtonPushed, true);
            app.ImportMultiSessionFigureButton.Tag = 'ImportMultiSessionFigureButton';
            app.ImportMultiSessionFigureButton.Icon = fullfile(pathToMLAPP, 'icons', 'upload_file_100dp_000000_FILL0_wght400_GRAD0_opsz48.svg');
            app.ImportMultiSessionFigureButton.Tooltip = {'Import MATLAB figure'};
            app.ImportMultiSessionFigureButton.Text = '(*.fig)';
            app.ResetMultiSessionButton = uibutton(app.MultiSessionLoaderManagerPanel, 'push');
            app.ResetMultiSessionButton.ButtonPushedFcn = createCallbackFcn(app, @ResetMultiSessionButtonPushed, true);
            app.ResetMultiSessionButton.Tag = 'ResetMultiSessionButton';
            app.ResetMultiSessionButton.Icon = fullfile(pathToMLAPP, 'icons', 'reset_settings_100dp_000000_FILL1_wght400_GRAD0_opsz48.svg');
            app.ResetMultiSessionButton.Tooltip = {'Reset multi-session'};
            app.ResetMultiSessionButton.Text = {'Reset'; 'Session'};
            app.RBECalculatorPanel = uipanel(app.ViewerTab);
            app.RBECalculatorPanel.Title = 'RBE Calculator';
            app.RBECalculatorPanel.Tag = 'RBECalculatorPanel';
            app.RBETable = uitable(app.RBECalculatorPanel);
            app.RBETable.ColumnName = {'SF'};
            app.RBETable.SelectionType = 'row';
            app.RBETable.Tag = 'RBETable';
            app.CalculateRBEButton = uibutton(app.RBECalculatorPanel, 'push');
            app.CalculateRBEButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateRBEButtonPushed, true);
            app.CalculateRBEButton.Tag = 'CalculateRBEButton';
            app.CalculateRBEButton.Icon = fullfile(pathToMLAPP, 'icons', 'calculate_100dp_000000_FILL1_wght400_GRAD0_opsz48.svg');
            app.CalculateRBEButton.Tooltip = {'Calculate RBE'};
            app.CalculateRBEButton.Text = {'Compute'; 'RBE'};
            app.RBEInputButtonGroup = uibuttongroup(app.RBECalculatorPanel);
            app.RBEInputButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @RBEInputButtonGroupSelectionChanged, true);
            app.RBEInputButtonGroup.Tag = 'RBEInputButtonGroup';
            app.RBESFButton = uiradiobutton(app.RBEInputButtonGroup);
            app.RBESFButton.Tag = 'RBESFButton';
            app.RBESFButton.Text = 'SF';
            app.RBESFButton.Value = true;
            app.RBEDoseButton = uiradiobutton(app.RBEInputButtonGroup);
            app.RBEDoseButton.Tag = 'RBEDoseButton';
            app.RBEDoseButton.Text = 'Dose';
            app.ReferenceLabel_2 = uilabel(app.RBECalculatorPanel);
            app.ReferenceLabel_2.Text = 'Reference ';
            app.ReferenceDropDown = uidropdown(app.RBECalculatorPanel);
            app.ReferenceDropDown.Items = {};
            app.ReferenceDropDown.ValueChangedFcn = createCallbackFcn(app, @ReferenceDropDownValueChanged, true);
            app.ReferenceDropDown.Tag = 'ReferenceDropDown';
            app.ReferenceDropDown.Value = {};
            app.SFminSpinnerLabel = uilabel(app.ViewerTab);
            app.SFminSpinnerLabel.Text = 'SFmin';
            app.SFminSpinner = uispinner(app.ViewerTab);
            app.SFminSpinner.Step = 0.01;
            app.SFminSpinner.Limits = [0.01 1];
            app.SFminSpinner.ValueDisplayFormat = '%3.2f';
            app.SFminSpinner.ValueChangedFcn = createCallbackFcn(app, @SFminSpinnerValueChanged, true);
            app.SFminSpinner.Tag = 'SFminSpinner';
            app.SFminSpinner.Value = 0.1;
            app.DmaxGySpinnerLabel = uilabel(app.ViewerTab);
            app.DmaxGySpinnerLabel.Text = 'Dmax [Gy]';
            app.DmaxSpinner = uispinner(app.ViewerTab);
            app.DmaxSpinner.Step = 0.25;
            app.DmaxSpinner.Limits = [0 100];
            app.DmaxSpinner.ValueDisplayFormat = '%3.2f';
            app.DmaxSpinner.ValueChangedFcn = createCallbackFcn(app, @DmaxSpinnerValueChanged, true);
            app.DmaxSpinner.Tag = 'DmaxSpinner';
            app.DmaxSpinner.Value = 12;
            app.AxesLabel = uilabel(app.ViewerTab);
            app.AxesLabel.Text = 'Labels Font Size';
            app.LabelsFontSizeSpinner = uispinner(app.ViewerTab);
            app.LabelsFontSizeSpinner.Limits = [8 48];
            app.LabelsFontSizeSpinner.RoundFractionalValues = 'on';
            app.LabelsFontSizeSpinner.ValueDisplayFormat = '%i';
            app.LabelsFontSizeSpinner.ValueChangedFcn = createCallbackFcn(app, @LabelsFontSizeSpinnerValueChanged, true);
            app.LabelsFontSizeSpinner.Tag = 'LabelsFontSizeSpinner';
            app.LabelsFontSizeSpinner.Value = 14;
            app.LegendFontLabel = uilabel(app.ViewerTab);
            app.LegendFontLabel.Text = 'Legend Font Size ';
            app.LegendFontSizeSpinner = uispinner(app.ViewerTab);
            app.LegendFontSizeSpinner.Limits = [8 48];
            app.LegendFontSizeSpinner.RoundFractionalValues = 'on';
            app.LegendFontSizeSpinner.ValueDisplayFormat = '%i';
            app.LegendFontSizeSpinner.ValueChangedFcn = createCallbackFcn(app, @LegendFontSizeSpinnerValueChanged, true);
            app.LegendFontSizeSpinner.Tag = 'LegendFontSpinner';
            app.LegendFontSizeSpinner.Value = 14;
            app.AxesLabel_2 = uilabel(app.ViewerTab);
            app.AxesLabel_2.Text = 'Graph Scaling';
            app.GraphScalingSpinner = uispinner(app.ViewerTab);
            app.GraphScalingSpinner.Step = 0.5;
            app.GraphScalingSpinner.Limits = [1 5];
            app.GraphScalingSpinner.ValueDisplayFormat = '%3.1f';
            app.GraphScalingSpinner.ValueChangedFcn = createCallbackFcn(app, @GraphScalingSpinnerValueChanged, true);
            app.GraphScalingSpinner.Tag = 'GraphScalingSpinner';
            app.GraphScalingSpinner.Value = 1;
            app.ResetSpinnersButton = uibutton(app.ViewerTab, 'push');
            app.ResetSpinnersButton.ButtonPushedFcn = createCallbackFcn(app, @ResetSpinnersButtonPushed, true);
            app.ResetSpinnersButton.Tag = 'ResetSpinnersButton';
            app.ResetSpinnersButton.Icon = fullfile(pathToMLAPP, 'icons', 'reset_settings_100dp_000000_FILL1_wght400_GRAD0_opsz48.svg');
            app.ResetSpinnersButton.Tooltip = {'Reset default limits and sizes'};
            app.ResetSpinnersButton.Text = 'Reset Settings';
            app.ExportRBETableButton = uibutton(app.ViewerTab, 'push');
            app.ExportRBETableButton.ButtonPushedFcn = createCallbackFcn(app, @ExportRBETableButtonPushed, true);
            app.ExportRBETableButton.Tag = 'ExportRBETableButton';
            app.ExportRBETableButton.Icon = fullfile(pathToMLAPP, 'icons', 'export_notes_100dp_000000_FILL0_wght400_GRAD0_opsz48.svg');
            app.ExportRBETableButton.Tooltip = {'Save plot as ...'};
            app.ExportRBETableButton.Text = 'Export RBE Table';
            % Show the figure after all components are created
            app.DataFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SDAnext

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.DataFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.DataFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DataFigure)
        end
    end
end